classdef Gen_app_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PunchingShearLabel              matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        Step6SavinginputButton          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Steps16ReloadoldmodelButton     matlab.ui.control.Button
        NameforsavingfileEditField      matlab.ui.control.EditField
        ProjectnameEditFieldLabel       matlab.ui.control.Label
        Step5OpeninginputPanel          matlab.ui.container.Panel
        NumberofservicecomboLabel_76    matlab.ui.control.Label
        UITable_hole                    matlab.ui.control.Table
        Step7PlottingslabopeningButton  matlab.ui.control.Button
        Step3LoadingPanel               matlab.ui.container.Panel
        ForedgeandcornercolumnsLabel    matlab.ui.control.Label
        F_LL_1                          matlab.ui.control.NumericEditField
        F_LL_5                          matlab.ui.control.NumericEditField
        F_LL_4                          matlab.ui.control.NumericEditField
        F_LL_3                          matlab.ui.control.NumericEditField
        F_LL_2                          matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_65    matlab.ui.control.Label
        F_SDL_1                         matlab.ui.control.NumericEditField
        F_SDL_5                         matlab.ui.control.NumericEditField
        F_SDL_4                         matlab.ui.control.NumericEditField
        F_SDL_3                         matlab.ui.control.NumericEditField
        F_SDL_2                         matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_64    matlab.ui.control.Label
        LL_in                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_59    matlab.ui.control.Label
        NumberofservicecomboLabel_58    matlab.ui.control.Label
        SDL_in                          matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_57    matlab.ui.control.Label
        NumberofservicecomboLabel_56    matlab.ui.control.Label
        My1                             matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_46    matlab.ui.control.Label
        Mx1                             matlab.ui.control.NumericEditField
        R1                              matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_53    matlab.ui.control.Label
        My5                             matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_52    matlab.ui.control.Label
        Mx5                             matlab.ui.control.NumericEditField
        R5                              matlab.ui.control.NumericEditField
        My4                             matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_51    matlab.ui.control.Label
        Mx4                             matlab.ui.control.NumericEditField
        R4                              matlab.ui.control.NumericEditField
        My3                             matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_50    matlab.ui.control.Label
        Mx3                             matlab.ui.control.NumericEditField
        R3                              matlab.ui.control.NumericEditField
        My2                             matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_49    matlab.ui.control.Label
        Mx2                             matlab.ui.control.NumericEditField
        R2                              matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_48    matlab.ui.control.Label
        NumberofservicecomboLabel_47    matlab.ui.control.Label
        Step8DesignButton               matlab.ui.control.Button
        Step4RebarsTendonPanel          matlab.ui.container.Panel
        TopslabrebarCheckBox            matlab.ui.control.CheckBox
        XrebarsSwitch                   matlab.ui.control.Switch
        XrebarsSwitchLabel              matlab.ui.control.Label
        NumberofservicecomboLabel_55    matlab.ui.control.Label
        mmLabel_19                      matlab.ui.control.Label
        d_eff_drop_in                   matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_54    matlab.ui.control.Label
        mmLabel_18                      matlab.ui.control.Label
        d_eff_slab_in                   matlab.ui.control.NumericEditField
        MinrebarButton                  matlab.ui.control.Button
        NumberofservicecomboLabel_45    matlab.ui.control.Label
        mmLabel_17                      matlab.ui.control.Label
        D_s_slab_x_in                   matlab.ui.control.DropDown
        mmLabel_16                      matlab.ui.control.Label
        D_s_slab_y_in                   matlab.ui.control.DropDown
        Spacing_s_slab_y_in             matlab.ui.control.NumericEditField
        Spacing_s_slab_x_in             matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_44    matlab.ui.control.Label
        NumberofservicecomboLabel_43    matlab.ui.control.Label
        NumberofservicecomboLabel_42    matlab.ui.control.Label
        NumberofservicecomboLabel_41    matlab.ui.control.Label
        NumberofservicecomboLabel_40    matlab.ui.control.Label
        mmLabel_15                      matlab.ui.control.Label
        d_s_x_in                        matlab.ui.control.DropDown
        mmLabel_14                      matlab.ui.control.Label
        d_s_y_in                        matlab.ui.control.DropDown
        n_s_y_in                        matlab.ui.control.NumericEditField
        n_s_x_in                        matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_39    matlab.ui.control.Label
        L_s_y                           matlab.ui.control.NumericEditField
        L_s_x                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_38    matlab.ui.control.Label
        Spacing_y_in                    matlab.ui.control.NumericEditField
        Spacing_x_in                    matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_37    matlab.ui.control.Label
        NumberofservicecomboLabel_36    matlab.ui.control.Label
        NumberofservicecomboLabel_35    matlab.ui.control.Label
        NumberofservicecomboLabel_34    matlab.ui.control.Label
        mmLabel_13                      matlab.ui.control.Label
        mmLabel_12                      matlab.ui.control.Label
        NumberofservicecomboLabel_33    matlab.ui.control.Label
        NumberofservicecomboLabel_32    matlab.ui.control.Label
        Drape_y_in                      matlab.ui.control.NumericEditField
        Drape_x_in                      matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_31    matlab.ui.control.Label
        n_p_y_drop_in                   matlab.ui.control.NumericEditField
        n_p_x_drop_in                   matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_30    matlab.ui.control.Label
        n_p_y_col_in                    matlab.ui.control.NumericEditField
        n_p_x_col_in                    matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_29    matlab.ui.control.Label
        NumberofservicecomboLabel_28    matlab.ui.control.Label
        NumberofservicecomboLabel_27    matlab.ui.control.Label
        n_p_y_in                        matlab.ui.control.NumericEditField
        n_p_x_in                        matlab.ui.control.NumericEditField
        cp_in                           matlab.ui.control.NumericEditField
        cr_in                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_26    matlab.ui.control.Label
        mmLabel_11                      matlab.ui.control.Label
        NumberofservicecomboLabel_25    matlab.ui.control.Label
        NumberofservicecomboLabel_24    matlab.ui.control.Label
        mmLabel_10                      matlab.ui.control.Label
        Step2GeometricalinputPanel      matlab.ui.container.Panel
        ColumnpositionDropDown          matlab.ui.control.DropDown
        ColumnpositionDropDownLabel     matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        NumberofservicecomboLabel_23    matlab.ui.control.Label
        mmLabel_9                       matlab.ui.control.Label
        By_in                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_22    matlab.ui.control.Label
        mmLabel_8                       matlab.ui.control.Label
        Bx_in                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_20    matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        Dp_in                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_19    matlab.ui.control.Label
        NumberofservicecomboLabel_17    matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        D_in                            matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_16    matlab.ui.control.Label
        NumberofservicecomboLabel_15    matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        Cy_in                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_14    matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        Cx_in                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_13    matlab.ui.control.Label
        NumberofservicecomboLabel_12    matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        Ly_in                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_11    matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        Lx_in                           matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_10    matlab.ui.control.Label
        Step1MaterialpropertiesPanel    matlab.ui.container.Panel
        mm2Label                        matlab.ui.control.Label
        Strand_area_in                  matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_60    matlab.ui.control.Label
        NumberofservicecomboLabel_9     matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        Link_dia_in                     matlab.ui.control.DropDown
        gamma_s_in                      matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_7     matlab.ui.control.Label
        gamma_c_in                      matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_6     matlab.ui.control.Label
        fpuLabel                        matlab.ui.control.Label
        Input_speff                     matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_5     matlab.ui.control.Label
        MPaLabel_4                      matlab.ui.control.Label
        Input_fpu                       matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_4     matlab.ui.control.Label
        MPaLabel_3                      matlab.ui.control.Label
        Input_fpy                       matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_3     matlab.ui.control.Label
        MPaLabel_2                      matlab.ui.control.Label
        Input_fsy                       matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_2     matlab.ui.control.Label
        MPaLabel                        matlab.ui.control.Label
        Input_fck                       matlab.ui.control.NumericEditField
        NumberofservicecomboLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        DropresultsTab                  matlab.ui.container.Tab
        NumberofservicecomboLabel_73    matlab.ui.control.Label
        NumberofservicecomboLabel_72    matlab.ui.control.Label
        Vrdc_drop                       matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_67    matlab.ui.control.Label
        NumberofservicecomboLabel_66    matlab.ui.control.Label
        Shear_on_drop                   matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_63    matlab.ui.control.Label
        NumberofservicecomboLabel_62    matlab.ui.control.Label
        NumberofservicecomboLabel_61    matlab.ui.control.Label
        U_out                           matlab.ui.control.NumericEditField
        Result_table_rein               matlab.ui.control.Table
        ReinforcementperimeterLabel     matlab.ui.control.Label
        ControlperimetersLabel          matlab.ui.control.Label
        Design_notice                   matlab.ui.control.TextArea
        PlotButton                      matlab.ui.control.Button
        ShearreinforcementsCheckBox     matlab.ui.control.CheckBox
        ControlperimetersCheckBox       matlab.ui.control.CheckBox
        LongitudinalreinforcementsCheckBox  matlab.ui.control.CheckBox
        OpeningCheckBox                 matlab.ui.control.CheckBox
        Result_table_drop               matlab.ui.control.Table
        Plotting_result                 matlab.ui.control.UIAxes
        SlabresultsTab                  matlab.ui.container.Tab
        NumberofservicecomboLabel_75    matlab.ui.control.Label
        NumberofservicecomboLabel_74    matlab.ui.control.Label
        Vrdc_slab                       matlab.ui.control.NumericEditField
        NumberofservicecomboLabel_69    matlab.ui.control.Label
        NumberofservicecomboLabel_68    matlab.ui.control.Label
        Shear_on_slab                   matlab.ui.control.NumericEditField
        Result_table_rein_2             matlab.ui.control.Table
        ReinforcementperimeterLabel_2   matlab.ui.control.Label
        SlabreinforcementsLabel_2       matlab.ui.control.Label
        Result_table_slab               matlab.ui.control.Table
        Design_notice_2                 matlab.ui.control.TextArea
        PlotButton_2                    matlab.ui.control.Button
        ShearreinforcementsCheckBox_2   matlab.ui.control.CheckBox
        ControlperimetersCheckBox_2     matlab.ui.control.CheckBox
        LongitudinalreinforcementsCheckBox_2  matlab.ui.control.CheckBox
        OpeningCheckBox_2               matlab.ui.control.CheckBox
        Plotting_result_2               matlab.ui.control.UIAxes
        CombinedTab                     matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        ExportcalculationreportButton   matlab.ui.control.Button
        NumberofservicecomboLabel_71    matlab.ui.control.Label
        NumberofservicecomboLabel_70    matlab.ui.control.Label
        Total_reinf_drop_slab           matlab.ui.control.NumericEditField
        SlabreinforcementperimeterLabel  matlab.ui.control.Label
        Result_table_rein_4             matlab.ui.control.Table
        Result_table_rein_3             matlab.ui.control.Table
        DropreinforcementperimeterLabel  matlab.ui.control.Label
        PlotButton_3                    matlab.ui.control.Button
        ShearreinforcementsCheckBox_3   matlab.ui.control.CheckBox
        ControlperimetersCheckBox_3     matlab.ui.control.CheckBox
        LongitudinalreinforcementsCheckBox_3  matlab.ui.control.CheckBox
        HoleCheckBox_3                  matlab.ui.control.CheckBox
        Plotting_result_3               matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable_hole.Data = zeros(20,5);
            app.UITable_hole.Data(:,1) = (1:20)';
        end

        % Button pushed function: Step8DesignButton
        function Step7(app, event)
        try
            app.Image.ImageSource = "processing.gif";
            pause(0.000001);
            warning("off");
                %% Reading input   
                Rebar_area = ...
                    [10 79; ...
                     12 113; ...
                     14 154; ...
                     16 201; ...
                     18 254; ...
                     20 314; ...
                     22 380; ...
                     25 491; ...
                     28 616; ...
                     32 804];
                
                % Material
                fck = app.Input_fck.Value;
                gamma_c = app.gamma_c_in.Value;
                fyk = app.Input_fsy.Value;
                fpk = app.Input_fpy.Value;
                fpu = app.Input_fpu.Value;
                gamma_s = app.gamma_s_in.Value;
                f_pe = app.Input_speff.Value;
                Dia_link = str2double(app.Link_dia_in.Value);
    
                % Span
                Span_x = app.Lx_in.Value*1000;
                Span_y = app.Ly_in.Value*1000;
    
                % Dimensions of column
                hcx = app.Cx_in.Value;
                hcy = app.Cy_in.Value;
                
                Check_radius = max([Span_x,Span_y]);
                
                % Edge points of hole
                hole_table = app.UITable_hole.Data;
                tem = hole_table(:,2) == 0 &...
                    hole_table(:,3) == 0 & ...
                    hole_table(:,4) == 0 & ...
                    hole_table(:,5) == 0;
    
                hole_table(tem,:) = [];
                
                no_hole = 0;
                
                if ~isempty(hole_table)   
                    [number_of_hole_input,~] = size(hole_table);
                    
                    x_hole_input = hole_table(:,[2,4]);
                    y_hole_input = hole_table(:,[3,5]);
                else
                    no_hole = 1;
                    number_of_hole_input = 0;
                    x_hole_input = [];
                    y_hole_input = [];
                end
                
                % Slab % drop
                S_thickness = app.D_in.Value;
                Drop_thickness = app.Dp_in.Value;
    
                if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                
                    Col_position = 1;
                
                elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                
                    Col_position = 2;
                
                elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                
                    Col_position = 3;
                
                end
    
                Drop_x_original = app.Bx_in.Value;
                Drop_y_original = app.By_in.Value;
                if Col_position == 1
                
                    Drop_x = app.Bx_in.Value;
                    Drop_y = app.By_in.Value;
                
                elseif Col_position == 2
                
                    Drop_x = (app.Bx_in.Value - hcx/2)*2;
                    Drop_y = app.By_in.Value;
                
                elseif Col_position == 3
                
                    Drop_x = (app.Bx_in.Value - hcx/2)*2;
                    Drop_y = (app.By_in.Value - hcy/2)*2;
                
                end


                % Cover to top
                Cx = app.cr_in.Value;
                Cy = app.cr_in.Value;
                
                Cpx = app.cp_in.Value;
                Cpy = app.cp_in.Value;
                
                % Diameter of rebars
                Dia_x = str2double(app.d_s_x_in.Value);
                Dia_y = str2double(app.d_s_y_in.Value);
                Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
                Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
                Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
                Spacing_slab_y = app.Spacing_s_slab_y_in.Value;
    
                Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
                Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
                Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
                Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
                Area_link = Rebar_area(Rebar_area(:,1) == Dia_link,2);
                
                % Rebar arrange
                num_x_s = app.n_s_x_in.Value;
                num_y_s = app.n_s_y_in.Value;
    
                d_eff_drop = app.d_eff_drop_in.Value;
                d_eff_slab = app.d_eff_slab_in.Value;
    
                if Col_position == 1
                
                    Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop,app.By_in.Value]);
                    Dis_length_Re_Y = min([app.Cx_in.Value + 6*d_eff_drop,app.Bx_in.Value]);
                
                elseif Col_position == 2
                
                    Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop,app.By_in.Value]);
                    Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop,app.Bx_in.Value]);
                
                elseif Col_position == 3
                
                    Dis_length_Re_X = min([app.Cy_in.Value + 3*d_eff_drop,app.By_in.Value]);
                    Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop,app.Bx_in.Value]);
                
                end
    
                spacing_x_drop = floor((Dis_length_Re_X-50)/num_x_s/25)*25;
                spacing_y_drop = floor((Dis_length_Re_Y-50)/num_y_s/25)*25;
    
                app.Spacing_x_in.Value = spacing_x_drop;
                app.Spacing_y_in.Value = spacing_y_drop;
    
                L_s_x_store = app.L_s_x.Value;
                L_s_y_store = app.L_s_y.Value;
                
    
                % Load
                LL = app.LL_in.Value;
                SDL = app.SDL_in.Value;
                
                % Strand
                One_stran_area = app.Strand_area_in.Value;
                
                Total_p_x = app.n_p_x_in.Value;
                Num_p_x_col = app.n_p_x_col_in.Value;
                Num_p_x_drop = app.n_p_x_drop_in.Value;
                
                Total_p_y = app.n_p_y_in.Value;
                Num_p_y_col = app.n_p_y_col_in.Value;
                Num_p_y_drop = app.n_p_y_drop_in.Value;
    
    
                Drape_x = app.Drape_x_in.Value;
                Drape_y = app.Drape_y_in.Value;
                
                P_x_col = f_pe*Num_p_x_col*One_stran_area*fpu;
                P_x_drop = f_pe*Num_p_x_drop*One_stran_area*fpu;
                P_x_all = f_pe*Total_p_x*One_stran_area*fpu;
    
                if Col_position == 1
                    s_cp_x = P_x_all/(Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness);
                elseif Col_position == 2
                    s_cp_x = P_x_all/(Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness);
                elseif Col_position == 3
                    s_cp_x = P_x_all/(Drop_y_original*Drop_thickness + (Span_y/2-Drop_y_original)*S_thickness);
                end
                
    
                if Col_position == 1
                    s_cp_x_slab = P_x_all/(Span_y*S_thickness);
                elseif Col_position == 2
                    s_cp_x_slab = P_x_all/(Span_y*S_thickness);
                elseif Col_position == 3
                    s_cp_x_slab = P_x_all/(Span_y*S_thickness/2);
                end


                P_y_col = f_pe*Num_p_y_col*One_stran_area*fpu;
                P_y_drop = f_pe*Num_p_y_drop*One_stran_area*fpu;
                P_y_all = f_pe*Total_p_y*One_stran_area*fpu;
                
                if Col_position == 1
                    s_cp_y = P_y_all/(Drop_x*Drop_thickness + (Span_x-Drop_x)*S_thickness);
                elseif Col_position == 2
                    s_cp_y = P_y_all/(Drop_x_original*Drop_thickness + (Span_x/2-Drop_x_original)*S_thickness);
                elseif Col_position == 3
                    s_cp_y = P_y_all/(Drop_x_original*Drop_thickness + (Span_x/2-Drop_x_original)*S_thickness);
                end
    
    
                if Col_position == 1
                    s_cp_y_slab = P_y_all/(Span_x*S_thickness);
                elseif Col_position == 2
                    s_cp_y_slab = P_y_all/(Span_x*S_thickness/2);
                elseif Col_position == 3
                    s_cp_y_slab = P_y_all/(Span_x*S_thickness/2);
                end
    
    
                s_cp = (s_cp_x + s_cp_y)/2;
                s_cp_slab = (s_cp_x_slab + s_cp_y_slab)/2;
                
                % Load
                Rz = [app.R1.Value, app.Mx1.Value, app.My1.Value;...
                      app.R2.Value, app.Mx2.Value, app.My2.Value;...
                      app.R3.Value, app.Mx3.Value, app.My3.Value;...
                      app.R4.Value, app.Mx4.Value, app.My4.Value;...
                      app.R5.Value, app.Mx5.Value, app.My5.Value];
                
                factor_SDL_LL = [app.F_SDL_1.Value, app.F_LL_1.Value; ...
                                 app.F_SDL_2.Value, app.F_LL_2.Value; ...
                                 app.F_SDL_3.Value, app.F_LL_3.Value; ...
                                 app.F_SDL_4.Value, app.F_LL_4.Value; ...
                                 app.F_SDL_5.Value, app.F_LL_5.Value];
    
                ex = 1000*Rz(:,2)./Rz(:,1);
                ey = 1000*Rz(:,3)./Rz(:,1);
    
    
                D_slab = app.D_in.Value;
                D_drop = app.Dp_in.Value;
    
    
                Design_column_or_wall = app.Switch.Value;
                Completed_input.Design_column_or_wall = Design_column_or_wall;
                Completed_input.Rebar_area = Rebar_area;
                Completed_input.fck = fck;
                Completed_input.gamma_c = gamma_c;
                Completed_input.fyk = fyk;
                Completed_input.fpk = fpk;
                Completed_input.fpu = fpu;
                Completed_input.gamma_s = gamma_s;
                Completed_input.f_pe = f_pe;
                Completed_input.Dia_link = Dia_link;
                Completed_input.Span_x = Span_x;
                Completed_input.Span_y = Span_y;
                Completed_input.hcx = hcx;
                Completed_input.hcy = hcy;
                Completed_input.Check_radius = Check_radius;
                Completed_input.hole_table = hole_table;
                Completed_input.no_hole = no_hole;
                Completed_input.number_of_hole_input = number_of_hole_input;
                Completed_input.S_thickness = S_thickness;
                Completed_input.Drop_thickness = Drop_thickness;
                Completed_input.Drop_x = Drop_x;
                Completed_input.Drop_y = Drop_y;
                Completed_input.Cx = Cx;
                Completed_input.Cy = Cy;
                Completed_input.Cpx = Cpx;
                Completed_input.Cpy = Cpy;
                Completed_input.Dia_x = Dia_x;
                Completed_input.Dia_y = Dia_y;
                Completed_input.Dia_x_slab = Dia_x_slab;
                Completed_input.Dia_y_slab = Dia_y_slab;
                Completed_input.Spacing_slab_x = Spacing_slab_x;
                Completed_input.Spacing_slab_y = Spacing_slab_y;
                Completed_input.Area_x_1 = Area_x_1;
                Completed_input.Area_y_1 = Area_y_1;
                Completed_input.Area_x_1_slab = Area_x_1_slab;
                Completed_input.Area_y_1_slab = Area_y_1_slab;
                Completed_input.Area_link = Area_link;
                Completed_input.num_x_s = num_x_s;
                Completed_input.num_y_s = num_y_s;
                Completed_input.spacing_x_drop = spacing_x_drop;
                Completed_input.spacing_y_drop = spacing_y_drop;
                Completed_input.L_s_x_store = L_s_x_store;
                Completed_input.L_s_y_store = L_s_y_store;
                Completed_input.d_eff_drop = d_eff_drop;
                Completed_input.d_eff_slab = d_eff_slab;
                Completed_input.LL = LL;
                Completed_input.SDL = SDL;
                Completed_input.One_stran_area = One_stran_area;
                Completed_input.Total_p_x = Total_p_x;
                Completed_input.Num_p_x_col = Num_p_x_col;
                Completed_input.Num_p_x_drop = Num_p_x_drop;
                Completed_input.Total_p_y = Total_p_y;
                Completed_input.Num_p_y_col = Num_p_y_col;
                Completed_input.Num_p_y_drop = Num_p_y_drop;
                Completed_input.Drape_x = Drape_x;
                Completed_input.Drape_y = Drape_y;
                Completed_input.P_x_col = P_x_col;
                Completed_input.P_x_drop = P_x_drop;
                Completed_input.P_x_all = P_x_all;
                Completed_input.s_cp_x = s_cp_x;
                Completed_input.P_y_col = P_y_col;
                Completed_input.P_y_drop = P_y_drop;
                Completed_input.P_y_all = P_y_all;
                Completed_input.s_cp_y = s_cp_y;
                Completed_input.s_cp = s_cp;
                Completed_input.s_cp_slab = s_cp_slab;
                Completed_input.Rz = Rz;
                Completed_input.ex = ex;
                Completed_input.ey = ey;
                Completed_input.x_hole_input = x_hole_input;
                Completed_input.y_hole_input = y_hole_input;
                Completed_input.Dis_length_Re_X = Dis_length_Re_X;
                Completed_input.Dis_length_Re_Y = Dis_length_Re_Y;
                Completed_input.factor_SDL_LL = factor_SDL_LL;
                Completed_input.Col_position = Col_position;
                Completed_input.D_slab = D_slab;
                Completed_input.D_drop = D_drop;
                Completed_input.Drop_x_original = Drop_x_original;
                Completed_input.Drop_y_original = Drop_y_original;
                Completed_input.Topslabrebar = app.TopslabrebarCheckBox.Value;
                assignin("base","Completed_input",Completed_input);


            jump_step = 0.75;
    
            %% Calculating output           
    
                % Effective depth
                d_drop_eff = Completed_input.d_eff_drop;
                d_slab_eff = Completed_input.d_eff_slab;
                
                % Determining number of perimeters for check
                
                Drop_perimeter_num = ...
                    ceil(min(Drop_x/2 - 2*d_drop_eff - hcx/2, ...
                        Drop_y/2 - 2*d_drop_eff - hcy/2)...
                        /(jump_step*d_drop_eff))+1;
    
                if Drop_perimeter_num < 2
                    txt='The drop is too small'; 
                    im=imread('Error.png');
                    h = msgbox(txt,'Error','custom',im); 
                    waitfor(h);
                end
     
                Slab_perimeter_num = ...
                    min([ceil(min(Check_radius - 2*d_drop_eff - Drop_x/2, ...
                        Check_radius - 2*d_drop_eff - Drop_y/2)...
                        /(jump_step*d_slab_eff))+1,20]);
    
    
                % Processing hole limit
                Hole = FCN2_hole_processing(number_of_hole_input,...
                                x_hole_input, y_hole_input, Check_radius);


                % Intersect (determining u*)
                if str2double(Design_column_or_wall) == 1
                    Drop_sum = FCN1_1_perimeter_determination_radius... 
                        (d_drop_eff, hcx, hcy, Hole, ...
                        Drop_perimeter_num, number_of_hole_input,...
                        jump_step, Col_position);
                else
                    Drop_sum = FCN11_perimeter_determination_radius_wall...
                        (d_drop_eff, hcx, hcy, Hole, ...
                        Drop_perimeter_num, number_of_hole_input,...
                        jump_step);
                end
    
                Slab_sum = FCN1_1_perimeter_determination_radius...
                    (d_slab_eff, Drop_x, Drop_y, Hole, ...
                    Slab_perimeter_num, number_of_hole_input,...
                    jump_step, Col_position);
    
                [Drop_sum, Slab_sum] = FCN4_depth_determin(Drop_sum, Slab_sum,...
                    d_slab_eff, d_drop_eff, Drop_x, Drop_y, Drop_perimeter_num, ...
                    Slab_perimeter_num);
    
    
                % Intersect (determining u)
                if Col_position ~= 1
                    Drop_sum_1 = FCN2_1_perimeter_determination_radius_for_u_in_edge... 
                        (d_drop_eff, hcx, hcy, Hole, ...
                        Drop_perimeter_num, number_of_hole_input,...
                        jump_step, Col_position);
        
                    Slab_sum_1 = FCN2_1_perimeter_determination_radius_for_u_in_edge...
                        (d_slab_eff, Drop_x, Drop_y, Hole, ...
                        Slab_perimeter_num, number_of_hole_input,...
                        jump_step, Col_position);
        
                    [Drop_sum_1, Slab_sum_1] = FCN4_depth_determin(Drop_sum_1, Slab_sum_1,...
                        d_slab_eff, d_drop_eff, Drop_x, Drop_y, Drop_perimeter_num, ...
                        Slab_perimeter_num);
                else
                    Drop_sum_1 = Drop_sum;
                    Slab_sum_1 = Slab_sum;
                end
    
                Drop_sum_1 = FCN17_shear_area(Drop_sum_1,Drop_perimeter_num);
                Slab_sum_1 = FCN17_shear_area(Slab_sum_1,Slab_perimeter_num);
    
                Completed_output.Drop_sum_1 = Drop_sum_1;
                Completed_output.Slab_sum_1 = Slab_sum_1;
    
    
                % Tendon effect
                a2_x = Drape_x;
                s2_x = Span_x - Drop_x_original;
                % w_x_col = 4*P_x_col*a2_x/s2_x;
    
                w_x_drop = 4*P_x_col*a2_x/s2_x;
    
                a2_y = Drape_y;
                s2_y = Span_y - Drop_y_original;
                % w_y_col = 4*P_y_col*a2_y/s2_y;
                w_y_drop = 4*P_y_col*a2_y/s2_y;
     
                Completed_output.w_x_drop = w_x_drop;
                Completed_output.w_y_drop = w_y_drop;
    
    
                % Stress at all perimeter face
    
    
                [Drop_sum, ~] = FCN15_Poly_perimeter_for_area(Drop_sum, ...
                    number_of_hole_input, Hole, Col_position, hcx, ...
                    hcy, Drop_x_original, Drop_y_original, 0); 
    
                [Slab_sum, drop_area] = FCN15_Poly_perimeter_for_area(Slab_sum, ...
                    number_of_hole_input, Hole, Col_position, hcx, ...
                    hcy, Drop_x_original, Drop_y_original, 1);
    
                dis_sef_drop = Drop_thickness/1000*25;
                dis_sef_slab = S_thickness/1000*25;
    
                for i10 = 1:5
                    distributed_load_in_drop(i10,1) = ...
                        factor_SDL_LL(i10,1)*(SDL + dis_sef_drop) ...
                        + factor_SDL_LL(i10,2)*LL;
    
                    distributed_load_in_slab(i10,1) = ...
                        factor_SDL_LL(i10,1)*(SDL + dis_sef_slab) ...
                        + factor_SDL_LL(i10,2)*LL;
    
                    inner_force(i10,1) = distributed_load_in_drop(i10,1)*(drop_area)/10^6;
                end
    
                [stress_tem_drop,Drop_sum] = FCN5_Stress_cal(hcx, ...
                    hcy, Drop_sum, Drop_perimeter_num, Rz, ...
                    ex, ey, 0, 0, distributed_load_in_drop, ... 
                    zeros(5,1), hcx/1000, hcy/1000, Col_position,...
                    d_drop_eff, Design_column_or_wall, 0); 
    
                [stress_tem_slab,Slab_sum] = FCN5_Stress_cal(Drop_x, ...
                    Drop_y, Slab_sum, Slab_perimeter_num, ...
                    Rz, ex, ey, w_x_drop, w_y_drop, distributed_load_in_slab, ...
                    inner_force, Drop_x_original/1000, ...
                    Drop_y_original/1000, Col_position, ...
                    d_slab_eff, Design_column_or_wall, 1);
     
                assignin('base','stress_tem_slab',stress_tem_slab);
                

                % Capacity at column face
                V_Rd_max = 0.5*(fck/gamma_c)*0.6*(1-fck/250);
                Completed_output.V_Rd_max = V_Rd_max;
    
    
                app.Design_notice.Value = 'Design Warning:' ;  
                if V_Rd_max < stress_tem_drop(1,1)
                    String_temp = "Failure at column face";
                    app.Design_notice.Value = [app.Design_notice.Value;  {' '}; String_temp] ;
                    Last_drop = 0;
                    Last_slab = 0;
                    drawnow()
     
                    txt='Failure at column face'; 
                    im=imread('Error.png');
                    h = msgbox(txt,'Error','custom',im); 
                    waitfor(h);
                elseif V_Rd_max < stress_tem_slab(1,1)
                    String_temp = "Failure at drop face";
                    app.Design_notice.Value = [app.Design_notice.Value;  {' '}; String_temp] ;
                    Last_drop = 0;
                    Last_slab = 0;
                    drawnow()
    
                    txt='Failure at column face'; 
                    im=imread('Error.png');
                    h = msgbox(txt,'Error','custom',im); 
                    waitfor(h);
                else
                    % Strength without shear reinforcement in drop
    
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                    
                        W_3D_col_x = hcx + 6*d_drop_eff;
                        W_3D_col_y = hcy + 6*d_drop_eff;
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    
                        W_3D_col_x = hcx + 3*d_drop_eff;
                        W_3D_col_y = hcy + 6*d_drop_eff;
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        W_3D_col_x = hcx + 3*d_drop_eff;
                        W_3D_col_y = hcy + 3*d_drop_eff;
                    
                    end


                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                        Re_y = linspace(-Dis_length_Re_Y/2+25,Dis_length_Re_Y/2-25,num_y_s);
                        num_tem = find(Re_y >= -W_3D_col_x/2 & Re_y <= W_3D_col_x/2);
                        num_rebar_3D_y = length(num_tem);
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                        Re_y = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        num_tem = find(Re_y <= W_3D_col_x-hcx/2);
                        num_rebar_3D_y = length(num_tem);
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                        Re_y = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        num_tem = find(Re_y <= W_3D_col_x-hcx/2);
                        num_rebar_3D_y = length(num_tem);
                    end
    
    
                    if strcmp(app.ColumnpositionDropDown.Value, "Corner")
                        Re_x = linspace(-hcy/2+25,Dis_length_Re_X-hcy/2-25,num_x_s);
                        num_tem = find(Re_x <= W_3D_col_y-hcy/2);
                        num_rebar_3D_x = length(num_tem);
                    else
                        Re_x = linspace(-Dis_length_Re_X/2+25,Dis_length_Re_X/2-25,num_x_s); 
                        num_tem = find(Re_x >= -W_3D_col_y/2 & Re_x <= W_3D_col_y/2);
                        num_rebar_3D_x = length(num_tem);
                    end
    
    
                    % num_rebar_3D_x = floor(W_3D_col_y/Completed_input.spacing_x_drop);
                    % num_rebar_3D_y = floor(W_3D_col_x/Completed_input.spacing_y_drop);
    
                    Rho_x = (num_rebar_3D_x*Area_x_1 + Num_p_x_col*One_stran_area)/D_drop/W_3D_col_y;
                    Rho_y = (num_rebar_3D_y*Area_y_1 + Num_p_y_col*One_stran_area)/D_drop/W_3D_col_x;
    
                    Rho = (Rho_x*Rho_y)^0.5;
    
                    CRd_c = 0.18/gamma_c;
    
                    k_1 = 0.1;
                    k = min(1 + (200/d_drop_eff)^0.5, 2);
    
                    V_Rd_c_drop = CRd_c*k*(100*Rho*fck)^(1/3) + 0.9*k_1*s_cp;
    
                    v_min = 0.035*(k^1.5)*(fck^0.5);
    
                    V_Rd_c_min = v_min + 0.9*k_1*s_cp; 
    
                    V_Rd_c_drop = max(V_Rd_c_drop,V_Rd_c_min);
    
                    % V_Rd_c_drop_2 = max(V_Rd_c_drop,V_Rd_c_min) - 0.9*k_1*s_cp;
    
                    Completed_output.V_Rd_c_drop = V_Rd_c_drop;
    
                    app.Vrdc_drop.Value = V_Rd_c_drop; 
    
                    % Section need punching steel in drop
    
                    Last_drop = 0;
                    for i1 = 2:Drop_perimeter_num
                        if stress_tem_drop(i1) > V_Rd_c_drop
                            Drop_sum(i1).Punching_bar = 1;
                            Last_drop = i1;
                        else
                            Drop_sum(i1).Punching_bar = 0;
                        end
                    end
    
                    if Last_drop ~= 0
                        u_out = Drop_sum(Last_drop).Max_force*1000/V_Rd_c_drop/d_drop_eff;
        
                        dis_out = (u_out - 2*hcx - 2*hcy)/pi/2;
        
        
                        Drop_out_sum = FCN16_perimeter_determination_radius_out... 
                            (d_drop_eff, hcx, hcy, Hole, ...
                            1, ...
                            number_of_hole_input, jump_step, ...
                            dis_out);
    
                        Completed_output.Drop_out_sum = Drop_out_sum;
    
                        app.U_out.Value = u_out;
                        
                        % Last_drop = min([Last_drop + 1,Drop_perimeter_num]);
                    end
    
                    Completed_output.Last_drop = Last_drop;
    
                    % Strength without shear reinforcement in slab
    
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                    
                        W_3D_slab_x = Drop_x + 6*d_slab_eff;
                        W_3D_slab_y = Drop_y + 6*d_slab_eff;
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    
                        W_3D_slab_x = Drop_x_original + 3*d_slab_eff;
                        W_3D_slab_y = Drop_y + 6*d_slab_eff;
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        W_3D_slab_x = Drop_x_original + 3*d_slab_eff;
                        W_3D_slab_y = Drop_y_original + 3*d_slab_eff;
                    
                    end
    
    
                    distance_x_slab = Completed_input.Spacing_slab_x;
                    distance_y_slab = Completed_input.Spacing_slab_y;
                    
                    if Completed_input.Topslabrebar == 1
    
                        num_rebar_3D_x_slab = floor(6*d_slab_eff/distance_x_slab);
                        num_rebar_3D_y_slab = floor(6*d_slab_eff/distance_y_slab);
                    else
                        num_rebar_3D_x_slab = 0;
                        num_rebar_3D_y_slab = 0;
                    end
    
                    A_rebar_3D_x_slab = num_rebar_3D_x_slab*...
                                    Completed_input.Area_x_1_slab...
                                    + num_rebar_3D_x*Area_x_1;
    
                    A_rebar_3D_y_slab = num_rebar_3D_y_slab*...
                                    Completed_input.Area_y_1_slab + ...
                                    num_rebar_3D_y*Area_y_1;
    
                    Rho_x = (A_rebar_3D_x_slab + Num_p_x_drop*One_stran_area)/D_slab/W_3D_slab_y;
                    Rho_y = (A_rebar_3D_y_slab + Num_p_y_drop*One_stran_area)/D_slab/W_3D_slab_x;
    
                    Rho = (Rho_x*Rho_y)^0.5;
    
                    CRd_c = 0.18/gamma_c;
    
                    k_1 = 0.1;
    
                    k = min(1 + (200/d_slab_eff)^0.5, 2);
    
                    V_Rd_c_slab = CRd_c*k*(100*Rho*fck)^(1/3) + 0.9*k_1*s_cp_slab;
    
                    v_min = 0.035*(k^1.5)*(fck^0.5);
    
                    V_Rd_c_min = v_min + 0.9*k_1*s_cp_slab; 
    
                    V_Rd_c_slab = max(V_Rd_c_slab,V_Rd_c_min);
    
                    % V_Rd_c_slab_2 = max(V_Rd_c_slab,V_Rd_c_min) - 0.9*k_1*s_cp_slab;
    
                    Completed_output.V_Rd_c_slab = V_Rd_c_slab;
    
                    app.Vrdc_slab.Value = V_Rd_c_slab;
                    % Section need punching steel in slab
                    Last_slab = 0;
                    for i1 = 2:Slab_perimeter_num
                        if stress_tem_slab(i1) > V_Rd_c_slab
                            Slab_sum(i1).Punching_bar = 1;
                            Last_slab = i1;
                        else
                            Slab_sum(i1).Punching_bar = 0;
                        end
                    end
                    % 
                    % if Last_slab ~= 0
                    %     Last_slab = Last_slab + 1;
                    % end
    
                    Completed_output.Last_slab = Last_slab;
    
    
                    if Last_drop ~= 0
                        % Required link area per perimeter drop
                        d_tem = d_drop_eff;
    
                        fywd_ef = min(250+0.25*d_tem,fyk/gamma_s);
                        Perimeter_num = Drop_perimeter_num;
                        Asw_drop = zeros(Perimeter_num,4);
    
                        for i0 = 2:Last_drop
                            area_tem = Drop_sum_1(i0).area;
    
                            if stress_tem_drop(i0) > V_Rd_c_drop
                                Asw_drop(i0,1) = (stress_tem_drop(i0,1) - ...
                                    0.75*V_Rd_c_drop)...
                                    *area_tem/(2*fywd_ef);
                            else
                                Asw_drop(i0,1) = 0;
                            end
    
                            Asw_drop(i0,2) = ceil(Asw_drop(i0,1)/Completed_input.Area_link);
    
                            ele_tem = Drop_sum(i0).All_element;
                            num_element = length(ele_tem);
                            total_length = sum(ele_tem(:,3).*(ones(num_element,1)-ele_tem(:,4)));
    
                            Asw_drop(i0,3) = min([floor(total_length/Asw_drop(i0,2)/25)*25,floor(1.5*d_tem/25)*25]);
    
                            Asw_drop(i0,2) = ceil(total_length/Asw_drop(i0,3));
    
                            Asw_drop(i0,4) = Asw_drop(i0,2)*Completed_input.Area_link;
    
                            % Drop_sum(i0).total_length = total_length;
                        end
    
                            Completed_output.Asw_drop = Asw_drop;
    
                        % Determining number of perimeters for check
                        if Last_drop ~= Drop_perimeter_num
                            Drop_perimeter_rein_num = ...
                                ceil((Drop_sum(Last_drop).distance_to_edge - 0.5*d_tem)...
                                    /(0.75*d_tem));
                        else
                            Drop_perimeter_rein_num = ...
                                ceil((Drop_sum(Last_drop).distance_to_edge - 0.5*d_tem)...
                                    /(0.75*d_tem)) + 1;
                        end
                        % Intersect
                        if str2double(Design_column_or_wall) == 1
                            Drop_sum_rein = FCN6_perimeter_rein_determination_radius...
                                (d_tem, hcx, hcy, Hole, ...
                                Drop_perimeter_rein_num, number_of_hole_input,...
                                Col_position, hcx, hcy);
                        else
                            Drop_sum_rein = FCN12_perimeter_rein_determination_radius_wall...
                                (d_tem, hcx, hcy, Hole, ...
                                Drop_perimeter_rein_num, number_of_hole_input);
                        end
    
                        Drop_sum_rein = FCN4_1_depth_determin_drop(Drop_sum_rein,...
                        S_thickness, Drop_thickness, Drop_x,...
                        Drop_y, Drop_perimeter_rein_num);
    
     
                        % Shear arrangement
                        peri_num = length(Drop_sum); 
                        Drop_sum = FCN9_Poly_perimeter(Drop_sum, peri_num, number_of_hole_input, Hole); 
    
                        Drop_sum = FCN10_intersection_rebar_control... 
                                (Drop_sum, Drop_sum_rein); 
    
                        required_area_density_per_peri = zeros(Drop_perimeter_rein_num,Last_drop);
                        
                        outter_peri_density = 0;
    
                        for i1 = 2:Last_drop
                            required_area = Asw_drop(i1,1);
    
                            all_length = 0;
                            num_peri = 0;
                            for i2 = 1:length(Drop_sum_rein)
                                all_length = all_length+Drop_sum(i1).rein_in_peri(i2).length_each;
                                if Drop_sum(i1).rein_in_peri(i2).length_each > 0
                                    num_peri = num_peri+1;
                                end
                            end
                            ave_length = all_length/num_peri;
    
    
                            for i2 = 1:length(Drop_sum_rein)
                                if i2 == length(Drop_sum_rein) && ...
                                    Last_drop == Drop_perimeter_num
    
                                    required_area_density_per_peri(i2,i1) = ...
                                        required_area_density_per_peri(i2-1,i1);
    
                                    outter_peri_density = required_area_density_per_peri(i2,i1);
                                elseif Drop_sum(i1).rein_in_peri(i2).length_each > ...
                                                0.6*ave_length
                                    required_area_density_per_peri(i2,i1) = ...
                                    required_area/Drop_sum(i1).rein_in_peri(i2).length_each; 
                                end
                            end
                        end
                                           
                        required_area_per_peri_max_drop = zeros(Drop_perimeter_rein_num,1);
                        for i2 = 1:Drop_perimeter_rein_num
                            required_area_per_peri_max_drop(i2,1) = max(required_area_density_per_peri(i2,:));
                        end
                        Completed_output.required_area_per_peri = required_area_density_per_peri;
    
                        Completed_output.required_area_per_peri_max_drop = required_area_per_peri_max_drop;
    
                        Asw_drop_rein = zeros(Drop_perimeter_rein_num,4);
     
                        for i0 = 1:Drop_perimeter_rein_num 
                            ele_tem = Drop_sum_rein(i0).All_element;
                            total_length = sum(ele_tem(:,3));
    
                            Asw_drop_rein(i0,1) = required_area_per_peri_max_drop(i0)*total_length;
    
                            Asw_drop_rein(i0,2) = ceil(Asw_drop_rein(i0,1)/Completed_input.Area_link);
    
                            Asw_drop_rein(i0,3) = (total_length)/Asw_drop_rein(i0,2);
    
                            Asw_drop_rein(i0,2) = ceil(total_length/Asw_drop_rein(i0,3));
    
                            Asw_drop_rein(i0,4) = Asw_drop_rein(i0,2)*Completed_input.Area_link;
    
                            Drop_sum_rein(i0).total_length = total_length;
                        end
        
                        if str2double(Design_column_or_wall) == 1
                            Drop_sum_rein = FCN8_Arrangement_of_shear...
                                (Asw_drop_rein,Drop_sum_rein, ...
                                d_tem, hcx, hcy, ...
                                Hole, Drop_perimeter_rein_num,...
                                number_of_hole_input, Area_link,... 
                                Drop_sum, Asw_drop, Last_drop,...
                                outter_peri_density, Drop_x, Drop_y,...
                                Col_position, hcx, hcy);
                        else
                            Drop_sum_rein = FCN13_Arrangement_of_shear_wall...
                                (Asw_drop_rein,Drop_sum_rein, d_tem, hcx, hcy, ...
                                Hole, Drop_perimeter_rein_num, ...
                                number_of_hole_input, Area_link,...
                                Drop_sum, Asw_drop, Last_drop,...
                                outter_peri_density, Drop_x, Drop_y);
                        end 
      
                        Completed_output.Asw_drop_rein = Asw_drop_rein;
                        Completed_output.Drop_sum_rein = Drop_sum_rein;
    
                    else 
                        String_temp = "No need shear rebars for drop";
                        app.Design_notice.Value = [app.Design_notice.Value;  {' '}; String_temp] ;
                        drawnow()
    
                    end
    
                    if Last_slab ~= 0
                    % Required link area per perimeter slab
                        d_tem = d_slab_eff;
    
                        fywd_ef = min(250+0.25*d_tem,fyk/gamma_s);
                        Perimeter_num = Slab_perimeter_num;
                        Asw_slab = zeros(Perimeter_num,4);
    
                        for i0 = 2:Last_slab
                            if stress_tem_slab(i0) > V_Rd_c_slab
                                area_tem = Slab_sum_1(i0).area;
                                Asw_slab(i0,1) = (stress_tem_slab(i0,1) - ...
                                    0.75*V_Rd_c_slab)...
                                    *area_tem/(2*fywd_ef);
                            else
                                Asw_slab(i0,1) = 0;
                            end
    
                            Asw_slab(i0,2) = ceil(Asw_slab(i0,1)/Completed_input.Area_link);
    
                            ele_tem = Slab_sum(i0).All_element;
                            num_element = length(ele_tem);
                            total_length = sum(ele_tem(:,3).*(ones(num_element,1)-ele_tem(:,4)));
                            Asw_slab(i0,3) = min([(total_length-200)/Asw_slab(i0,2),floor(1.5*d_tem/25)*25]);
    
                            Asw_slab(i0,2) = ceil(total_length/Asw_slab(i0,3));
    
                            Asw_slab(i0,4) = Asw_slab(i0,2)*Completed_input.Area_link;
    
                            % Slab_sum(i0).total_length = total_length;
                        end
                            Completed_output.Asw_slab = Asw_slab;
    
    
                            Slab_perimeter_rein_num = ...
                                ceil((Slab_sum(Last_slab).distance_to_edge - 0.5*d_tem)...
                                    /(0.75*d_tem));
    
    
                            Slab_sum_rein = FCN6_perimeter_rein_determination_radius...
                                (d_tem, Drop_x, Drop_y, Hole, ...
                                Slab_perimeter_rein_num, number_of_hole_input, ...
                                Col_position, hcx, hcy);
    
                            Slab_sum_rein = FCN4_2_depth_determin_slab(Slab_sum_rein,...
                                S_thickness, Drop_thickness, Drop_x, Drop_y, ...
                                Slab_perimeter_rein_num);
    
                        % Shear arrangement
                        peri_num = length(Slab_sum); 
    
                        Slab_sum = FCN9_Poly_perimeter(Slab_sum, peri_num, number_of_hole_input, Hole);
    
                        Slab_sum = FCN10_intersection_rebar_control... 
                                (Slab_sum, Slab_sum_rein);
    
                        required_area_density_per_peri = zeros(Slab_perimeter_rein_num,Last_slab);
    
    
                        for i1 = 2:Last_slab
                            required_area = Asw_slab(i1,1);
                            all_length = 0;
                            num_peri = 0;
                            for i2 = 1:length(Slab_sum_rein)
                                all_length = all_length+Slab_sum(i1).rein_in_peri(i2).length_each;
                                if Slab_sum(i1).rein_in_peri(i2).length_each > 0
                                    num_peri = num_peri+1;
                                end
                            end
                            ave_length = all_length/num_peri;
    
                            for i2 = 1:length(Slab_sum_rein)
                                if Slab_sum(i1).rein_in_peri(i2).length_each ~= 0
       
                                    if Slab_sum(i1).rein_in_peri(i2).length_each > ...
                                           ave_length*0.5
                                           
                                        required_area_density_per_peri(i2,i1) = required_area/...
                                            Slab_sum(i1).rein_in_peri(i2).length_each;
        
                                    end
                                end
                            end
                        end
    
                        required_area_per_peri_max_slab = zeros(Slab_perimeter_rein_num,1);
                        for i2 = 1:Slab_perimeter_rein_num
                            required_area_per_peri_max_slab(i2,1) = max(required_area_density_per_peri(i2,:));
                        end
    
                        Completed_output.required_area_per_peri_max_slab = required_area_per_peri_max_slab;
    
                        Asw_slab_rein = zeros(Slab_perimeter_rein_num,1);
    
                        for i0 = 1:Slab_perimeter_rein_num
                            ele_tem = Slab_sum_rein(i0).All_element;
                            total_length = sum(ele_tem(:,3));
    
                            Asw_slab_rein(i0,1) = required_area_per_peri_max_slab(i0)*total_length;
    
                            Asw_slab_rein(i0,2) = ceil(Asw_slab_rein(i0,1)/Completed_input.Area_link);
    
                            Asw_slab_rein(i0,3) = (total_length)/Asw_slab_rein(i0,2);
    
                            Asw_slab_rein(i0,2) = ceil(total_length/Asw_slab_rein(i0,3));
    
                            Asw_slab_rein(i0,4) = Asw_slab_rein(i0,2)*Completed_input.Area_link;
    
                            Slab_sum_rein(i0).total_length = total_length;
                        end
    
                        Slab_sum_rein = FCN8_Arrangement_of_shear...
                            (Asw_slab_rein,Slab_sum_rein, d_tem, Drop_x, Drop_y, ...
                            Hole, Slab_perimeter_rein_num, number_of_hole_input,...
                            Area_link, Slab_sum, Asw_slab, Last_slab, 0,...
                            Drop_x, Drop_y, Col_position, hcx, hcy);
    
                        Completed_output.Asw_slab_rein = Asw_slab_rein;
                        Completed_output.Slab_sum_rein = Slab_sum_rein;
                    else
                        String_temp = "No need shear rebars for slab";
                        app.Design_notice.Value = [app.Design_notice.Value;  {' '}; String_temp] ;
                        drawnow()
                    end
    
                end
    
                Completed_output.stress_tem_drop = stress_tem_drop;
                Completed_output.stress_tem_slab = stress_tem_slab;
    
                Completed_output.Drop_sum = Drop_sum;
                Completed_output.Slab_sum = Slab_sum;


                %% Result tables
                    assignin("base","Drop_sum",Drop_sum);
    
                app.Result_table_drop.Data = zeros(Drop_perimeter_num,9);
    
    
                if Last_drop ~= 0
    
                    down_stand_sum = Drop_sum;
                    down_stand_sum_rein = Drop_sum_rein;
                    Perimeter_num = Drop_perimeter_num;
                    d_tem = d_drop_eff;
                    Asw = Asw_drop;
                    stess_ar = stress_tem_drop;
    
                    drop_table_1 = zeros(Perimeter_num,3);
                    drop_table_2 = cell(Perimeter_num,2);
                    drop_table_3 = zeros(Perimeter_num,5);
    
                    [down_stand_sum,~] = FCN14_reinf_each_perimeter...
                            (down_stand_sum, down_stand_sum_rein);
    
                    for i1 = 1:Perimeter_num
                        drop_table_1(i1,:) = [i1, ...
                            max([down_stand_sum(i1).distance_to_edge - 1.5*d_tem,0]),...
                            down_stand_sum(i1).distance_to_edge];
                            
    
                        if ~isempty(down_stand_sum(i1).shear_peri) 
                            text_tem = append(num2str(down_stand_sum(i1).shear_peri(1)));
                            num_tem = length(down_stand_sum(i1).shear_peri);
    
                            if num_tem >= 2
                                for i2 = 2:num_tem
                                    text_tem = append(text_tem,"+",...
                                        num2str(down_stand_sum(i1).shear_peri(i2)));
                                end
                            end
    
                            drop_table_2(i1,1) = {text_tem};
    
                            rein_peri_tem = down_stand_sum(i1).shear_peri(1); 
    
                            total_rein = down_stand_sum(i1).rein_consider(rein_peri_tem).total;
    
    
                            text_tem = append(num2str(total_rein));
    
                            if num_tem >= 2
                                for i2 = 2:num_tem
                                    rein_peri_tem = down_stand_sum(i1).shear_peri(i2);
    
                                    total_rein = down_stand_sum(i1).rein_consider(rein_peri_tem).total;
    
                                    text_tem = append(text_tem,"+",...
                                        num2str(total_rein));
    
                                end
                            end
    
                            drop_table_2(i1,2) = {text_tem};
                        end
                        
                        drop_table_3(i1,:) = ...
                            [down_stand_sum(i1).total_length, ...
                            down_stand_sum(i1).area, ...
                            down_stand_sum(i1).Max_force, ...
                            stess_ar(i1,1), ...
                            max([0,Asw(i1,1)])];
                    end 
    
    
                    drop_table_1 = array2table(drop_table_1);
                    drop_table_2 = array2table(drop_table_2);
                    drop_table_3 = array2table(drop_table_3);
    
                    drop_table = [drop_table_1, drop_table_2, drop_table_3];
    
                    app.Result_table_drop.Data = drop_table;
                    Completed_output.drop_table = drop_table;


                    rein_table_1 = zeros(length(down_stand_sum_rein),9);
    
    
                    for i1 = 1:length(down_stand_sum_rein)
                        
                        Num_x = 0;
                        Num_y = 0;
    
                        for i2 = 1:2:3
                            f_tem = down_stand_sum_rein(i1).L_rein(i2).intersect == 0;
                            Num_x = Num_x + sum(f_tem,"all");
                        end
    
                        for i2 = 2:2:4
                            f_tem = down_stand_sum_rein(i1).L_rein(i2).intersect == 0;
                            Num_y = Num_y + sum(f_tem,"all");
                        end
    
                        rein_table_1(i1,:) = [i1, ...
                            down_stand_sum_rein(i1).distance_to_edge, ...
                            down_stand_sum_rein(i1).L_rein(1).ele_size, ...
                            down_stand_sum_rein(i1).L_rein(2).ele_size, ...
                            1.5*d_tem, Num_x, Num_y, Num_x + Num_y,...
                            (Num_x+Num_y)*Area_link];
    
                    end 
    
                    total_mass_rein = sum(rein_table_1(:,9)*(1e-6))*...
                        (app.d_eff_drop_in.Value + 15*Completed_input.Dia_link)*7850/1000;
                    
                    disp((app.Link_dia_in.Value));
                    
                    app.Shear_on_drop.Value = total_mass_rein;
    
                    rein_table_1 = array2table(rein_table_1);
    
                    app.Result_table_rein.Data = rein_table_1;
    
                    app.Result_table_rein_3.Data = rein_table_1;
    
                    Completed_output.drop_rein_table = rein_table_1;
    
                else
                    down_stand_sum = Drop_sum;
                    Perimeter_num = 2;
                    d_tem = d_drop_eff;
                    stess_ar = stress_tem_drop;
    
                    drop_table_1 = zeros(Perimeter_num,3);
                    drop_table_2 = cell(Perimeter_num,2);
                    drop_table_3 = zeros(Perimeter_num,5);
    
    
                    for i1 = 1:Perimeter_num
                        drop_table_1(i1,:) = [i1, ...
                            max([down_stand_sum(i1).distance_to_edge - 1.5*d_tem,0]),...
                            down_stand_sum(i1).distance_to_edge];


                            drop_table_2(i1,1) = {'NA'};
                            drop_table_2(i1,2) = {'NA'};
                        
                        drop_table_3(i1,:) = ...
                            [down_stand_sum(i1).total_length, ...
                            down_stand_sum(i1).area, ...
                            down_stand_sum(i1).Max_force, ...
                            stess_ar(i1,1), ...
                            0];
                    end 
    
    
                    drop_table_1 = array2table(drop_table_1);
                    drop_table_2 = array2table(drop_table_2);
                    drop_table_3 = array2table(drop_table_3);
    
                    drop_table = [drop_table_1, drop_table_2, drop_table_3];
    
                    app.Result_table_drop.Data = drop_table;
                    Completed_output.drop_table = drop_table;


                    rein_table_1 = zeros(0,9);
    
                    total_mass_rein = 0;
                                   
                    app.Shear_on_drop.Value = total_mass_rein;
    
                    rein_table_1 = array2table(rein_table_1);
    
                    app.Result_table_rein.Data = rein_table_1;
    
                    app.Result_table_rein_3.Data = rein_table_1;
    
                    Completed_output.drop_rein_table = rein_table_1;
                end


                app.Result_table_slab.Data = zeros(Slab_perimeter_num,9);
    
                if Last_slab ~= 0
    
                    down_stand_sum = Slab_sum;
                    down_stand_sum_rein = Slab_sum_rein;
                    Perimeter_num = Slab_perimeter_num;
                    d_tem = d_slab_eff;
                    Asw = Asw_slab;
                    stess_ar = stress_tem_slab;
    
                    drop_table_1 = zeros(Perimeter_num,3);
                    drop_table_2 = cell(Perimeter_num,2);
                    drop_table_3 = zeros(Perimeter_num,5);
    
                    [down_stand_sum,~] = FCN14_reinf_each_perimeter...
                            (down_stand_sum, down_stand_sum_rein);
    
                    for i1 = 1:Perimeter_num
                        drop_table_1(i1,:) = [i1, ...
                            max([down_stand_sum(i1).distance_to_edge - 1.5*d_tem,0]),...
                            down_stand_sum(i1).distance_to_edge];
                            
                        if ~isempty(down_stand_sum(i1).shear_peri)
                            text_tem = append(num2str(down_stand_sum(i1).shear_peri(1)));
                            num_tem = length(down_stand_sum(i1).shear_peri);
    
                            if num_tem >= 2
                                for i2 = 2:num_tem
                                    text_tem = append(text_tem,"+",...
                                        num2str(down_stand_sum(i1).shear_peri(i2)));
                                end
                            end
    
                            assignin("base","text_tem",text_tem);
    
                            drop_table_2(i1,1) = {text_tem};
    
                            rein_peri_tem = down_stand_sum(i1).shear_peri(1);
    
                            total_rein = down_stand_sum(i1).rein_consider(rein_peri_tem).total;
                            
                            text_tem = append(num2str(total_rein));
    
                            if num_tem >= 2
                                for i2 = 2:num_tem
                                    rein_peri_tem = down_stand_sum(i1).shear_peri(i2);
    
                                    total_rein = down_stand_sum(i1).rein_consider(rein_peri_tem).total;
    
                                    text_tem = append(text_tem,"+",...
                                        num2str(total_rein));
                                end 
                            end
    
                            drop_table_2(i1,2) = {text_tem};
                        end
    
                        drop_table_3(i1,:) = ...
                            [down_stand_sum(i1).total_length, ...
                            down_stand_sum(i1).area, ...
                            down_stand_sum(i1).Max_force, ...
                            stess_ar(i1,1), ...
                            max([0,Asw(i1,1)])];
                    end 
    
    
                    drop_table_1 = array2table(drop_table_1);
                    drop_table_2 = array2table(drop_table_2);
                    drop_table_3 = array2table(drop_table_3);
    
                    drop_table = [drop_table_1, drop_table_2, drop_table_3];
                    Completed_output.slab_table = drop_table;
                    app.Result_table_slab.Data = drop_table;


                    rein_table_1 = zeros(length(down_stand_sum_rein),9);
    
    
                    for i1 = 1:length(down_stand_sum_rein)
                        
                        Num_x = 0;
                        Num_y = 0;
    
                        for i2 = 1:2:3
                            f_tem = down_stand_sum_rein(i1).L_rein(i2).intersect == 0;
                            Num_x = Num_x + sum(f_tem,"all");
                        end
    
                        for i2 = 2:2:4
                            f_tem = down_stand_sum_rein(i1).L_rein(i2).intersect == 0;
                            Num_y = Num_y + sum(f_tem,"all");
                        end
    
                        rein_table_1(i1,:) = [i1, ...
                            down_stand_sum_rein(i1).distance_to_edge, ...
                            down_stand_sum_rein(i1).L_rein(1).ele_size, ...
                            down_stand_sum_rein(i1).L_rein(2).ele_size, ...
                            1.5*d_tem, Num_x, Num_y, Num_x + Num_y,...
                            (Num_x+Num_y)*Area_link];
    
                    end 
    
                    total_mass_rein = sum(rein_table_1(:,9)*(1e-6))*...
                        (app.d_eff_slab_in.Value + 15*Completed_input.Dia_link)*7850/1000;
    
                    app.Shear_on_slab.Value = total_mass_rein;
    
                    rein_table_1 = array2table(rein_table_1);
    
                    app.Result_table_rein_2.Data = rein_table_1;
    
                    app.Result_table_rein_4.Data = rein_table_1;
    
                    Completed_output.slab_rein_table = rein_table_1;
    
                else
    
                    down_stand_sum = Slab_sum;
                    Perimeter_num = 2;
                    d_tem = d_slab_eff;
                    stess_ar = stress_tem_slab;
    
                    drop_table_1 = zeros(Perimeter_num,3);
                    drop_table_2 = cell(Perimeter_num,2);
                    drop_table_3 = zeros(Perimeter_num,5);
    
                    for i1 = 1:Perimeter_num
                        drop_table_1(i1,:) = [i1, ...
                            max([down_stand_sum(i1).distance_to_edge - 1.5*d_tem,0]),...
                            down_stand_sum(i1).distance_to_edge];
                            
    
                        drop_table_2(i1,1) = {'NA'};
    
                        drop_table_2(i1,2) = {'NA'};
    
                        drop_table_3(i1,:) = ...
                            [down_stand_sum(i1).total_length, ...
                            down_stand_sum(i1).area, ...
                            down_stand_sum(i1).Max_force, ...
                            stess_ar(i1,1), ...
                            0];
                    end 
    
    
                    drop_table_1 = array2table(drop_table_1);
                    drop_table_2 = array2table(drop_table_2);
                    drop_table_3 = array2table(drop_table_3);
    
                    drop_table = [drop_table_1, drop_table_2, drop_table_3];
                    Completed_output.slab_table = drop_table;
                    app.Result_table_slab.Data = drop_table;
    
                    rein_table_1 = zeros(0,9);
    
                    total_mass_rein = 0;
    
                    app.Shear_on_slab.Value = total_mass_rein;
    
                    rein_table_1 = array2table(rein_table_1);
    
                    app.Result_table_rein_2.Data = rein_table_1;
    
                    app.Result_table_rein_4.Data = rein_table_1;
    
                    Completed_output.slab_rein_table = rein_table_1;
                end
    
                app.Total_reinf_drop_slab.Value = ...
                    app.Shear_on_drop.Value + app.Shear_on_slab.Value;
    
                app.Design_notice_2.Value = app.Design_notice.Value;
                
                Data_name = app.NameforsavingfileEditField.Value;
    
                Completed_output.Data_name = Data_name;
                
                assignin("base","Completed_output",Completed_output);
    
                % name_4_save = char(Data_name);
    
                % name_4_save_in = char(append(name_4_save,"_Input_file"));
    
                % save(name_4_save_in,'Completed_input');
    
    
                txt='Done'; 
                im=imread('OK.jpg');
                h = msgbox(txt,'Completed','custom',im); 
                waitfor(h);
    
                app.Image.ImageSource = "Finish.PNG";
        catch
                txt='Wrong inputs'; 
                im=imread('Error.png');
                h = msgbox(txt,'Error','custom',im); 
                waitfor(h);
        end

        end

        % Button pushed function: MinrebarButton
        function MinrebarButtonPushed(app, event)
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;


            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
                       
            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);

            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop, Drop_y]);
                Dis_length_Re_Y = min([app.Cx_in.Value + 6*d_eff_drop, Drop_x]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop, Drop_y]);
                Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop, Drop_x]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                Dis_length_Re_X = min([app.Cy_in.Value + 3*d_eff_drop, Drop_y]);
                Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop, Drop_x]);

            end


            % Minimum rebar
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                A_s_x_min = (Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness)*0.075/100;
                A_s_y_min = (Drop_x*Drop_thickness + (Span_x-Drop_x)*S_thickness)*0.075/100;

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                A_s_x_min = (Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness)*0.075/100;
                A_s_y_min = (Drop_x*Drop_thickness + (Span_x/2-Drop_x)*S_thickness)*0.075/100;

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                A_s_x_min = (Drop_y*Drop_thickness + (Span_y/2-Drop_y)*S_thickness)*0.075/100;
                A_s_y_min = (Drop_x*Drop_thickness + (Span_x/2-Drop_x)*S_thickness)*0.075/100;

            end

            num_x_min = ceil(A_s_x_min/Area_x_1);
            num_y_min = ceil(A_s_y_min/Area_y_1);


            Re_x = linspace(25,Dis_length_Re_X-25,num_x_min);
            distance_x = floor((Re_x(2)-Re_x(1))/25)*25;


            Re_y = linspace(25,Dis_length_Re_Y-25,num_y_min);
            distance_y = floor((Re_y(2)-Re_y(1))/25)*25;


            assignin("base","Dis_length_Re_X",Dis_length_Re_X);
            assignin("base","Dis_length_Re_Y",Dis_length_Re_Y);

            app.Spacing_x_in.Value = distance_x;
            app.Spacing_y_in.Value = distance_y;

            
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                app.L_s_x.Value = Drop_x + min([50*Dia_x*2,1200]);
                app.L_s_y.Value = Drop_y + min([50*Dia_y*2,1200]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                app.L_s_x.Value = Drop_x + min([50*Dia_x,600]);
                app.L_s_y.Value = Drop_y + min([50*Dia_y*2,1200]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                app.L_s_x.Value = Drop_x + min([50*Dia_x,600]);
                app.L_s_y.Value = Drop_y + min([50*Dia_y,600]);

            end

            app.n_s_x_in.Value = num_x_min;
            app.n_s_y_in.Value = num_y_min;

        end

        % Value changed function: Lx_in
        function Lx_inValueChanged(app, event)
            value = app.Lx_in.Value;
            app.Bx_in.Value = ceil(value*1000/3/50)*50;
        end

        % Value changed function: Ly_in
        function Ly_inValueChanged(app, event)
            value = app.Ly_in.Value;
            app.By_in.Value = ceil(value*1000/3/50)*50;
        end

        % Callback function
        function CalculateeffectivedepthButtonPushed(app, event)
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Total_p_x*One_stran_area;
            A_y_p_slab = Total_p_y*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;

        end

        % Button pushed function: Step7PlottingslabopeningButton
        function Step7PlottingslabopeningButtonPushed(app, event)
            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;
            Check_radius = max([Span_x,Span_y]);

            % Dimensions of column
            hcx = app.Cx_in.Value;
            hcy = app.Cy_in.Value;

            % Edge points of hole
            hole_table = app.UITable_hole.Data;
            tem = hole_table(:,2) == 0 &...
                  hole_table(:,3) == 0 & ...
                  hole_table(:,4) == 0 & ...
                  hole_table(:,5) == 0;

            hole_table(tem,:) = [];
            
            no_hole = 0;
            
            if ~isempty(hole_table)   
                [number_of_hole_input,~] = size(hole_table);
                
                x_hole_input = hole_table(:,[2,4]);
                y_hole_input = hole_table(:,[3,5]);

                number_of_hole = number_of_hole_input;
                
                Hole = struct;
                for i1 = 1:number_of_hole
                    Hole(i1).x_hole = x_hole_input(i1,:);
                    Hole(i1).y_hole = y_hole_input(i1,:);
                
                    Hole(i1).a1 = Hole(i1).y_hole(1)/Hole(i1).x_hole(1);
                    Hole(i1).a2 = Hole(i1).y_hole(2)/Hole(i1).x_hole(2);
                end
            else
                no_hole = 1;
                number_of_hole_input = 0;
            end
            
            % Slab % drop
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            cla(app. UIAxes)
            hold(app.UIAxes,'on');
            axis(app.UIAxes,'equal');

            if strcmp(app.ColumnpositionDropDown.Value, "Edge")
                xline(app.UIAxes,-hcx/2,"Color","r","LineWidth",3);
                
            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                plot(app.UIAxes,[-hcx/2,10e100],[-hcy/2,-hcy/2],"Color","r","LineWidth",3);
                plot(app.UIAxes,[-hcx/2,-hcx/2],[-hcy/2,10e100],"Color","r","LineWidth",3);
            
            end

            clear coor_plot_tem
            coor_plot_tem(1,:) = [-hcx/2, hcx/2, hcx/2, -hcx/2, -hcx/2];
            coor_plot_tem(2,:) = [hcy/2, hcy/2, -hcy/2, -hcy/2, hcy/2];
            
            plot(app.UIAxes,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");
            patch(app.UIAxes,coor_plot_tem(1,:),coor_plot_tem(2,:),'k','EdgeColor','k')

                        
            clear coor_plot_tem
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                coor_plot_tem(1,:) = [-Drop_x/2, Drop_x/2, Drop_x/2, -Drop_x/2, -Drop_x/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.UIAxes,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.UIAxes,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y-hcy/2, Drop_y-hcy/2, -hcy/2, -hcy/2, Drop_y-hcy/2];
                plot(app.UIAxes,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            end

            Fic_w = zeros(number_of_hole_input,1);
            
            if no_hole ~= 1
                for i1 = 1:number_of_hole_input
                    x_hole = Hole(i1).x_hole;
                    y_hole = Hole(i1).y_hole;
                
                    % a1 = Hole(i1).a1;
                    % a2 = Hole(i1).a2;
                
                    scatter(app.UIAxes,x_hole,y_hole,"MarkerEdgeColor","m","Marker","x", "LineWidth",10);
                
                    x_far_1 = x_hole(1)*Check_radius*1.2;
                    y_far_1 = y_hole(1)*Check_radius*1.2;
                    x_far_2 = x_hole(2)*Check_radius*1.2;
                    y_far_2 = y_hole(2)*Check_radius*1.2;
                
                    plot(app.UIAxes,[0,x_far_1],[0,y_far_1],"Color","r", "LineStyle","--");
                    plot(app.UIAxes,[0,x_far_2],[0,y_far_2],"Color","r", "LineStyle","--");
                
                    Fic_w(i1) = 0.1*((x_hole(1)-x_hole(2))^2 + (y_hole(1)-y_hole(2))^2)^0.5;
                
                    x_hole_fic = [x_hole(1), (x_hole(1)+x_hole(2))/2+Fic_w(i1), ...
                        x_hole(2), (x_hole(1)+x_hole(2))/2-Fic_w(i1), x_hole(1)];
                
                    y_hole_fic = [y_hole(1), (y_hole(1)+y_hole(2))/2+Fic_w(i1), ...
                        y_hole(2), (y_hole(1)+y_hole(2))/2-Fic_w(i1), y_hole(1)];
                
                    % plot(x_hole_fic,y_hole_fic,"Color","m", "LineStyle","--","LineWidth",2);+
                    hole_gon = polyshape(x_hole_fic,y_hole_fic);
                    plot(app.UIAxes,hole_gon, "FaceColor","w", "LineWidth", 5);
                    plot(app.UIAxes,x_hole,y_hole,"Color","k", "LineStyle","--","LineWidth",2);
                    plot(app.UIAxes,x_hole_fic(1,[2,4]),y_hole_fic(1,[2,4]),"Color","k", "LineStyle","--","LineWidth",1);
                
                end
            end
                       
            xlim(app.UIAxes,[-Check_radius*1.2,Check_radius*1.2])
            ylim(app.UIAxes,[-Check_radius*1.2,Check_radius*1.2])

            hold(app.UIAxes,'off');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            Completed_input = evalin("base","Completed_input");
            Completed_output = evalin("base","Completed_output");

            % Dimensions of column
            hcx = Completed_input.hcx;
            hcy = Completed_input.hcy;
            
            % Edge points of hole
            hole_table = Completed_input.hole_table;
            tem = hole_table(:,2) == 0 &...
                  hole_table(:,3) == 0 & ...
                  hole_table(:,4) == 0 & ...
                  hole_table(:,5) == 0;

            hole_table(tem,:) = [];
            
            no_hole = 0;
            
            if ~isempty(hole_table)   
                [number_of_hole_input,~] = size(hole_table);
                
                x_hole_input = hole_table(:,[2,4]);
                y_hole_input = hole_table(:,[3,5]);

                number_of_hole = number_of_hole_input;
                
                Hole = struct;
                for i1 = 1:number_of_hole
                    Hole(i1).x_hole = x_hole_input(i1,:);
                    Hole(i1).y_hole = y_hole_input(i1,:);
                
                    Hole(i1).a1 = Hole(i1).y_hole(1)/Hole(i1).x_hole(1);
                    Hole(i1).a2 = Hole(i1).y_hole(2)/Hole(i1).x_hole(2);
                end
            else
                no_hole = 1;
                number_of_hole_input = 0;
            end
            
            % Slab % drop
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;


            cla(app. Plotting_result)
            hold(app.Plotting_result,'on');
            axis(app.Plotting_result,'equal');
            
            clear coor_plot_tem
            coor_plot_tem(1,:) = [-hcx/2, hcx/2, hcx/2, -hcx/2, -hcx/2];
            coor_plot_tem(2,:) = [hcy/2, hcy/2, -hcy/2, -hcy/2, hcy/2];
            
            plot(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");
            patch(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),'k','EdgeColor','k')
                        
            clear coor_plot_tem
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                coor_plot_tem(1,:) = [-Drop_x/2, Drop_x/2, Drop_x/2, -Drop_x/2, -Drop_x/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y-hcy/2, Drop_y-hcy/2, -hcy/2, -hcy/2, Drop_y-hcy/2];
                plot(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            end
                                  
            Fic_w = zeros(number_of_hole_input,1);
            
            if app.OpeningCheckBox.Value == 1
                if no_hole ~= 1
                    for i1 = 1:number_of_hole_input
                        x_hole = Hole(i1).x_hole;
                        y_hole = Hole(i1).y_hole;
                    
                        % a1 = Hole(i1).a1;
                        % a2 = Hole(i1).a2;
                    
                        scatter(app.Plotting_result,x_hole,y_hole,"MarkerEdgeColor","m","Marker","x", "LineWidth",10);
                    

                        L1 = (x_hole(1)^2+y_hole(1)^2)^0.5;
                        L2 = (x_hole(2)^2+y_hole(2)^2)^0.5;

                        x_far_1 = x_hole(1)*5000/L1;
                        y_far_1 = y_hole(1)*5000/L1;
                        x_far_2 = x_hole(2)*5000/L2;
                        y_far_2 = y_hole(2)*5000/L2;
                    
                        plot(app.Plotting_result,[0,x_far_1],[0,y_far_1],"Color","r", "LineStyle","--");
                        plot(app.Plotting_result,[0,x_far_2],[0,y_far_2],"Color","r", "LineStyle","--");
                    
                        Fic_w(i1) = 0.1*((x_hole(1)-x_hole(2))^2 + (y_hole(1)-y_hole(2))^2)^0.5;
                    
                        x_hole_fic = [x_hole(1), (x_hole(1)+x_hole(2))/2+Fic_w(i1), ...
                            x_hole(2), (x_hole(1)+x_hole(2))/2-Fic_w(i1), x_hole(1)];
                    
                        y_hole_fic = [y_hole(1), (y_hole(1)+y_hole(2))/2+Fic_w(i1), ...
                            y_hole(2), (y_hole(1)+y_hole(2))/2-Fic_w(i1), y_hole(1)];
                    
                        % plot(x_hole_fic,y_hole_fic,"Color","m", "LineStyle","--","LineWidth",2);+
                        hole_gon = polyshape(x_hole_fic,y_hole_fic);
                        plot(app.Plotting_result,hole_gon, "FaceColor","w", "LineWidth", 2);
                        plot(app.Plotting_result,x_hole,y_hole,"Color","k", "LineStyle","--","LineWidth",2);
                        plot(app.Plotting_result,x_hole_fic(1,[2,4]),y_hole_fic(1,[2,4]),"Color","k", "LineStyle","--","LineWidth",1);
                    
                    end
                end
            end

            
            if app.LongitudinalreinforcementsCheckBox.Value == 1
                num_x_s = Completed_input.num_x_s;
                num_y_s = Completed_input.num_y_s;

                L_s_x_store = Completed_input.L_s_x_store;
                L_s_y_store = Completed_input.L_s_y_store;

                Dis_length_Re_X = Completed_input.Dis_length_Re_X;
                Dis_length_Re_Y = Completed_input.Dis_length_Re_Y;

                %% Thép đứng trong nấm
                clear coor_plot_X coor_plot_Y
                if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                    coor_plot_X = linspace(-Dis_length_Re_Y/2+25,Dis_length_Re_Y/2-25,num_y_s);
                    coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];

                elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                    coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                    coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
                
                elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                
                    coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                    coor_plot_Y = [-hcy/2,L_s_y_store-hcy/2];
                end


                for i1 = 1:length(coor_plot_X)
                    plot(app.Plotting_result,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                        "Color","b", "LineStyle","-","LineWidth",1);
                end

                plot(app.Plotting_result,[coor_plot_X(1),coor_plot_X(end)],...
                    [max(coor_plot_Y)*1.2,max(coor_plot_Y)*1.2],...
                        "Color","k", "LineStyle","-","LineWidth",0.5);

                plot(app.Plotting_result,[coor_plot_X(1),coor_plot_X(1)],...
                    abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                        "Color","k", "LineStyle","-","LineWidth",0.5);

                plot(app.Plotting_result,[coor_plot_X(end),coor_plot_X(end)],...
                    abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                        "Color","k", "LineStyle","-","LineWidth",0.5);

                if str2double(app.XrebarsSwitch.Value) == 0
                    text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T2 - L = ", num2str(app.L_s_y.Value));
                else
                    text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T1 - L = ", num2str(app.L_s_y.Value));
                end
                text(app.Plotting_result,(max(coor_plot_X)+min(coor_plot_X))/2,...
                    max(coor_plot_Y)*1.2,text_tem,"HorizontalAlignment",...
                    "center","VerticalAlignment","bottom");
                

                %% Thép ngang trong nấm
                clear coor_plot_X coor_plot_Y

                coor_plot_Y = linspace(-Dis_length_Re_X/2+25,Dis_length_Re_X/2-25,num_x_s);
                coor_plot_X = [-L_s_x_store/2,L_s_x_store/2];


                if strcmp(app.ColumnpositionDropDown.Value, "Edge")
                
                    coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                
                elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                
                    coor_plot_Y = linspace(-hcy/2+25,Dis_length_Re_X-hcy/2-25,num_x_s);
                    coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                end


                for i1 = 1:length(coor_plot_Y)
                    plot(app.Plotting_result,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                        "Color","b", "LineStyle","-","LineWidth",1);
                end

                plot(app.Plotting_result,abs([max(coor_plot_X)*1.2,max(coor_plot_X)*1.2]),...
                    [coor_plot_Y(1),coor_plot_Y(end)],...
                        "Color","k", "LineStyle","-","LineWidth",0.5);

                plot(app.Plotting_result,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                    [coor_plot_Y(1),coor_plot_Y(1)],...
                        "Color","k", "LineStyle","-","LineWidth",0.5);

                plot(app.Plotting_result,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                    [coor_plot_Y(end),coor_plot_Y(end)],...
                        "Color","k", "LineStyle","-","LineWidth",0.5);

                if str2double(app.XrebarsSwitch.Value) == 0
                    text_tem = append("T",app.d_s_x_in.Value,"@",...
                        num2str(app.Spacing_x_in.Value),"-T1 - L = ", num2str(app.L_s_x.Value));
                else
                    text_tem = append("T",app.d_s_x_in.Value,"@",...
                        num2str(app.Spacing_x_in.Value),"-T2 - L = ", num2str(app.L_s_x.Value));
                end

                text(app.Plotting_result,abs(max(coor_plot_X)*1.2),...
                    (max(coor_plot_Y) + min(coor_plot_Y))/2,...
                    text_tem,"HorizontalAlignment","center",...
                    "VerticalAlignment","bottom", "Rotation",90);


            end


            if app.ControlperimetersCheckBox.Value == 1

                Drop_sum = Completed_output.Drop_sum;
                Drop_perimeter_num = length(Drop_sum);
                
                for i1 = 2:Drop_perimeter_num
                    if Drop_sum(i1).Punching_bar == 0
                        for i2 = 1:4
                            f_tem = find(Drop_sum(i1).L_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum(i1).L_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum(i1).L_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","b","Marker",".", "LineWidth",1);
                            end
                        end
                    
                        for i2 = 1:4
                            f_tem = find(Drop_sum(i1).C_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum(i1).C_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum(i1).C_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","b","Marker",".", "LineWidth",1);
                            end
                    
                        end
                    else
                        for i2 = 1:4
                            f_tem = find(Drop_sum(i1).L_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum(i1).L_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum(i1).L_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","r","Marker","x", "LineWidth",1);
                            end
                        end
                    
                        for i2 = 1:4
                            f_tem = find(Drop_sum(i1).C_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum(i1).C_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum(i1).C_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","r","Marker","x", "LineWidth",1);
                            end
                        end    
                    end
                end


                if Completed_output.Last_drop ~= 0
                    Drop_out_sum = Completed_output.Drop_out_sum;
                    
                    for i2 = 1:4
                        f_tem = find(Drop_out_sum.L_gross(i2).intersect == 0);
                        if ~isempty(f_tem)
                            clear coor_plot_tem
                            coor_plot_tem(1,:) = Drop_out_sum.L_gross(i2).x_mid(f_tem);
                            coor_plot_tem(2,:) = Drop_out_sum.L_gross(i2).y_mid(f_tem);
                
                            scatter(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","g","Marker",".", "LineWidth",1);
                        end
                    end
                
                    for i2 = 1:4
                        f_tem = find(Drop_out_sum.C_gross(i2).intersect == 0);
                        if ~isempty(f_tem)
                            clear coor_plot_tem
                            coor_plot_tem(1,:) = Drop_out_sum.C_gross(i2).x_mid(f_tem);
                            coor_plot_tem(2,:) = Drop_out_sum.C_gross(i2).y_mid(f_tem);
                
                            scatter(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","g","Marker",".", "LineWidth",1);
                        end
                
                    end
                end
            end
 

            if app.ShearreinforcementsCheckBox.Value == 1
                if Completed_output.Last_drop ~= 0
                    Drop_sum_rein = Completed_output.Drop_sum_rein;
                    Drop_perimeter_num = length(Drop_sum_rein);
                    
                    for i1 = 1:Drop_perimeter_num
                        for i2 = 1:4

                            clear coor_plot_tem
                            coor_plot_tem(1,:) = Drop_sum_rein(i1).L_rein(i2).x;
                            coor_plot_tem(2,:) = Drop_sum_rein(i1).L_rein(i2).y;
                            
                            plot(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","r", "LineWidth",0.2);

                            text_tem = append("[D",num2str(i1),"]");
                            x_text = mean(coor_plot_tem(1,:));
                            y_text = mean(coor_plot_tem(2,:));
                            text(app.Plotting_result,x_text,y_text,text_tem);

                            f_tem = find(Drop_sum_rein(i1).L_rein(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum_rein(i1).L_rein(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum_rein(i1).L_rein(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result,coor_plot_tem(1,:),coor_plot_tem(2,:),...
                                    "MarkerEdgeColor","r", "MarkerFaceColor","r","Marker","o", "LineWidth",1);
                            end
                        end
                    end
                end
            end
            

            %  X = [-Drop_x*0.8, -Drop_x*0.6];
            %  Y = [-Drop_y*0.7, -Drop_y*0.7];
            %  plot(app.Plotting_result,X,Y,"Color","k", "LineWidth",0.2);
            % 
            % 
            %  X = [-Drop_x*0.7, -Drop_x*0.7];
            %  Y = [-Drop_y*0.8, -Drop_y*0.6];
            %  plot(app.Plotting_result,X,Y,"Color","k", "LineWidth",0.2);
            % 
            % tem = app.XrebarsSwitch.Value;
            % 
            % if str2double(tem) == 0
            %     text(app.Plotting_result,-Drop_x*0.6,-Drop_y*0.7,"T1");
            %     text(app.Plotting_result,-Drop_x*0.7,-Drop_y*0.6,"T2");
            % else
            %     text(app.Plotting_result,-Drop_x*0.6,-Drop_y*0.7,"T2");
            %     text(app.Plotting_result,-Drop_x*0.7,-Drop_y*0.6,"T1");
            % end
            % 
            
            
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                xlim(app.Plotting_result,[-Drop_x,Drop_x])
                ylim(app.Plotting_result,[-Drop_y,Drop_y])

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                xlim(app.Plotting_result,[-hcx*1.5,Drop_x])
                ylim(app.Plotting_result,[-Drop_y,Drop_y])

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                xlim(app.Plotting_result,[-hcx*1.5,Drop_x])
                ylim(app.Plotting_result,[-hcy*1.5,Drop_y])

            end

            axis(app.Plotting_result,"equal");

            hold(app.Plotting_result,'off');    


        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            Completed_input = evalin("base","Completed_input");
            Completed_output = evalin("base","Completed_output");

            % Span
            % Span_x = Completed_input.Span_x;
            % Span_y = Completed_input.Span_y;
            % Check_radius = max([Span_x,Span_y]);

            % Dimensions of column
            hcx = Completed_input.hcx;
            hcy = Completed_input.hcy;
            
            % Edge points of hole
            hole_table = Completed_input.hole_table;
            tem = hole_table(:,2) == 0 &...
                  hole_table(:,3) == 0 & ...
                  hole_table(:,4) == 0 & ...
                  hole_table(:,5) == 0;

            hole_table(tem,:) = [];
            
            no_hole = 0;
            
            if ~isempty(hole_table)   
                [number_of_hole_input,~] = size(hole_table);
                
                x_hole_input = hole_table(:,[2,4]);
                y_hole_input = hole_table(:,[3,5]);

                number_of_hole = number_of_hole_input;
                
                Hole = struct;
                for i1 = 1:number_of_hole
                    Hole(i1).x_hole = x_hole_input(i1,:);
                    Hole(i1).y_hole = y_hole_input(i1,:);
                
                    Hole(i1).a1 = Hole(i1).y_hole(1)/Hole(i1).x_hole(1);
                    Hole(i1).a2 = Hole(i1).y_hole(2)/Hole(i1).x_hole(2);
                end
            else
                no_hole = 1;
                number_of_hole_input = 0;
            end
            
            % Slab % drop
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;


            cla(app. Plotting_result_2)
            hold(app.Plotting_result_2,'on');
            axis(app.Plotting_result_2,'equal');
                        
                        
            clear coor_plot_tem
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                coor_plot_tem(1,:) = [-Drop_x/2, Drop_x/2, Drop_x/2, -Drop_x/2, -Drop_x/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");
                patch(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),[0.9290 0.6940 0.1250],'EdgeColor','k')

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");
                patch(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),[0.9290 0.6940 0.1250],'EdgeColor','k')

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y-hcy/2, Drop_y-hcy/2, -hcy/2, -hcy/2, Drop_y-hcy/2];
                plot(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");
                patch(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),[0.9290 0.6940 0.1250],'EdgeColor','k')

            end

                       
            Fic_w = zeros(number_of_hole_input,1);
            
            if app.OpeningCheckBox_2.Value == 1
                if no_hole ~= 1
                    for i1 = 1:number_of_hole_input
                        x_hole = Hole(i1).x_hole;
                        y_hole = Hole(i1).y_hole;
                    
                        % a1 = Hole(i1).a1;
                        % a2 = Hole(i1).a2;
                    
                        scatter(app.Plotting_result_2,x_hole,y_hole,"MarkerEdgeColor","m","Marker","x", "LineWidth",10);

                        L1 = (x_hole(1)^2+y_hole(1)^2)^0.5;
                        L2 = (x_hole(2)^2+y_hole(2)^2)^0.5;

                        x_far_1 = x_hole(1)*10000/L1;
                        y_far_1 = y_hole(1)*10000/L1;
                        x_far_2 = x_hole(2)*10000/L2;
                        y_far_2 = y_hole(2)*10000/L2;
                    
                        plot(app.Plotting_result_2,[0,x_far_1],[0,y_far_1],"Color","r", "LineStyle","--");
                        plot(app.Plotting_result_2,[0,x_far_2],[0,y_far_2],"Color","r", "LineStyle","--");
                    
                        Fic_w(i1) = 0.1*((x_hole(1)-x_hole(2))^2 + (y_hole(1)-y_hole(2))^2)^0.5;
                    
                        x_hole_fic = [x_hole(1), (x_hole(1)+x_hole(2))/2+Fic_w(i1), ...
                            x_hole(2), (x_hole(1)+x_hole(2))/2-Fic_w(i1), x_hole(1)];
                    
                        y_hole_fic = [y_hole(1), (y_hole(1)+y_hole(2))/2+Fic_w(i1), ...
                            y_hole(2), (y_hole(1)+y_hole(2))/2-Fic_w(i1), y_hole(1)];
                    
                        % plot(x_hole_fic,y_hole_fic,"Color","m", "LineStyle","--","LineWidth",2);+
                        hole_gon = polyshape(x_hole_fic,y_hole_fic);
                        plot(app.Plotting_result_2,hole_gon, "FaceColor","w", "LineWidth", 2);
                        plot(app.Plotting_result_2,x_hole,y_hole,"Color","k", "LineStyle","--","LineWidth",2);
                        plot(app.Plotting_result_2,x_hole_fic(1,[2,4]),y_hole_fic(1,[2,4]),"Color","k", "LineStyle","--","LineWidth",1);
                    
                    end
                end
            end

            if app.LongitudinalreinforcementsCheckBox_2.Value == 1
                
                if app.TopslabrebarCheckBox.Value == 1

                    num_x_s = Completed_input.num_x_s;
                    num_y_s = Completed_input.num_y_s;
    
                    L_s_x_store = Completed_input.L_s_x_store;
                    L_s_y_store = Completed_input.L_s_y_store;
    
                    Dis_length_Re_X = Completed_input.Dis_length_Re_X;
                    Dis_length_Re_Y = Completed_input.Dis_length_Re_Y;
    
                    %% Thép đứng trong nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        coor_plot_X = linspace(-Dis_length_Re_Y/2+25,Dis_length_Re_Y/2-25,num_y_s);
                        coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
    
                        coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        coor_plot_Y = [-hcy/2,L_s_y_store-hcy/2];
                    end


                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_2,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",1.5);
                    end
    
                    plot(app.Plotting_result_2,[coor_plot_X(1),coor_plot_X(end)],...
                        [max(coor_plot_Y)*1.2,max(coor_plot_Y)*1.2],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_2,[coor_plot_X(1),coor_plot_X(1)],...
                        abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_2,[coor_plot_X(end),coor_plot_X(end)],...
                        abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T2");
                    else
                        text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T1");
                    end
                    text(app.Plotting_result_2,(max(coor_plot_X)+min(coor_plot_X))/2,...
                        max(coor_plot_Y)*1.2,text_tem,"HorizontalAlignment",...
                        "center","VerticalAlignment","bottom");
                    
    
                    %% Thép ngang trong nấm
                    clear coor_plot_X coor_plot_Y
    
                    coor_plot_Y = linspace(-Dis_length_Re_X/2+25,Dis_length_Re_X/2-25,num_x_s);
                    coor_plot_X = [-L_s_x_store/2,L_s_x_store/2];
    
    
                    if strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    
                        coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_Y = linspace(-hcy/2+25,Dis_length_Re_X-hcy/2-25,num_x_s);
                        coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                    end


                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_2,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",1.5);
                    end
    
                    plot(app.Plotting_result_2,abs([max(coor_plot_X)*1.2,max(coor_plot_X)*1.2]),...
                        [coor_plot_Y(1),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_2,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                        [coor_plot_Y(1),coor_plot_Y(1)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_2,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                        [coor_plot_Y(end),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.d_s_x_in.Value,"@",...
                            num2str(app.Spacing_x_in.Value),"-T1");
                    else
                        text_tem = append("T",app.d_s_x_in.Value,"@",...
                            num2str(app.Spacing_x_in.Value),"-T2");
                    end
    
                    text(app.Plotting_result_2,abs(max(coor_plot_X)*1.2),...
                        (max(coor_plot_Y) + min(coor_plot_Y))/2,...
                        text_tem,"HorizontalAlignment","center",...
                        "VerticalAlignment","bottom", "Rotation",90);


                    L_s_x_store = Completed_input.L_s_x_store;
                    L_s_y_store = Completed_input.L_s_y_store;
            
                    Dis_length_Re_X = Completed_input.Dis_length_Re_X;
                    Dis_length_Re_Y = Completed_input.Dis_length_Re_Y;
            
            
                    Check_radius = max([Drop_x/2,Drop_y/2]) + 4000;
            
                    Spacing_slab_x = Completed_input.Spacing_slab_x;
                    Spacing_slab_y = Completed_input.Spacing_slab_y;
    
                    %% Thép đứng 2 bên nấm
                    clear coor_plot_X coor_plot_Y
            
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        tem = Dis_length_Re_Y/2+50:Spacing_slab_y:Check_radius;
                        coor_plot_X = unique([-tem,tem]);
                
                        coor_plot_Y = [-Check_radius,Check_radius];
            
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    
                        tem = Dis_length_Re_Y - hcx/2 + 50:Spacing_slab_y:Check_radius;
                        coor_plot_X = tem;
                        coor_plot_Y = [-Check_radius,Check_radius];
    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        tem = Dis_length_Re_Y - hcx/2 + 50:Spacing_slab_y:Check_radius;
                        coor_plot_X = tem;
                        coor_plot_Y = [-hcy/2,Check_radius];
            
                    end


                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_2,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",0.1);
                    end


                    plot(app.Plotting_result_2,[coor_plot_X(1),coor_plot_X(end)],...
                        [max(coor_plot_Y)*1.2,max(coor_plot_Y)*1.2],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    plot(app.Plotting_result_2,[coor_plot_X(1),coor_plot_X(1)],...
                        [max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    plot(app.Plotting_result_2,[coor_plot_X(end),coor_plot_X(end)],...
                        [max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.D_s_slab_y_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T2");
                    else
                        text_tem = append("T",app.D_s_slab_y_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T1");
                    end
                    text(app.Plotting_result_2,max(coor_plot_X)/2 + min(coor_plot_X)/2,...
                        max(coor_plot_Y)*1.2,text_tem,"HorizontalAlignment","center",...
                        "VerticalAlignment","bottom");


                    %% Thép đứng bên trên nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        tem = Spacing_slab_y/2:Spacing_slab_y:Dis_length_Re_Y/2;
                        coor_plot_X = unique([-tem,tem]);
                
                        coor_plot_Y = [L_s_y_store/2,Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                        tem = -hcx/2:Spacing_slab_y:Dis_length_Re_Y-hcx/2;
                        coor_plot_X = unique(tem);
                
                        coor_plot_Y = [L_s_y_store/2,Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                        tem = -hcx/2:Spacing_slab_y:Dis_length_Re_Y-hcx/2;
                        coor_plot_X = unique(tem);
    
                        coor_plot_Y = [L_s_y_store-hcy/2,Check_radius];
            
                    end
            
                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_2,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end
            
            
                    %% Thép đứng bên dưới nấm
                    clear coor_plot_X coor_plot_Y
    
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                        tem = Spacing_slab_y/2:Spacing_slab_y:Dis_length_Re_Y/2;
                        coor_plot_X = unique([-tem,tem]);
                
                        coor_plot_Y = [-L_s_y_store/2,-Check_radius];
            
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                        tem = -hcx/2:Spacing_slab_y:Dis_length_Re_Y-hcx/2;
                        coor_plot_X = unique(tem);
                
                        coor_plot_Y = [-L_s_y_store/2,-Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_X = [];
                        
                    end


                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_2,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end


                    %% Thép ngang trên dưới nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        tem = Dis_length_Re_X/2+50:Spacing_slab_x:Check_radius;
                        coor_plot_X = [-Check_radius,Check_radius];
                        coor_plot_Y = unique([-tem,tem]);
            
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                        tem = Dis_length_Re_X/2+50:Spacing_slab_x:Check_radius;
                        coor_plot_Y = unique([-tem,tem]);
                        coor_plot_X = [-hcx/2,Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                        tem = Dis_length_Re_X-hcy/2+50:Spacing_slab_x:Check_radius;
                        coor_plot_Y = tem;
                        coor_plot_X = [-hcx/2,Check_radius];
            
                    end
            
            
                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_2,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end
            
            
                    plot(app.Plotting_result_2,[max(coor_plot_X)*1.2,max(coor_plot_X)*1.2],...
                        [coor_plot_Y(1),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    plot(app.Plotting_result_2,[max(coor_plot_X)*1.1,max(coor_plot_X)*1.3],...
                        [coor_plot_Y(1),coor_plot_Y(1)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    plot(app.Plotting_result_2,[max(coor_plot_X)*1.1,max(coor_plot_X)*1.3],...
                        [coor_plot_Y(end),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.D_s_slab_x_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T1");
                    else
                        text_tem = append("T",app.D_s_slab_x_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T2");
                    end
            
                    text(app.Plotting_result_2,max(coor_plot_X)*1.2,...
                        max(coor_plot_Y)/2+min(coor_plot_Y)/2,text_tem,...
                        "HorizontalAlignment","center","VerticalAlignment",...
                        "bottom", "Rotation",90);


                    %% Thép ngang phải nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        tem = Spacing_slab_x/2:Spacing_slab_x:Dis_length_Re_X/2;
                        coor_plot_X = [L_s_x_store/2,Check_radius];
                        coor_plot_Y = unique([-tem,tem]);
            
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                        tem = Spacing_slab_x/2:Spacing_slab_x:Dis_length_Re_X/2;
                        coor_plot_Y = unique([-tem,tem]);
                        coor_plot_X = [L_s_x_store-hcx/2,Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        tem = -hcy/2:Spacing_slab_x:Dis_length_Re_X-hcy/2;
                        coor_plot_Y = tem;
                        coor_plot_X = [L_s_x_store-hcx/2,Check_radius];
            
                    end
            
                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_2,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end
            
            
                    %% Thép ngang trái nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                        tem = Spacing_slab_x/2:Spacing_slab_x:Dis_length_Re_X/2;
                        coor_plot_X = [-L_s_x_store/2,-Check_radius];
                        coor_plot_Y = unique([-tem,tem]);
                    else
                        coor_plot_X = [];
                        coor_plot_Y = [];
                    end
            
            
                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_2,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end
                
                else % Không có thép sàn rải đều
            
                    Spacing_slab_x = Completed_input.Spacing_slab_x;
                    Spacing_slab_y = Completed_input.Spacing_slab_y;
    
                    num_x_s = Completed_input.num_x_s;
                    num_y_s = Completed_input.num_y_s;
    
                    L_s_x_store = Completed_input.L_s_x_store;
                    L_s_y_store = Completed_input.L_s_y_store;
    
                    Dis_length_Re_X = Completed_input.Dis_length_Re_X;
                    Dis_length_Re_Y = Completed_input.Dis_length_Re_Y;
    
                    %% Thép đứng trong nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        coor_plot_X = linspace(-Dis_length_Re_Y/2+25,Dis_length_Re_Y/2-25,num_y_s);
                        coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
    
                        coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        coor_plot_Y = [-hcy/2,L_s_y_store-hcy/2];
                    end


                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_2,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",1.5);
                    end
    
                    plot(app.Plotting_result_2,[coor_plot_X(1),coor_plot_X(end)],...
                        [max(coor_plot_Y)*1.2,max(coor_plot_Y)*1.2],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_2,[coor_plot_X(1),coor_plot_X(1)],...
                        abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_2,[coor_plot_X(end),coor_plot_X(end)],...
                        abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T2");
                    else
                        text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T1");
                    end
                    text(app.Plotting_result_2,(max(coor_plot_X)+min(coor_plot_X))/2,...
                        max(coor_plot_Y)*1.2,text_tem,"HorizontalAlignment",...
                        "center","VerticalAlignment","bottom");
                    
    
                    %% Thép ngang trong nấm
                    clear coor_plot_X coor_plot_Y
    
                    coor_plot_Y = linspace(-Dis_length_Re_X/2+25,Dis_length_Re_X/2-25,num_x_s);
                    coor_plot_X = [-L_s_x_store/2,L_s_x_store/2];
    
    
                    if strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    
                        coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_Y = linspace(-hcy/2+25,Dis_length_Re_X-hcy/2-25,num_x_s);
                        coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                    end


                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_2,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",1.5);
                    end
    
                    
                    plot(app.Plotting_result_2,abs([max(coor_plot_X)*1.2,max(coor_plot_X)*1.2]),...
                        [coor_plot_Y(1),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_2,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                        [coor_plot_Y(1),coor_plot_Y(1)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_2,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                        [coor_plot_Y(end),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.d_s_x_in.Value,"@",...
                            num2str(app.Spacing_x_in.Value),"-T1");
                    else
                        text_tem = append("T",app.d_s_x_in.Value,"@",...
                            num2str(app.Spacing_x_in.Value),"-T2");
                    end
    
                    text(app.Plotting_result_2,abs(max(coor_plot_X)*1.2),...
                        (max(coor_plot_Y) + min(coor_plot_Y))/2,...
                        text_tem,"HorizontalAlignment","center",...
                        "VerticalAlignment","bottom", "Rotation",90);


                    %% Thép đứng 2 bên nấm
                    % clear coor_plot_X coor_plot_Y
                    % 
                    % if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                    % 
                    %     Check_radius = app.L_s_x.Value/2;
                    % 
                    %     tem = Dis_length_Re_Y/2+50:Spacing_slab_y:Check_radius;
                    %     coor_plot_X = unique([-tem,tem]);
                    % 
                    %     coor_plot_Y = [-app.L_s_y.Value/2,app.L_s_y.Value/2];
                    % 
                    % elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    % 
                    %     Check_radius = app.L_s_x.Value;
                    %     tem = Dis_length_Re_Y - hcx/2 + 50:Spacing_slab_y:Check_radius - hcx/2;
                    %     coor_plot_X = tem;
                    %     coor_plot_Y = [-app.L_s_y.Value/2,app.L_s_y.Value/2];
                    % 
                    % elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    % 
                    %     Check_radius = app.L_s_x.Value;
                    %     tem = Dis_length_Re_Y - hcx/2 + 50:Spacing_slab_y:Check_radius - hcx/2;
                    %     coor_plot_X = tem;
                    %     coor_plot_Y = [-hcy/2,app.L_s_y.Value-hcy/2];
                    % 
                    % end
                    % 
                    % 
                    % 
                    % for i1 = 1:length(coor_plot_X)
                    %     plot(app.Plotting_result_2,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                    %         "Color","b", "LineStyle","-","LineWidth",0.1);
                    % end
            
                    % % Left dim
                    % plot(app.Plotting_result_2,[coor_plot_X(1),coor_plot_X(end)],...
                    %     [max(coor_plot_Y)*1.4,max(coor_plot_Y)*1.4],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % plot(app.Plotting_result_2,[coor_plot_X(1),coor_plot_X(1)],...
                    %     [max(coor_plot_Y)*1.3,max(coor_plot_Y)*1.5],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % plot(app.Plotting_result_2,[coor_plot_X(end),coor_plot_X(end)],...
                    %     [max(coor_plot_Y)*1.3,max(coor_plot_Y)*1.5],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % if str2double(app.XrebarsSwitch.Value) == 0
                    %     text_tem = append("T",app.D_s_slab_y_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T2");
                    % else
                    %     text_tem = append("T",app.D_s_slab_y_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T1");
                    % end
                    % text(app.Plotting_result_2,max(coor_plot_X)/2 + min(coor_plot_X)/2,...
                    %     max(coor_plot_Y)*1.4,text_tem,"HorizontalAlignment","center",...
                    %     "VerticalAlignment","bottom");
                    % 
                        
                    % %% Thép ngang trên dưới nấm
                    % clear coor_plot_X coor_plot_Y
                    % if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                    %     Check_radius = app.L_s_y.Value/2;
                    %     tem = Dis_length_Re_X/2+50:Spacing_slab_x:Check_radius;
                    %     coor_plot_X = [-app.L_s_x.Value/2,app.L_s_x.Value/2];
                    %     coor_plot_Y = unique([-tem,tem]);
                    % 
                    % elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    %     Check_radius = app.L_s_y.Value/2;
                    %     tem = Dis_length_Re_X/2+50:Spacing_slab_x:Check_radius;
                    %     coor_plot_Y = unique([-tem,tem]);
                    %     coor_plot_X = [-hcx/2,app.L_s_x.Value-hcx/2];
                    % 
                    % elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    %     Check_radius = app.L_s_y.Value;
                    %     tem = Dis_length_Re_X-hcy/2+50:Spacing_slab_x:Check_radius-hcy/2;
                    %     coor_plot_Y = tem;
                    %     coor_plot_X = [-hcx/2,app.L_s_x.Value-hcx/2];
                    % 
                    % end
                    % 
                    % 
                    % for i1 = 1:length(coor_plot_Y)
                    %     plot(app.Plotting_result_2,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                    %         "Color","b", "LineStyle","-","LineWidth",0.01);
                    % end
                    % 
                    % 
                    % plot(app.Plotting_result_2,[max(coor_plot_X)*1.4,max(coor_plot_X)*1.4],...
                    %     [coor_plot_Y(1),coor_plot_Y(end)],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % plot(app.Plotting_result_2,[max(coor_plot_X)*1.3,max(coor_plot_X)*1.5],...
                    %     [coor_plot_Y(1),coor_plot_Y(1)],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % plot(app.Plotting_result_2,[max(coor_plot_X)*1.3,max(coor_plot_X)*1.5],...
                    %     [coor_plot_Y(end),coor_plot_Y(end)],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % if str2double(app.XrebarsSwitch.Value) == 0
                    %     text_tem = append("T",app.D_s_slab_x_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T1");
                    % else
                    %     text_tem = append("T",app.D_s_slab_x_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T2");
                    % end
                    % 
                    % text(app.Plotting_result_2,max(coor_plot_X)*1.4,...
                    %     max(coor_plot_Y)/2+min(coor_plot_Y)/2,text_tem,...
                    %     "HorizontalAlignment","center","VerticalAlignment",...
                    %     "bottom", "Rotation",90);
             
                 end
            end 


            if app.ControlperimetersCheckBox_2.Value == 1

                Slab_sum = Completed_output.Slab_sum;
                Slab_perimeter_num = length(Slab_sum);
                
                for i1 = 2:Slab_perimeter_num
                    if Slab_sum(i1).Punching_bar == 0
                
                        for i2 = 1:4
                            f_tem = find(Slab_sum(i1).L_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum(i1).L_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum(i1).L_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","c","Marker",".", "LineWidth",1);
                            end
                        end
                    
                        for i2 = 1:4
                            f_tem = find(Slab_sum(i1).C_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum(i1).C_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum(i1).C_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","c","Marker",".", "LineWidth",1);
                            end
                        end
                    else
                        for i2 = 1:4
                            f_tem = find(Slab_sum(i1).L_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum(i1).L_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum(i1).L_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","r","Marker","x", "LineWidth",1);
                            end
                        end
                    
                        for i2 = 1:4
                            f_tem = find(Slab_sum(i1).C_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum(i1).C_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum(i1).C_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","r","Marker","x", "LineWidth",1);
                            end
                        end
                    end
                end
            end


            if app.ShearreinforcementsCheckBox_2.Value == 1
                
                if Completed_output.Last_slab ~= 0
                    Slab_sum_rein = Completed_output.Slab_sum_rein;
                    Slab_perimeter_num = length(Slab_sum_rein);
                    
                    for i1 = 1:Slab_perimeter_num
                        for i2 = 1:4
                            clear coor_plot_tem
                            coor_plot_tem(1,:) = Slab_sum_rein(i1).L_rein(i2).x;
                            coor_plot_tem(2,:) = Slab_sum_rein(i1).L_rein(i2).y;
                            
                            plot(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","r", "LineWidth",0.2);

                            text_tem = append("[S",num2str(i1),"]");
                            x_text = mean(coor_plot_tem(1,:));
                            y_text = mean(coor_plot_tem(2,:));
                            text(app.Plotting_result_2,x_text,y_text,text_tem);

                            f_tem = find(Slab_sum_rein(i1).L_rein(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum_rein(i1).L_rein(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum_rein(i1).L_rein(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_2,coor_plot_tem(1,:),coor_plot_tem(2,:),...
                                    "MarkerEdgeColor","r", "MarkerFaceColor","r","Marker","o", "LineWidth",1);
                            end
                        end
                    end
                end
            end
            
            %  X = [(-Drop_x-500)*0.8, (-Drop_x-500)*0.6];
            %  Y = [(-Drop_y-500)*0.7, (-Drop_y-500)*0.7];
            %  plot(app.Plotting_result_2,X,Y,"Color","k", "LineWidth",0.2);
            % 
            % 
            %  X = [(-Drop_x-500)*0.7, (-Drop_x-500)*0.7];
            %  Y = [(-Drop_y-500)*0.8, (-Drop_y-500)*0.6];
            %  plot(app.Plotting_result_2,X,Y,"Color","k", "LineWidth",0.2);
            % 
            % tem = app.XrebarsSwitch.Value;
            % 
            % if str2double(tem) == 0
            %     text(app.Plotting_result_2,(-Drop_x-500)*0.6,(-Drop_y-500)*0.7,"T1");
            %     text(app.Plotting_result_2,(-Drop_x-500)*0.7,(-Drop_y-500)*0.6,"T2");
            % else
            %     text(app.Plotting_result_2,(-Drop_x-500)*0.6,(-Drop_y-500)*0.7,"T2");
            %     text(app.Plotting_result_2,(-Drop_x-500)*0.7,(-Drop_y-500)*0.6,"T1");
            % end


            % if Completed_output.Last_slab ~= 0
            %     xlim(app.Plotting_result_2,...
            %         [-Drop_x/2-Completed_output.Slab_sum(Completed_output.Last_slab).distance_to_edge*1.2,...
            %         Drop_x/2+Completed_output.Slab_sum(Completed_output.Last_slab).distance_to_edge*1.2])
            % 
            %     ylim(app.Plotting_result_2,...
            %         [-Drop_y/2-Completed_output.Slab_sum(Completed_output.Last_slab).distance_to_edge*1.2,...
            %         Drop_y/2+Completed_output.Slab_sum(Completed_output.Last_slab).distance_to_edge*1.2])
            % else

            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                xlim(app.Plotting_result_2,...
                    [-Drop_x/2-5000,...
                    Drop_x/2+5000])
                
                ylim(app.Plotting_result_2,...
                    [-Drop_y/2-5000,...
                    Drop_y/2+5000])

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                xlim(app.Plotting_result_2,[-hcx-2000,Drop_x+5000])
                ylim(app.Plotting_result_2,[-Drop_y,Drop_y])

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                xlim(app.Plotting_result_2,[-hcx-2000,Drop_x+5000])
                ylim(app.Plotting_result_2,[-hcy-2000,Drop_y+5000])

            end


            % end

            axis(app.Plotting_result_2,"equal");
            hold(app.Plotting_result_2,'off');
        end

        % Value changed function: n_s_x_in
        function n_s_x_inValueChanged(app, event)
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Total_p_x*One_stran_area;
            A_y_p_slab = Total_p_y*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;

            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;


            Dia_x = str2double(app.d_s_x_in.Value);
                       
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop, Drop_y]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop, Drop_y]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                Dis_length_Re_X = min([app.Cy_in.Value + 3*d_eff_drop, Drop_y]);

            end

            % Minimum rebar
            num_x_min = num_x_s;

            Re_x = linspace(25,Dis_length_Re_X-25,num_x_min);

            distance_x = floor((Re_x(2)-Re_x(1))/25)*25;
            

            assignin("base","Dis_length_Re_X",Dis_length_Re_X);

            app.Spacing_x_in.Value = distance_x;

            app.L_s_x.Value = Drop_x + max([50*Dia_x*2,1200]);


            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;


            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
        end

        % Value changed function: n_s_y_in
        function n_s_y_inValueChanged(app, event)
        Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;

            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;


            Dia_y = str2double(app.d_s_y_in.Value);
                       
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                Dis_length_Re_Y = min([app.Cx_in.Value + 6*d_eff_drop, Drop_x]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop, Drop_x]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop, Drop_x]);

            end

            num_y_min = app.n_s_y_in.Value;

            Re_y = linspace(25,Dis_length_Re_Y-25,num_y_min);
            distance_y = floor((Re_y(2)-Re_y(1))/25)*25;

            assignin("base","Dis_length_Re_Y",Dis_length_Re_Y);

            app.Spacing_y_in.Value = distance_y;

            app.L_s_y.Value = Drop_y + max([50*Dia_y*2,1200]);


            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;

        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            Completed_input = evalin("base","Completed_input");
            Completed_output = evalin("base","Completed_output");

            % Dimensions of column
            hcx = Completed_input.hcx;
            hcy = Completed_input.hcy;
            
            % Edge points of hole
            hole_table = Completed_input.hole_table;
            tem = hole_table(:,2) == 0 &...
                  hole_table(:,3) == 0 & ...
                  hole_table(:,4) == 0 & ...
                  hole_table(:,5) == 0;

            hole_table(tem,:) = [];
            
            no_hole = 0;
            
            if ~isempty(hole_table)   
                [number_of_hole_input,~] = size(hole_table);
                
                x_hole_input = hole_table(:,[2,4]);
                y_hole_input = hole_table(:,[3,5]);

                number_of_hole = number_of_hole_input;
                
                Hole = struct;
                for i1 = 1:number_of_hole
                    Hole(i1).x_hole = x_hole_input(i1,:);
                    Hole(i1).y_hole = y_hole_input(i1,:);
                
                    Hole(i1).a1 = Hole(i1).y_hole(1)/Hole(i1).x_hole(1);
                    Hole(i1).a2 = Hole(i1).y_hole(2)/Hole(i1).x_hole(2);
                end
            else
                no_hole = 1;
                number_of_hole_input = 0;
            end
            
            % Slab % drop
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;


            cla(app. Plotting_result_3)
            hold(app.Plotting_result_3,'on');
            axis(app.Plotting_result_3,'equal');
            
            clear coor_plot_tem
            coor_plot_tem(1,:) = [-hcx/2, hcx/2, hcx/2, -hcx/2, -hcx/2];
            coor_plot_tem(2,:) = [hcy/2, hcy/2, -hcy/2, -hcy/2, hcy/2];
            
            plot(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");
            patch(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),'k','EdgeColor','k')
                        
            clear coor_plot_tem
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                coor_plot_tem(1,:) = [-Drop_x/2, Drop_x/2, Drop_x/2, -Drop_x/2, -Drop_x/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y-hcy/2, Drop_y-hcy/2, -hcy/2, -hcy/2, Drop_y-hcy/2];
                plot(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            end
                       
            % Fic_w = zeros(number_of_hole_input,1);
            
            % if app.HoleCheckBox_3.Value == 1
            %     if no_hole ~= 1
            %         for i1 = 1:number_of_hole_input
            %             x_hole = Hole(i1).x_hole;
            %             y_hole = Hole(i1).y_hole;
            % 
            %             % a1 = Hole(i1).a1;
            %             % a2 = Hole(i1).a2;
            % 
            %             scatter(app.Plotting_result_3,x_hole,y_hole,"MarkerEdgeColor","m","Marker","x", "LineWidth",10);
            % 
            %             x_far_1 = Drop_x;
            %             y_far_1 = (y_hole(1)/x_hole(1))*Drop_x;
            %             x_far_2 = Drop_x;
            %             y_far_2 = (y_hole(2)/x_hole(2))*Drop_x;
            % 
            %             plot(app.Plotting_result_3,[0,x_far_1],[0,y_far_1],"Color","r", "LineStyle","--");
            %             plot(app.Plotting_result_3,[0,x_far_2],[0,y_far_2],"Color","r", "LineStyle","--");
            % 
            %             Fic_w(i1) = 0.1*((x_hole(1)-x_hole(2))^2 + (y_hole(1)-y_hole(2))^2)^0.5;
            % 
            %             x_hole_fic = [x_hole(1), (x_hole(1)+x_hole(2))/2+Fic_w(i1), ...
            %                 x_hole(2), (x_hole(1)+x_hole(2))/2-Fic_w(i1), x_hole(1)];
            % 
            %             y_hole_fic = [y_hole(1), (y_hole(1)+y_hole(2))/2+Fic_w(i1), ...
            %                 y_hole(2), (y_hole(1)+y_hole(2))/2-Fic_w(i1), y_hole(1)];
            % 
            %             % plot(x_hole_fic,y_hole_fic,"Color","m", "LineStyle","--","LineWidth",2);+
            %             hole_gon = polyshape(x_hole_fic,y_hole_fic);
            %             plot(app.Plotting_result_3,hole_gon, "FaceColor","w", "LineWidth", 2);
            %             plot(app.Plotting_result_3,x_hole,y_hole,"Color","k", "LineStyle","--","LineWidth",2);
            %             plot(app.Plotting_result_3,x_hole_fic(1,[2,4]),y_hole_fic(1,[2,4]),"Color","k", "LineStyle","--","LineWidth",1);
            % 
            %         end
            %     end
            % end

            
            % if app.LongitudinalreinforcementsCheckBox_3.Value == 1
            % 
            %     if app.TopslabrebarCheckBox.Value == 1
            % 
            %         num_x_s = Completed_input.num_x_s;
            %         num_y_s = Completed_input.num_y_s;
            % 
            % 
            %         L_s_x_store = Completed_input.L_s_x_store;
            %         L_s_y_store = Completed_input.L_s_y_store;
            % 
            %         Dis_length_Re_X = Completed_input.Dis_length_Re_X;
            %         Dis_length_Re_Y = Completed_input.Dis_length_Re_Y;
            % 
            %         %% Thép đứng trong nấm
            %         clear coor_plot_X coor_plot_Y
            %         if strcmp(app.ColumnpositionDropDown.Value, "Middle")
            % 
            %             coor_plot_X = linspace(-Dis_length_Re_Y/2+25,Dis_length_Re_Y/2-25,num_y_s);
            %             coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
            % 
            %             coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
            %             coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
            % 
            %             coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
            %             coor_plot_Y = [-hcy/2,L_s_y_store-hcy/2];
            %         end
            % 
            %         for i1 = 1:length(coor_plot_X)
            %             plot(app.Plotting_result_3,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
            %                 "Color","b", "LineStyle","-","LineWidth",1.5);
            %         end
            % 
            %         if strcmp(app.ColumnpositionDropDown.Value, "Middle")
            %             Check_radius = max([Drop_x/2,Drop_y/2]) + 2000;
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
            %             Check_radius = max([Drop_x,Drop_y/2]) + 2000;
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
            %             Check_radius = max([Drop_x,Drop_y]) + 2000;
            % 
            %         end
            % 
            % 
            %         plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(end)],...
            %             [max(Check_radius)*1.1,max(Check_radius)*1.1],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(1)],...
            %             [max(Check_radius)*1.05,max(Check_radius)*1.15],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         plot(app.Plotting_result_3,[coor_plot_X(end),coor_plot_X(end)],...
            %             [max(Check_radius)*1.05,max(Check_radius)*1.15],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         if str2double(app.XrebarsSwitch.Value) == 0
            %             text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T2");
            %         else
            %             text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T1");
            %         end
            %         text(app.Plotting_result_3,...
            %             max(coor_plot_X)/2 + min(coor_plot_X)/2,...
            %             max(Check_radius)*1.1,text_tem,...
            %             "HorizontalAlignment","center","VerticalAlignment","bottom");
            % 
            %         %% Thép ngang trong nấm
            %         clear coor_plot_X coor_plot_Y
            % 
            %         coor_plot_Y = linspace(-Dis_length_Re_X/2+25,Dis_length_Re_X/2-25,num_x_s);
            %         coor_plot_X = [-L_s_x_store/2,L_s_x_store/2];
            % 
            %         if strcmp(app.ColumnpositionDropDown.Value, "Edge")
            % 
            %             coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
            % 
            %             coor_plot_Y = linspace(-hcy/2+25,Dis_length_Re_X-hcy/2-25,num_x_s);
            %             coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
            %         end
            % 
            %         for i1 = 1:length(coor_plot_Y)
            %             plot(app.Plotting_result_3,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
            %                 "Color","b", "LineStyle","-","LineWidth",1.5);
            %         end
            % 
            %         plot(app.Plotting_result_3,abs([Check_radius*1.1,Check_radius*1.1]),...
            %             [coor_plot_Y(1),coor_plot_Y(end)],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         plot(app.Plotting_result_3,abs([Check_radius*1.05,Check_radius*1.15]),...
            %             [coor_plot_Y(1),coor_plot_Y(1)],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         plot(app.Plotting_result_3,abs([Check_radius*1.05,Check_radius*1.15]),...
            %             [coor_plot_Y(end),coor_plot_Y(end)],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         if str2double(app.XrebarsSwitch.Value) == 0
            %             text_tem = append("T",app.d_s_x_in.Value,"@",num2str(app.Spacing_x_in.Value),"-T1");
            %         else
            %             text_tem = append("T",app.d_s_x_in.Value,"@",num2str(app.Spacing_x_in.Value),"-T2");
            %         end
            % 
            %         text(app.Plotting_result_3,abs(Check_radius*1.1),...
            %             max(coor_plot_Y)/2 + min(coor_plot_Y)/2,text_tem,...
            %             "HorizontalAlignment","center","VerticalAlignment",...
            %             "bottom", "Rotation",90);
            % 
            %     else
            % 
            %         num_x_s = Completed_input.num_x_s;
            %         num_y_s = Completed_input.num_y_s;
            % 
            %         L_s_x_store = Completed_input.L_s_x_store;
            %         L_s_y_store = Completed_input.L_s_y_store;
            % 
            %         Dis_length_Re_X = Completed_input.Dis_length_Re_X;
            %         Dis_length_Re_Y = Completed_input.Dis_length_Re_Y;
            % 
            %         %% Thép đứng trong nấm
            %         clear coor_plot_X coor_plot_Y
            %         if strcmp(app.ColumnpositionDropDown.Value, "Middle")
            % 
            %             coor_plot_X = linspace(-Dis_length_Re_Y/2+25,Dis_length_Re_Y/2-25,num_y_s);
            %             coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
            % 
            %             coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
            %             coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
            % 
            %             coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
            %             coor_plot_Y = [-hcy/2,L_s_y_store-hcy/2];
            %         end
            % 
            %         for i1 = 1:length(coor_plot_X)
            %             plot(app.Plotting_result_3,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
            %                 "Color","b", "LineStyle","-","LineWidth",1.5);
            %         end
            % 
            %         if strcmp(app.ColumnpositionDropDown.Value, "Middle")
            %             Check_radius = max([Drop_x/2,Drop_y/2]) + 2000;
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
            %             Check_radius = max([Drop_x,Drop_y/2]) + 2000;
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
            %             Check_radius = max([Drop_x,Drop_y]) + 2000;
            % 
            %         end
            % 
            % 
            %         plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(end)],...
            %             [max(Check_radius)*1.1,max(Check_radius)*1.1],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(1)],...
            %             [max(Check_radius)*1.05,max(Check_radius)*1.15],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         plot(app.Plotting_result_3,[coor_plot_X(end),coor_plot_X(end)],...
            %             [max(Check_radius)*1.05,max(Check_radius)*1.15],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         if str2double(app.XrebarsSwitch.Value) == 0
            %             text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T2");
            %         else
            %             text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T1");
            %         end
            %         text(app.Plotting_result_3,...
            %             max(coor_plot_X)/2 + min(coor_plot_X)/2,...
            %             max(Check_radius)*1.1,text_tem,...
            %             "HorizontalAlignment","center","VerticalAlignment","bottom");
            % 
            %         %% Thép ngang trong nấm
            %         clear coor_plot_X coor_plot_Y
            % 
            %         coor_plot_Y = linspace(-Dis_length_Re_X/2+25,Dis_length_Re_X/2-25,num_x_s);
            %         coor_plot_X = [-L_s_x_store/2,L_s_x_store/2];
            % 
            %         if strcmp(app.ColumnpositionDropDown.Value, "Edge")
            % 
            %             coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
            % 
            %         elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
            % 
            %             coor_plot_Y = linspace(-hcy/2+25,Dis_length_Re_X-hcy/2-25,num_x_s);
            %             coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
            %         end
            % 
            %         for i1 = 1:length(coor_plot_Y)
            %             plot(app.Plotting_result_3,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
            %                 "Color","b", "LineStyle","-","LineWidth",1.5);
            %         end
            % 
            %         plot(app.Plotting_result_3,abs([Check_radius*1.1,Check_radius*1.1]),...
            %             [coor_plot_Y(1),coor_plot_Y(end)],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         plot(app.Plotting_result_3,abs([Check_radius*1.05,Check_radius*1.15]),...
            %             [coor_plot_Y(1),coor_plot_Y(1)],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         plot(app.Plotting_result_3,abs([Check_radius*1.05,Check_radius*1.15]),...
            %             [coor_plot_Y(end),coor_plot_Y(end)],...
            %                 "Color","k", "LineStyle","-","LineWidth",0.5);
            % 
            %         if str2double(app.XrebarsSwitch.Value) == 0
            %             text_tem = append("T",app.d_s_x_in.Value,"@",num2str(app.Spacing_x_in.Value),"-T1");
            %         else
            %             text_tem = append("T",app.d_s_x_in.Value,"@",num2str(app.Spacing_x_in.Value),"-T2");
            %         end
            % 
            %         text(app.Plotting_result_3,abs(Check_radius*1.1),...
            %             max(coor_plot_Y)/2 + min(coor_plot_Y)/2,text_tem,...
            %             "HorizontalAlignment","center","VerticalAlignment",...
            %             "bottom", "Rotation",90);
            % 
            %     end
            % end


            if app.ControlperimetersCheckBox_3.Value == 1

                Drop_sum = Completed_output.Drop_sum;
                Drop_perimeter_num = length(Drop_sum);
                
                for i1 = 2:Drop_perimeter_num
                    if Drop_sum(i1).Punching_bar == 0
                        for i2 = 1:4
                            f_tem = find(Drop_sum(i1).L_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum(i1).L_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum(i1).L_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","b","Marker",".", "LineWidth",1);
                            end
                        end
                    
                        for i2 = 1:4
                            f_tem = find(Drop_sum(i1).C_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum(i1).C_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum(i1).C_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","b","Marker",".", "LineWidth",1);
                            end
                    
                        end
                    else
                        for i2 = 1:4
                            f_tem = find(Drop_sum(i1).L_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum(i1).L_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum(i1).L_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","r","Marker","x", "LineWidth",1);
                            end
                        end
                    
                        for i2 = 1:4
                            f_tem = find(Drop_sum(i1).C_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum(i1).C_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum(i1).C_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","r","Marker","x", "LineWidth",1);
                            end
                        end    
                    end
                end


                if Completed_output.Last_drop ~= 0
                    Drop_out_sum = Completed_output.Drop_out_sum;
                    
                    for i2 = 1:4
                        f_tem = find(Drop_out_sum.L_gross(i2).intersect == 0);
                        if ~isempty(f_tem)
                            clear coor_plot_tem
                            coor_plot_tem(1,:) = Drop_out_sum.L_gross(i2).x_mid(f_tem);
                            coor_plot_tem(2,:) = Drop_out_sum.L_gross(i2).y_mid(f_tem);
                
                            scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","g","Marker",".", "LineWidth",1);
                        end
                    end
                
                    for i2 = 1:4
                        f_tem = find(Drop_out_sum.C_gross(i2).intersect == 0);
                        if ~isempty(f_tem)
                            clear coor_plot_tem
                            coor_plot_tem(1,:) = Drop_out_sum.C_gross(i2).x_mid(f_tem);
                            coor_plot_tem(2,:) = Drop_out_sum.C_gross(i2).y_mid(f_tem);
                
                            scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","g","Marker",".", "LineWidth",1);
                        end
                
                    end
                end
            end


            if app.ShearreinforcementsCheckBox_3.Value == 1
                if Completed_output.Last_drop ~= 0
                    Drop_sum_rein = Completed_output.Drop_sum_rein;
                    Drop_perimeter_num = length(Drop_sum_rein);
                    
                    for i1 = 1:Drop_perimeter_num
                        for i2 = 1:4

                            clear coor_plot_tem
                            coor_plot_tem(1,:) = Drop_sum_rein(i1).L_rein(i2).x;
                            coor_plot_tem(2,:) = Drop_sum_rein(i1).L_rein(i2).y;
                            
                            plot(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","r", "LineWidth",0.2);

                            text_tem = append("[D",num2str(i1),"]");
                            x_text = mean(coor_plot_tem(1,:));
                            y_text = mean(coor_plot_tem(2,:));
                            text(app.Plotting_result_3,x_text,y_text,text_tem);

                            f_tem = find(Drop_sum_rein(i1).L_rein(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Drop_sum_rein(i1).L_rein(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Drop_sum_rein(i1).L_rein(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),...
                                    "MarkerEdgeColor","r", "MarkerFaceColor","r","Marker","o", "LineWidth",1);
                            end
                        end
                    end
                end
            end
                     
            
%%

            Completed_input = evalin("base","Completed_input");
            % Completed_output = evalin("base","Completed_output");

            % Span
            % Span_x = Completed_input.Span_x;
            % Span_y = Completed_input.Span_y;
            % Check_radius = max([Span_x,Span_y]);

            % Edge points of hole
            hole_table = Completed_input.hole_table;
            tem = hole_table(:,2) == 0 &...
                  hole_table(:,3) == 0 & ...
                  hole_table(:,4) == 0 & ...
                  hole_table(:,5) == 0;

            hole_table(tem,:) = [];
            
            no_hole = 0;
            
            if ~isempty(hole_table)   
                [number_of_hole_input,~] = size(hole_table);
                
                x_hole_input = hole_table(:,[2,4]);
                y_hole_input = hole_table(:,[3,5]);

                number_of_hole = number_of_hole_input;
                
                Hole = struct;
                for i1 = 1:number_of_hole
                    Hole(i1).x_hole = x_hole_input(i1,:);
                    Hole(i1).y_hole = y_hole_input(i1,:);
                
                    Hole(i1).a1 = Hole(i1).y_hole(1)/Hole(i1).x_hole(1);
                    Hole(i1).a2 = Hole(i1).y_hole(2)/Hole(i1).x_hole(2);
                end
            else
                no_hole = 1;
                number_of_hole_input = 0;
            end
            
            % Slab % drop
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;


            clear coor_plot_tem
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                coor_plot_tem(1,:) = [-Drop_x/2, Drop_x/2, Drop_x/2, -Drop_x/2, -Drop_x/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y/2, Drop_y/2, -Drop_y/2, -Drop_y/2, Drop_y/2];
                plot(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                coor_plot_tem(1,:) = [-hcx/2, Drop_x-hcx/2, Drop_x-hcx/2, -hcx/2, -hcx/2];
                coor_plot_tem(2,:) = [Drop_y-hcy/2, Drop_y-hcy/2, -hcy/2, -hcy/2, Drop_y-hcy/2];
                plot(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","k");

            end
                       
            Fic_w = zeros(number_of_hole_input,1);
            
            if app.HoleCheckBox_3.Value == 1
                if no_hole ~= 1
                    for i1 = 1:number_of_hole_input
                        x_hole = Hole(i1).x_hole;
                        y_hole = Hole(i1).y_hole;
                    
                        % a1 = Hole(i1).a1;
                        % a2 = Hole(i1).a2;
                    
                        scatter(app.Plotting_result_3,x_hole,y_hole,"MarkerEdgeColor","m","Marker","x", "LineWidth",10);
                    
                        L1 = (x_hole(1)^2+y_hole(1)^2)^0.5;
                        L2 = (x_hole(2)^2+y_hole(2)^2)^0.5;

                        x_far_1 = x_hole(1)*10000/L1;
                        y_far_1 = y_hole(1)*10000/L1;
                        x_far_2 = x_hole(2)*10000/L2;
                        y_far_2 = y_hole(2)*10000/L2;
                    
                        plot(app.Plotting_result_3,[0,x_far_1],[0,y_far_1],"Color","r", "LineStyle","--");
                        plot(app.Plotting_result_3,[0,x_far_2],[0,y_far_2],"Color","r", "LineStyle","--");
                    
                        Fic_w(i1) = 0.1*((x_hole(1)-x_hole(2))^2 + (y_hole(1)-y_hole(2))^2)^0.5;
                    
                        x_hole_fic = [x_hole(1), (x_hole(1)+x_hole(2))/2+Fic_w(i1), ...
                            x_hole(2), (x_hole(1)+x_hole(2))/2-Fic_w(i1), x_hole(1)];
                    
                        y_hole_fic = [y_hole(1), (y_hole(1)+y_hole(2))/2+Fic_w(i1), ...
                            y_hole(2), (y_hole(1)+y_hole(2))/2-Fic_w(i1), y_hole(1)];
                    
                        % plot(x_hole_fic,y_hole_fic,"Color","m", "LineStyle","--","LineWidth",2);+
                        hole_gon = polyshape(x_hole_fic,y_hole_fic);
                        plot(app.Plotting_result_3,hole_gon, "FaceColor","w", "LineWidth", 2);
                        plot(app.Plotting_result_3,x_hole,y_hole,"Color","k", "LineStyle","--","LineWidth",2);
                        plot(app.Plotting_result_3,x_hole_fic(1,[2,4]),y_hole_fic(1,[2,4]),"Color","k", "LineStyle","--","LineWidth",1);
                    
                    end
                end
            end

            if app.LongitudinalreinforcementsCheckBox_3.Value == 1
                
                if app.TopslabrebarCheckBox.Value == 1

                    num_x_s = Completed_input.num_x_s;
                    num_y_s = Completed_input.num_y_s;
    
                    L_s_x_store = Completed_input.L_s_x_store;
                    L_s_y_store = Completed_input.L_s_y_store;
    
                    Dis_length_Re_X = Completed_input.Dis_length_Re_X;
                    Dis_length_Re_Y = Completed_input.Dis_length_Re_Y;
    
                    %% Thép đứng trong nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        coor_plot_X = linspace(-Dis_length_Re_Y/2+25,Dis_length_Re_Y/2-25,num_y_s);
                        coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
    
                        coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        coor_plot_Y = [-hcy/2,L_s_y_store-hcy/2];
                    end


                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_3,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",1.5);
                    end
    
                    plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(end)],...
                        [max(coor_plot_Y)*1.2,max(coor_plot_Y)*1.2],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(1)],...
                        abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_3,[coor_plot_X(end),coor_plot_X(end)],...
                        abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T2");
                    else
                        text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T1");
                    end
                    text(app.Plotting_result_3,(max(coor_plot_X)+min(coor_plot_X))/2,...
                        max(coor_plot_Y)*1.2,text_tem,"HorizontalAlignment",...
                        "center","VerticalAlignment","bottom");
                    
    
                    %% Thép ngang trong nấm
                    clear coor_plot_X coor_plot_Y
    
                    coor_plot_Y = linspace(-Dis_length_Re_X/2+25,Dis_length_Re_X/2-25,num_x_s);
                    coor_plot_X = [-L_s_x_store/2,L_s_x_store/2];
    
    
                    if strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    
                        coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_Y = linspace(-hcy/2+25,Dis_length_Re_X-hcy/2-25,num_x_s);
                        coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                    end


                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_3,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",1.5);
                    end
    
                    plot(app.Plotting_result_3,abs([max(coor_plot_X)*1.2,max(coor_plot_X)*1.2]),...
                        [coor_plot_Y(1),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_3,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                        [coor_plot_Y(1),coor_plot_Y(1)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_3,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                        [coor_plot_Y(end),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.d_s_x_in.Value,"@",...
                            num2str(app.Spacing_x_in.Value),"-T1");
                    else
                        text_tem = append("T",app.d_s_x_in.Value,"@",...
                            num2str(app.Spacing_x_in.Value),"-T2");
                    end
    
                    text(app.Plotting_result_3,abs(max(coor_plot_X)*1.2),...
                        (max(coor_plot_Y) + min(coor_plot_Y))/2,...
                        text_tem,"HorizontalAlignment","center",...
                        "VerticalAlignment","bottom", "Rotation",90);


                    L_s_x_store = Completed_input.L_s_x_store;
                    L_s_y_store = Completed_input.L_s_y_store;
            
                    Dis_length_Re_X = Completed_input.Dis_length_Re_X;
                    Dis_length_Re_Y = Completed_input.Dis_length_Re_Y;
            
            
                    Check_radius = max([Drop_x/2,Drop_y/2]) + 4000;
            
                    Spacing_slab_x = Completed_input.Spacing_slab_x;
                    Spacing_slab_y = Completed_input.Spacing_slab_y;
    
                    %% Thép đứng 2 bên nấm
                    clear coor_plot_X coor_plot_Y
            
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        tem = Dis_length_Re_Y/2+50:Spacing_slab_y:Check_radius;
                        coor_plot_X = unique([-tem,tem]);
                
                        coor_plot_Y = [-Check_radius,Check_radius];
            
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    
                        tem = Dis_length_Re_Y - hcx/2 + 50:Spacing_slab_y:Check_radius;
                        coor_plot_X = tem;
                        coor_plot_Y = [-Check_radius,Check_radius];
    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        tem = Dis_length_Re_Y - hcx/2 + 50:Spacing_slab_y:Check_radius;
                        coor_plot_X = tem;
                        coor_plot_Y = [-hcy/2,Check_radius];
            
                    end


                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_3,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",0.1);
                    end


                    plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(end)],...
                        [max(coor_plot_Y)*1.2,max(coor_plot_Y)*1.2],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(1)],...
                        [max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    plot(app.Plotting_result_3,[coor_plot_X(end),coor_plot_X(end)],...
                        [max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.D_s_slab_y_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T2");
                    else
                        text_tem = append("T",app.D_s_slab_y_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T1");
                    end
                    text(app.Plotting_result_3,max(coor_plot_X)/2 + min(coor_plot_X)/2,...
                        max(coor_plot_Y)*1.2,text_tem,"HorizontalAlignment","center",...
                        "VerticalAlignment","bottom");


                    %% Thép đứng bên trên nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        tem = Spacing_slab_y/2:Spacing_slab_y:Dis_length_Re_Y/2;
                        coor_plot_X = unique([-tem,tem]);
                
                        coor_plot_Y = [L_s_y_store/2,Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                        tem = -hcx/2:Spacing_slab_y:Dis_length_Re_Y-hcx/2;
                        coor_plot_X = unique(tem);
                
                        coor_plot_Y = [L_s_y_store/2,Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                        tem = -hcx/2:Spacing_slab_y:Dis_length_Re_Y-hcx/2;
                        coor_plot_X = unique(tem);
    
                        coor_plot_Y = [L_s_y_store-hcy/2,Check_radius];
            
                    end
            
                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_3,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end
            
            
                    %% Thép đứng bên dưới nấm
                    clear coor_plot_X coor_plot_Y
    
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                        tem = Spacing_slab_y/2:Spacing_slab_y:Dis_length_Re_Y/2;
                        coor_plot_X = unique([-tem,tem]);
                
                        coor_plot_Y = [-L_s_y_store/2,-Check_radius];
            
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                        tem = -hcx/2:Spacing_slab_y:Dis_length_Re_Y-hcx/2;
                        coor_plot_X = unique(tem);
                
                        coor_plot_Y = [-L_s_y_store/2,-Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_X = [];
                        
                    end


                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_3,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end


                    %% Thép ngang trên dưới nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        tem = Dis_length_Re_X/2+50:Spacing_slab_x:Check_radius;
                        coor_plot_X = [-Check_radius,Check_radius];
                        coor_plot_Y = unique([-tem,tem]);
            
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                        tem = Dis_length_Re_X/2+50:Spacing_slab_x:Check_radius;
                        coor_plot_Y = unique([-tem,tem]);
                        coor_plot_X = [-hcx/2,Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                        tem = Dis_length_Re_X-hcy/2+50:Spacing_slab_x:Check_radius;
                        coor_plot_Y = tem;
                        coor_plot_X = [-hcx/2,Check_radius];
            
                    end
            
            
                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_3,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end
            
            
                    plot(app.Plotting_result_3,[max(coor_plot_X)*1.2,max(coor_plot_X)*1.2],...
                        [coor_plot_Y(1),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    plot(app.Plotting_result_3,[max(coor_plot_X)*1.1,max(coor_plot_X)*1.3],...
                        [coor_plot_Y(1),coor_plot_Y(1)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    plot(app.Plotting_result_3,[max(coor_plot_X)*1.1,max(coor_plot_X)*1.3],...
                        [coor_plot_Y(end),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
            
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.D_s_slab_x_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T1");
                    else
                        text_tem = append("T",app.D_s_slab_x_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T2");
                    end
            
                    text(app.Plotting_result_3,max(coor_plot_X)*1.2,...
                        max(coor_plot_Y)/2+min(coor_plot_Y)/2,text_tem,...
                        "HorizontalAlignment","center","VerticalAlignment",...
                        "bottom", "Rotation",90);


                    %% Thép ngang phải nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        tem = Spacing_slab_x/2:Spacing_slab_x:Dis_length_Re_X/2;
                        coor_plot_X = [L_s_x_store/2,Check_radius];
                        coor_plot_Y = unique([-tem,tem]);
            
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                        tem = Spacing_slab_x/2:Spacing_slab_x:Dis_length_Re_X/2;
                        coor_plot_Y = unique([-tem,tem]);
                        coor_plot_X = [L_s_x_store-hcx/2,Check_radius];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        tem = -hcy/2:Spacing_slab_x:Dis_length_Re_X-hcy/2;
                        coor_plot_Y = tem;
                        coor_plot_X = [L_s_x_store-hcx/2,Check_radius];
            
                    end
            
                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_3,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end
            
            
                    %% Thép ngang trái nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                        tem = Spacing_slab_x/2:Spacing_slab_x:Dis_length_Re_X/2;
                        coor_plot_X = [-L_s_x_store/2,-Check_radius];
                        coor_plot_Y = unique([-tem,tem]);
                    else
                        coor_plot_X = [];
                        coor_plot_Y = [];
                    end
            
            
                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_3,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",0.01);
                    end
                
                else % Không có thép sàn rải đều
            
                    Spacing_slab_x = Completed_input.Spacing_slab_x;
                    Spacing_slab_y = Completed_input.Spacing_slab_y;
    
                    num_x_s = Completed_input.num_x_s;
                    num_y_s = Completed_input.num_y_s;
    
                    L_s_x_store = Completed_input.L_s_x_store;
                    L_s_y_store = Completed_input.L_s_y_store;
    
                    Dis_length_Re_X = Completed_input.Dis_length_Re_X;
                    Dis_length_Re_Y = Completed_input.Dis_length_Re_Y;
    
                    %% Thép đứng trong nấm
                    clear coor_plot_X coor_plot_Y
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
    
                        coor_plot_X = linspace(-Dis_length_Re_Y/2+25,Dis_length_Re_Y/2-25,num_y_s);
                        coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
    
                        coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        coor_plot_Y = [-L_s_y_store/2,L_s_y_store/2];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_X = linspace(-hcx/2+25,Dis_length_Re_Y-hcx/2-25,num_y_s);
                        coor_plot_Y = [-hcy/2,L_s_y_store-hcy/2];
                    end


                    for i1 = 1:length(coor_plot_X)
                        plot(app.Plotting_result_3,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                            "Color","b", "LineStyle","-","LineWidth",1.5);
                    end
    
                    plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(end)],...
                        [max(coor_plot_Y)*1.2,max(coor_plot_Y)*1.2],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(1)],...
                        abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_3,[coor_plot_X(end),coor_plot_X(end)],...
                        abs([max(coor_plot_Y)*1.1,max(coor_plot_Y)*1.3]),...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T2");
                    else
                        text_tem = append("T",app.d_s_y_in.Value,"@",num2str(app.Spacing_y_in.Value),"-T1");
                    end
                    text(app.Plotting_result_3,(max(coor_plot_X)+min(coor_plot_X))/2,...
                        max(coor_plot_Y)*1.2,text_tem,"HorizontalAlignment",...
                        "center","VerticalAlignment","bottom");
                    
    
                    %% Thép ngang trong nấm
                    clear coor_plot_X coor_plot_Y
    
                    coor_plot_Y = linspace(-Dis_length_Re_X/2+25,Dis_length_Re_X/2-25,num_x_s);
                    coor_plot_X = [-L_s_x_store/2,L_s_x_store/2];
    
    
                    if strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    
                        coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        coor_plot_Y = linspace(-hcy/2+25,Dis_length_Re_X-hcy/2-25,num_x_s);
                        coor_plot_X = [-hcx/2,L_s_x_store-hcx/2];
                    end


                    for i1 = 1:length(coor_plot_Y)
                        plot(app.Plotting_result_3,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                            "Color","b", "LineStyle","-","LineWidth",1.5);
                    end
    
                    
                    plot(app.Plotting_result_3,abs([max(coor_plot_X)*1.2,max(coor_plot_X)*1.2]),...
                        [coor_plot_Y(1),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_3,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                        [coor_plot_Y(1),coor_plot_Y(1)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    plot(app.Plotting_result_3,abs([max(coor_plot_X)*1.1,max(coor_plot_X)*1.3]),...
                        [coor_plot_Y(end),coor_plot_Y(end)],...
                            "Color","k", "LineStyle","-","LineWidth",0.5);
    
                    if str2double(app.XrebarsSwitch.Value) == 0
                        text_tem = append("T",app.d_s_x_in.Value,"@",...
                            num2str(app.Spacing_x_in.Value),"-T1");
                    else
                        text_tem = append("T",app.d_s_x_in.Value,"@",...
                            num2str(app.Spacing_x_in.Value),"-T2");
                    end
    
                    text(app.Plotting_result_3,abs(max(coor_plot_X)*1.2),...
                        (max(coor_plot_Y) + min(coor_plot_Y))/2,...
                        text_tem,"HorizontalAlignment","center",...
                        "VerticalAlignment","bottom", "Rotation",90);


                    % 
                    % 
                    % %% Thép đứng 2 bên nấm
                    % clear coor_plot_X coor_plot_Y
                    % 
                    % if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                    % 
                    %     Check_radius = app.L_s_x.Value/2;
                    % 
                    %     tem = Dis_length_Re_Y/2+50:Spacing_slab_y:Check_radius;
                    %     coor_plot_X = unique([-tem,tem]);
                    % 
                    %     coor_plot_Y = [-app.L_s_y.Value/2,app.L_s_y.Value/2];
                    % 
                    % elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    % 
                    %     Check_radius = app.L_s_x.Value;
                    %     tem = Dis_length_Re_Y - hcx/2 + 50:Spacing_slab_y:Check_radius - hcx/2;
                    %     coor_plot_X = tem;
                    %     coor_plot_Y = [-app.L_s_y.Value/2,app.L_s_y.Value/2];
                    % 
                    % elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    % 
                    %     Check_radius = app.L_s_x.Value;
                    %     tem = Dis_length_Re_Y - hcx/2 + 50:Spacing_slab_y:Check_radius - hcx/2;
                    %     coor_plot_X = tem;
                    %     coor_plot_Y = [-hcy/2,app.L_s_y.Value-hcy/2];
                    % 
                    % end
                    % 
                    % 
                    % 
                    % for i1 = 1:length(coor_plot_X)
                    %     plot(app.Plotting_result_3,[coor_plot_X(i1),coor_plot_X(i1)],coor_plot_Y,...
                    %         "Color","b", "LineStyle","-","LineWidth",0.1);
                    % end
                    % 
                    % % Left dim
                    % plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(end)],...
                    %     [max(coor_plot_Y)*1.4,max(coor_plot_Y)*1.4],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % plot(app.Plotting_result_3,[coor_plot_X(1),coor_plot_X(1)],...
                    %     [max(coor_plot_Y)*1.3,max(coor_plot_Y)*1.5],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % plot(app.Plotting_result_3,[coor_plot_X(end),coor_plot_X(end)],...
                    %     [max(coor_plot_Y)*1.3,max(coor_plot_Y)*1.5],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % if str2double(app.XrebarsSwitch.Value) == 0
                    %     text_tem = append("T",app.D_s_slab_y_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T2");
                    % else
                    %     text_tem = append("T",app.D_s_slab_y_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T1");
                    % end
                    % text(app.Plotting_result_3,max(coor_plot_X)/2 + min(coor_plot_X)/2,...
                    %     max(coor_plot_Y)*1.4,text_tem,"HorizontalAlignment","center",...
                    %     "VerticalAlignment","bottom");
                    % 
                    % 
                    % %% Thép ngang trên dưới nấm
                    % clear coor_plot_X coor_plot_Y
                    % if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                    %     Check_radius = app.L_s_y.Value/2;
                    %     tem = Dis_length_Re_X/2+50:Spacing_slab_x:Check_radius;
                    %     coor_plot_X = [-app.L_s_x.Value/2,app.L_s_x.Value/2];
                    %     coor_plot_Y = unique([-tem,tem]);
                    % 
                    % elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    %     Check_radius = app.L_s_y.Value/2;
                    %     tem = Dis_length_Re_X/2+50:Spacing_slab_x:Check_radius;
                    %     coor_plot_Y = unique([-tem,tem]);
                    %     coor_plot_X = [-hcx/2,app.L_s_x.Value-hcx/2];
                    % 
                    % elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    %     Check_radius = app.L_s_y.Value;
                    %     tem = Dis_length_Re_X-hcy/2+50:Spacing_slab_x:Check_radius-hcy/2;
                    %     coor_plot_Y = tem;
                    %     coor_plot_X = [-hcx/2,app.L_s_x.Value-hcx/2];
                    % 
                    % end
                    % 
                    % 
                    % for i1 = 1:length(coor_plot_Y)
                    %     plot(app.Plotting_result_3,coor_plot_X,[coor_plot_Y(i1),coor_plot_Y(i1)],...
                    %         "Color","b", "LineStyle","-","LineWidth",0.01);
                    % end
                    % 
                    % 
                    % plot(app.Plotting_result_3,[max(coor_plot_X)*1.4,max(coor_plot_X)*1.4],...
                    %     [coor_plot_Y(1),coor_plot_Y(end)],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % plot(app.Plotting_result_3,[max(coor_plot_X)*1.3,max(coor_plot_X)*1.5],...
                    %     [coor_plot_Y(1),coor_plot_Y(1)],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % plot(app.Plotting_result_3,[max(coor_plot_X)*1.3,max(coor_plot_X)*1.5],...
                    %     [coor_plot_Y(end),coor_plot_Y(end)],...
                    %         "Color","k", "LineStyle","-","LineWidth",0.5);
                    % 
                    % if str2double(app.XrebarsSwitch.Value) == 0
                    %     text_tem = append("T",app.D_s_slab_x_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T1");
                    % else
                    %     text_tem = append("T",app.D_s_slab_x_in.Value,"@",num2str(app.Spacing_s_slab_y_in.Value),"-T2");
                    % end
                    % 
                    % text(app.Plotting_result_3,max(coor_plot_X)*1.4,...
                    %     max(coor_plot_Y)/2+min(coor_plot_Y)/2,text_tem,...
                    %     "HorizontalAlignment","center","VerticalAlignment",...
                    %     "bottom", "Rotation",90);
            
                 end
            end


            if app.ControlperimetersCheckBox_3.Value == 1

                Slab_sum = Completed_output.Slab_sum;
                Slab_perimeter_num = length(Slab_sum);
                
                for i1 = 2:Slab_perimeter_num
                    if Slab_sum(i1).Punching_bar == 0
                
                        for i2 = 1:4
                            f_tem = find(Slab_sum(i1).L_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum(i1).L_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum(i1).L_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","c","Marker",".", "LineWidth",1);
                            end
                        end
                    
                        for i2 = 1:4
                            f_tem = find(Slab_sum(i1).C_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum(i1).C_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum(i1).C_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","c","Marker",".", "LineWidth",1);
                            end
                        end
                    else
                        for i2 = 1:4
                            f_tem = find(Slab_sum(i1).L_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum(i1).L_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum(i1).L_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","r","Marker","x", "LineWidth",1);
                            end
                        end
                    
                        for i2 = 1:4
                            f_tem = find(Slab_sum(i1).C_gross(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum(i1).C_gross(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum(i1).C_gross(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"MarkerEdgeColor","r","Marker","x", "LineWidth",1);
                            end
                        end
                    end
                end
            end


            if app.ShearreinforcementsCheckBox_3.Value == 1
                
                if Completed_output.Last_slab ~= 0
                    Slab_sum_rein = Completed_output.Slab_sum_rein;
                    Slab_perimeter_num = length(Slab_sum_rein);
                    
                    for i1 = 1:Slab_perimeter_num
                        for i2 = 1:4
                            clear coor_plot_tem
                            coor_plot_tem(1,:) = Slab_sum_rein(i1).L_rein(i2).x;
                            coor_plot_tem(2,:) = Slab_sum_rein(i1).L_rein(i2).y;
                            
                            plot(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),"Color","r", "LineWidth",0.2);

                            text_tem = append("[S",num2str(i1),"]");
                            x_text = mean(coor_plot_tem(1,:));
                            y_text = mean(coor_plot_tem(2,:));
                            text(app.Plotting_result_3,x_text,y_text,text_tem);

                            f_tem = find(Slab_sum_rein(i1).L_rein(i2).intersect == 0);
                            if ~isempty(f_tem)
                                clear coor_plot_tem
                                coor_plot_tem(1,:) = Slab_sum_rein(i1).L_rein(i2).x_mid(f_tem);
                                coor_plot_tem(2,:) = Slab_sum_rein(i1).L_rein(i2).y_mid(f_tem);
                    
                                scatter(app.Plotting_result_3,coor_plot_tem(1,:),coor_plot_tem(2,:),...
                                    "MarkerEdgeColor","r", "MarkerFaceColor","r","Marker","o", "LineWidth",1);
                            end
                        end
                    end
                end
            end


            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                xlim(app.Plotting_result_3,...
                    [-Drop_x/2-3000,...
                    Drop_x/2+3000])
                
                ylim(app.Plotting_result_3,...
                    [-Drop_y/2-3000,...
                    Drop_y/2+3000])

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                xlim(app.Plotting_result_3,[-hcx-1000,Drop_x+3000])
                ylim(app.Plotting_result_3,[-Drop_y,Drop_y])

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                xlim(app.Plotting_result_3,[-hcx-1000,Drop_x+3000])
                ylim(app.Plotting_result_3,[-hcy-1000,Drop_y+3000])

            end


            axis(app.Plotting_result_3,"equal");

            hold(app.Plotting_result_3,'off'); 
        end

        % Button pushed function: Steps16ReloadoldmodelButton
        function Steps16ReloadoldmodelButtonPushed(app, event)
            warning('off');
            [file,path] = uigetfile('*.*');
            if isequal(file,0)                
               return
            end

        try
            load(strcat(path,'\',file),'Completed_input');

            if Completed_input.Col_position == 1
                app.ColumnpositionDropDown.Value = "Middle";
            elseif Completed_input.Col_position == 2
                app.ColumnpositionDropDown.Value = "Edge";
            else
                app.ColumnpositionDropDown.Value = "Corner";
            end

            app.Switch.Value = Completed_input.Design_column_or_wall;

            if str2double(app.Switch.Value) == 0
                app.ColumnpositionDropDown.Value = {'Middle'};
                app.ColumnpositionDropDown.Enable = 0;
            else
                app.ColumnpositionDropDown.Enable = 1;
            end
            
            app.Input_fck.Value = Completed_input.fck;
            app.gamma_c_in.Value = Completed_input.gamma_c;
            app.Input_fsy.Value = Completed_input.fyk;
            app.Input_fpy.Value = Completed_input.fpk;
            app.gamma_s_in.Value = Completed_input.gamma_s;
            app.Input_speff.Value = Completed_input.f_pe;
            app.Link_dia_in.Value = num2str(Completed_input.Dia_link);
            app.Lx_in.Value = Completed_input.Span_x/1000;
            app.Ly_in.Value = Completed_input.Span_y/1000;
            app.Cx_in.Value = Completed_input.hcx;
            app.Cy_in.Value = Completed_input.hcy;
            app.UITable_hole.Data = Completed_input.hole_table;
            app.D_in.Value = Completed_input.S_thickness;
            app.Dp_in.Value = Completed_input.Drop_thickness;

            if Completed_input.Col_position == 1
            
                app.Bx_in.Value = Completed_input.Drop_x;
                app.By_in.Value = Completed_input.Drop_y;
            
            elseif Completed_input.Col_position == 2
            
                app.Bx_in.Value = Completed_input.Drop_x/2 + Completed_input.hcx/2;
                app.By_in.Value = Completed_input.Drop_y;
            
            elseif Completed_input.Col_position == 3
            
                app.Bx_in.Value = Completed_input.Drop_x/2 + Completed_input.hcx/2;
                app.By_in.Value = Completed_input.Drop_y/2 + Completed_input.hcy/2;
            
            end


            app.cr_in.Value = Completed_input.Cx;
            app.cr_in.Value = Completed_input.Cy;
            app.cp_in.Value = Completed_input.Cpx;
            app.cp_in.Value = Completed_input.Cpy;
            app.d_s_x_in.Value = num2str(Completed_input.Dia_x);
            app.d_s_y_in.Value = num2str(Completed_input.Dia_y);
            app.D_s_slab_x_in.Value = num2str(Completed_input.Dia_x_slab);
            app.D_s_slab_y_in.Value = num2str(Completed_input.Dia_y_slab);
            app.Spacing_s_slab_x_in.Value = Completed_input.Spacing_slab_x;
            app.Spacing_s_slab_y_in.Value = Completed_input.Spacing_slab_y;

            app.n_s_x_in.Value = Completed_input.num_x_s;
            app.n_s_y_in.Value = Completed_input.num_y_s;
            app.Spacing_x_in.Value = Completed_input.spacing_x_drop;
            app.Spacing_y_in.Value = Completed_input.spacing_y_drop;
            app.L_s_x.Value = Completed_input.L_s_x_store;
            app.L_s_y.Value = Completed_input.L_s_y_store;
            app.d_eff_drop_in.Value = Completed_input.d_eff_drop;
            app.d_eff_slab_in.Value = Completed_input.d_eff_slab;
            app.LL_in.Value = Completed_input.LL;
            app.SDL_in.Value = Completed_input.SDL;
            app.Strand_area_in.Value = Completed_input.One_stran_area;
            app.n_p_x_in.Value = Completed_input.Total_p_x;
            app.n_p_x_col_in.Value = Completed_input.Num_p_x_col;
            app.n_p_x_drop_in.Value = Completed_input.Num_p_x_drop;
            app.n_p_y_in.Value = Completed_input.Total_p_y;

            app.n_p_y_col_in.Value = Completed_input.Num_p_y_col;
            app.n_p_y_drop_in.Value = Completed_input.Num_p_y_drop;
            app.Drape_x_in.Value = Completed_input.Drape_x;
            app.Drape_y_in.Value = Completed_input.Drape_y;

            app.TopslabrebarCheckBox.Value = Completed_input.Topslabrebar;
            
            Rz = Completed_input.Rz;

            app.R1.Value = Rz(1,1);
            app.R2.Value = Rz(2,1);
            app.R3.Value = Rz(3,1);
            app.R4.Value = Rz(4,1);
            app.R5.Value = Rz(5,1);

            app.Mx1.Value = Rz(1,2);
            app.Mx2.Value = Rz(2,2);
            app.Mx3.Value = Rz(3,2);
            app.Mx4.Value = Rz(4,2);
            app.Mx5.Value = Rz(5,2);

            app.My1.Value = Rz(1,3);
            app.My2.Value = Rz(2,3);
            app.My3.Value = Rz(3,3);
            app.My4.Value = Rz(4,3);
            app.My5.Value = Rz(5,3);

            factor_SDL_LL = Completed_input.factor_SDL_LL;

            app.F_SDL_1.Value = factor_SDL_LL(1,1);
            app.F_LL_1.Value = factor_SDL_LL(1,2);
            app.F_SDL_2.Value = factor_SDL_LL(2,1);
            app.F_LL_2.Value = factor_SDL_LL(2,2);
            app.F_SDL_3.Value = factor_SDL_LL(3,1);
            app.F_LL_3.Value = factor_SDL_LL(3,2);
            app.F_SDL_4.Value = factor_SDL_LL(4,1);
            app.F_LL_4.Value = factor_SDL_LL(4,2);
            app.F_SDL_5.Value = factor_SDL_LL(5,1);
            app.F_LL_5.Value = factor_SDL_LL(5,2);

            if Completed_input.Col_position == 1
                app.Mx1.Visible = 1;
                app.Mx1.Enable = 1;

                app.My1.Visible = 1;
                app.My1.Enable = 1;

                app.Mx2.Visible = 1;
                app.Mx2.Enable = 1;

                app.My2.Visible = 1;
                app.My2.Enable = 1;

                app.Mx3.Visible = 1;
                app.Mx3.Enable = 1;

                app.My3.Visible = 1;
                app.My3.Enable = 1;

                app.Mx4.Visible = 1;
                app.Mx4.Enable = 1;

                app.My4.Visible = 1;
                app.My4.Enable = 1;


                app.Mx5.Visible = 1;
                app.Mx5.Enable = 1;

                app.My5.Visible = 1;
                app.My5.Enable = 1;

                app.ForedgeandcornercolumnsLabel.Visible = 0;
                app.ForedgeandcornercolumnsLabel.Enable = 0;

            else
                app.Mx1.Visible = 0;
                app.Mx1.Enable = 0;

                app.My1.Visible = 0;
                app.My1.Enable = 0;

                app.Mx2.Visible = 0;
                app.Mx2.Enable = 0;

                app.My2.Visible = 0;
                app.My2.Enable = 0;

                app.Mx3.Visible = 0;
                app.Mx3.Enable = 0;

                app.My3.Visible = 0;
                app.My3.Enable = 0;

                app.Mx4.Visible = 0;
                app.Mx4.Enable = 0;

                app.My4.Visible = 0;
                app.My4.Enable = 0;


                app.Mx5.Visible = 0;
                app.Mx5.Enable = 0;

                app.My5.Visible = 0;
                app.My5.Enable = 0;
                
                app.ForedgeandcornercolumnsLabel.Visible = 1;
                app.ForedgeandcornercolumnsLabel.Enable = 1;
            end
            

            txt='Done'; 
            im=imread('OK.jpg');
            h = msgbox(txt,'Complete','custom',im); 
            waitfor(h);
        catch
            txt='The selected file is wrong, please select again'; 
            im=imread('ERROR.png');
            h = msgbox(txt,'Error','custom',im); 
            waitfor(h);

        end
        end

        % Button pushed function: ExportcalculationreportButton
        function ExportcalculationreportButtonPushed(app, event)
            warning('off');
                path = uigetdir('*.*');
            if isequal(path,0)                
               return
            else
                app.Image_2.ImageSource = "processing.gif";
                
                % currentFolder = pwd;


                try
                    Completed_input = evalin("base",'Completed_input');
                    Completed_output = evalin("base",'Completed_output');
                    Report_data = FCN10_report_column(Completed_input,Completed_output);

                    Name = Completed_output.Data_name;
                    
                    if str2double(Completed_input.Design_column_or_wall) == 1
                        sample_name = "Template_column.xlsm";
                    else
                        sample_name = "Template_wall.xlsm";
                    end
                    
                    New_file_name = append(Name, "_Report.xlsm");
                    currentFolder = pwd;
                    f_sample = fullfile(currentFolder,sample_name);
                    f_name = fullfile(path,New_file_name);
                    
                    copyfile(f_sample, f_name);
                    
                    writecell(Report_data,f_name,'Sheet',2,'Range','A1:T200');
                    
                    % txt=currentFolder;
                    txt='Done'; 
                    im=imread('OK.jpg');
                    h = msgbox(txt,'Complete','custom',im); 
                    waitfor(h);
                catch
                    % txt=currentFolder;
                    txt='Error is found, report is not exported'; 
                    im=imread('ERROR.png');
                    h = msgbox(txt,'Error','custom',im); 
                    waitfor(h);
                end
    
                app.Image_2.ImageSource = "Finish.PNG";
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if str2double(value) == 0
                app.ColumnpositionDropDown.Value = {'Middle'};
                app.ColumnpositionDropDown.Enable = 0;
                txt='The punching forces should be the reactions at the wall tips, using the entire reaction force of the wall is not necessary'; 
                im=imread('Notice.png');
                h = msgbox(txt,'Notice','custom',im); 
                waitfor(h);
            else
                app.ColumnpositionDropDown.Enable = 1;
            end

        end

        % Value changed function: d_s_x_in
        function d_s_x_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;

        end

        % Value changed function: d_s_y_in
        function d_s_y_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
            
        end

        % Value changed function: D_s_slab_x_in
        function D_s_slab_x_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
            
        end

        % Value changed function: Spacing_s_slab_x_in
        function Spacing_s_slab_x_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
            
        end

        % Value changed function: D_s_slab_y_in
        function D_s_slab_y_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
            
        end

        % Value changed function: Spacing_s_slab_y_in
        function Spacing_s_slab_y_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            
            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
            
        end

        % Value changed function: n_p_x_in
        function n_p_x_inValueChanged(app, event)
            value = app.n_p_x_in.Value;
            
        end

        % Value changed function: ColumnpositionDropDown
        function ColumnpositionDropDownValueChanged(app, event)
            value = app.ColumnpositionDropDown.Value;
            
            if strcmp(value,"Middle")
                app.Mx1.Visible = 1;
                app.Mx1.Enable = 1;

                app.My1.Visible = 1;
                app.My1.Enable = 1;

                app.Mx2.Visible = 1;
                app.Mx2.Enable = 1;

                app.My2.Visible = 1;
                app.My2.Enable = 1;

                app.Mx3.Visible = 1;
                app.Mx3.Enable = 1;

                app.My3.Visible = 1;
                app.My3.Enable = 1;

                app.Mx4.Visible = 1;
                app.Mx4.Enable = 1;

                app.My4.Visible = 1;
                app.My4.Enable = 1;


                app.Mx5.Visible = 1;
                app.Mx5.Enable = 1;

                app.My5.Visible = 1;
                app.My5.Enable = 1;

                app.ForedgeandcornercolumnsLabel.Visible = 0;
                app.ForedgeandcornercolumnsLabel.Enable = 0;

            else
                app.Mx1.Visible = 0;
                app.Mx1.Enable = 0;

                app.My1.Visible = 0;
                app.My1.Enable = 0;

                app.Mx2.Visible = 0;
                app.Mx2.Enable = 0;

                app.My2.Visible = 0;
                app.My2.Enable = 0;

                app.Mx3.Visible = 0;
                app.Mx3.Enable = 0;

                app.My3.Visible = 0;
                app.My3.Enable = 0;

                app.Mx4.Visible = 0;
                app.Mx4.Enable = 0;

                app.My4.Visible = 0;
                app.My4.Enable = 0;


                app.Mx5.Visible = 0;
                app.Mx5.Enable = 0;

                app.My5.Visible = 0;
                app.My5.Enable = 0;
                
                app.ForedgeandcornercolumnsLabel.Visible = 1;
                app.ForedgeandcornercolumnsLabel.Enable = 1;
            end
        end

        % Value changed function: Dp_in
        function Dp_inValueChanged(app, event)
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;


            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
                       
            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);

            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop, Drop_y]);
                Dis_length_Re_Y = min([app.Cx_in.Value + 6*d_eff_drop, Drop_x]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop, Drop_y]);
                Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop, Drop_x]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                Dis_length_Re_X = min([app.Cy_in.Value + 3*d_eff_drop, Drop_y]);
                Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop, Drop_x]);

            end


            % Minimum rebar
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                A_s_x_min = (Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness)*0.075/100;
                A_s_y_min = (Drop_x*Drop_thickness + (Span_x-Drop_x)*S_thickness)*0.075/100;

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                A_s_x_min = (Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness)*0.075/100;
                A_s_y_min = (Drop_x*Drop_thickness + (Span_x/2-Drop_x)*S_thickness)*0.075/100;

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                A_s_x_min = (Drop_y*Drop_thickness + (Span_y/2-Drop_y)*S_thickness)*0.075/100;
                A_s_y_min = (Drop_x*Drop_thickness + (Span_x/2-Drop_x)*S_thickness)*0.075/100;

            end

            num_x_min = ceil(A_s_x_min/Area_x_1);
            num_y_min = ceil(A_s_y_min/Area_y_1);


            Re_x = linspace(25,Dis_length_Re_X-25,num_x_min);
            distance_x = floor((Re_x(2)-Re_x(1))/25)*25;


            Re_y = linspace(25,Dis_length_Re_Y-25,num_y_min);
            distance_y = floor((Re_y(2)-Re_y(1))/25)*25;


            assignin("base","Dis_length_Re_X",Dis_length_Re_X);
            assignin("base","Dis_length_Re_Y",Dis_length_Re_Y);

            app.Spacing_x_in.Value = distance_x;
            app.Spacing_y_in.Value = distance_y;

            
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                app.L_s_x.Value = Drop_x + min([50*Dia_x*2,1200]);
                app.L_s_y.Value = Drop_y + min([50*Dia_y*2,1200]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                app.L_s_x.Value = Drop_x + min([50*Dia_x,600]);
                app.L_s_y.Value = Drop_y + min([50*Dia_y*2,1200]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                app.L_s_x.Value = Drop_x + min([50*Dia_x,600]);
                app.L_s_y.Value = Drop_y + min([50*Dia_y,600]);

            end

            app.n_s_x_in.Value = num_x_min;
            app.n_s_y_in.Value = num_y_min;
            
        end

        % Value changed function: D_in
        function D_inValueChanged(app, event)
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Total_p_x = app.n_p_x_in.Value;
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Total_p_y = app.n_p_y_in.Value;
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;

            fyk = app.Input_fsy.Value;
            fpk = app.Input_fpy.Value;

            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;


            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
                       
            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);

            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop, Drop_y]);
                Dis_length_Re_Y = min([app.Cx_in.Value + 6*d_eff_drop, Drop_x]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop, Drop_y]);
                Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop, Drop_x]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                Dis_length_Re_X = min([app.Cy_in.Value + 3*d_eff_drop, Drop_y]);
                Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop, Drop_x]);

            end


            % Minimum rebar
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                A_s_x_min = (Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness)*0.075/100;
                A_s_y_min = (Drop_x*Drop_thickness + (Span_x-Drop_x)*S_thickness)*0.075/100;

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                A_s_x_min = (Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness)*0.075/100;
                A_s_y_min = (Drop_x*Drop_thickness + (Span_x/2-Drop_x)*S_thickness)*0.075/100;

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                A_s_x_min = (Drop_y*Drop_thickness + (Span_y/2-Drop_y)*S_thickness)*0.075/100;
                A_s_y_min = (Drop_x*Drop_thickness + (Span_x/2-Drop_x)*S_thickness)*0.075/100;

            end

            num_x_min = ceil(A_s_x_min/Area_x_1);
            num_y_min = ceil(A_s_y_min/Area_y_1);


            Re_x = linspace(25,Dis_length_Re_X-25,num_x_min);
            distance_x = floor((Re_x(2)-Re_x(1))/25)*25;


            Re_y = linspace(25,Dis_length_Re_Y-25,num_y_min);
            distance_y = floor((Re_y(2)-Re_y(1))/25)*25;


            assignin("base","Dis_length_Re_X",Dis_length_Re_X);
            assignin("base","Dis_length_Re_Y",Dis_length_Re_Y);

            app.Spacing_x_in.Value = distance_x;
            app.Spacing_y_in.Value = distance_y;

            
            if strcmp(app.ColumnpositionDropDown.Value, "Middle")

                app.L_s_x.Value = Drop_x + min([50*Dia_x*2,1200]);
                app.L_s_y.Value = Drop_y + min([50*Dia_y*2,1200]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")

                app.L_s_x.Value = Drop_x + min([50*Dia_x,600]);
                app.L_s_y.Value = Drop_y + min([50*Dia_y*2,1200]);

            elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")

                app.L_s_x.Value = Drop_x + min([50*Dia_x,600]);
                app.L_s_y.Value = Drop_y + min([50*Dia_y,600]);

            end

            app.n_s_x_in.Value = num_x_min;
            app.n_s_y_in.Value = num_y_min;
            
        end

        % Button pushed function: Step6SavinginputButton
        function Step6SavinginputButtonPushed(app, event)
            warning('off');
                path = uigetdir('*.*');
            if isequal(path,0)                
               return
            else

                if isempty(app.NameforsavingfileEditField.Value)
                    txt='Please input the name'; 
                    im=imread('Error.png');
                    h = msgbox(txt,'Error','custom',im); 
                    waitfor(h);
                else
    
                    %% Reading input   
                    Rebar_area = ...
                        [10 79; ...
                         12 113; ...
                         14 154; ...
                         16 201; ...
                         18 254; ...
                         20 314; ...
                         22 380; ...
                         25 491; ...
                         28 616; ...
                         32 804];
                    
                    % Material
                    fck = app.Input_fck.Value;
                    gamma_c = app.gamma_c_in.Value;
                    fyk = app.Input_fsy.Value;
                    fpk = app.Input_fpy.Value;
                    fpu = app.Input_fpu.Value;
                    gamma_s = app.gamma_s_in.Value;
                    f_pe = app.Input_speff.Value;
                    Dia_link = str2double(app.Link_dia_in.Value);
        
                    % Span
                    Span_x = app.Lx_in.Value*1000;
                    Span_y = app.Ly_in.Value*1000;
        
                    % Dimensions of column
                    hcx = app.Cx_in.Value;
                    hcy = app.Cy_in.Value;
                    
                    Check_radius = max([Span_x,Span_y]);
                    
                    % Edge points of hole
                    hole_table = app.UITable_hole.Data;
                    tem = hole_table(:,2) == 0 &...
                        hole_table(:,3) == 0 & ...
                        hole_table(:,4) == 0 & ...
                        hole_table(:,5) == 0;
        
                    hole_table(tem,:) = [];
                    
                    no_hole = 0;
                    
                    if ~isempty(hole_table)   
                        [number_of_hole_input,~] = size(hole_table);
                        
                        x_hole_input = hole_table(:,[2,4]);
                        y_hole_input = hole_table(:,[3,5]);
                    else
                        no_hole = 1;
                        number_of_hole_input = 0;
                        x_hole_input = [];
                        y_hole_input = [];
                    end
                    
                    % Slab % drop
                    S_thickness = app.D_in.Value;
                    Drop_thickness = app.Dp_in.Value;
        
                    if strcmp(app.ColumnpositionDropDown.Value, "Middle")
                    
                        Col_position = 1;
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Edge")
                    
                        Col_position = 2;
                    
                    elseif strcmp(app.ColumnpositionDropDown.Value, "Corner")
                    
                        Col_position = 3;
                    
                    end
        
                    Drop_x_original = app.Bx_in.Value;
                    Drop_y_original = app.By_in.Value;
                    if Col_position == 1
                    
                        Drop_x = app.Bx_in.Value;
                        Drop_y = app.By_in.Value;
                    
                    elseif Col_position == 2
                    
                        Drop_x = (app.Bx_in.Value - hcx/2)*2;
                        Drop_y = app.By_in.Value;
                    
                    elseif Col_position == 3
                    
                        Drop_x = (app.Bx_in.Value - hcx/2)*2;
                        Drop_y = (app.By_in.Value - hcy/2)*2;
                    
                    end


                    % Cover to top
                    Cx = app.cr_in.Value;
                    Cy = app.cr_in.Value;
                    
                    Cpx = app.cp_in.Value;
                    Cpy = app.cp_in.Value;
                    
                    % Diameter of rebars
                    Dia_x = str2double(app.d_s_x_in.Value);
                    Dia_y = str2double(app.d_s_y_in.Value);
                    Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
                    Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
                    Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
                    Spacing_slab_y = app.Spacing_s_slab_y_in.Value;
        
                    Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
                    Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
                    Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
                    Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
                    Area_link = Rebar_area(Rebar_area(:,1) == Dia_link,2);
                    
                    % Rebar arrange
                    num_x_s = app.n_s_x_in.Value;
                    num_y_s = app.n_s_y_in.Value;
        
                    d_eff_drop = app.d_eff_drop_in.Value;
                    d_eff_slab = app.d_eff_slab_in.Value;
        
                    if Col_position == 1
                    
                        Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop,app.By_in.Value]);
                        Dis_length_Re_Y = min([app.Cx_in.Value + 6*d_eff_drop,app.Bx_in.Value]);
                    
                    elseif Col_position == 2
                    
                        Dis_length_Re_X = min([app.Cy_in.Value + 6*d_eff_drop,app.By_in.Value]);
                        Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop,app.Bx_in.Value]);
                    
                    elseif Col_position == 3
                    
                        Dis_length_Re_X = min([app.Cy_in.Value + 3*d_eff_drop,app.By_in.Value]);
                        Dis_length_Re_Y = min([app.Cx_in.Value + 3*d_eff_drop,app.Bx_in.Value]);
                    
                    end
        
                    spacing_x_drop = floor((Dis_length_Re_X-50)/num_x_s/25)*25;
                    spacing_y_drop = floor((Dis_length_Re_Y-50)/num_y_s/25)*25;
        
                    app.Spacing_x_in.Value = spacing_x_drop;
                    app.Spacing_y_in.Value = spacing_y_drop;
        
                    L_s_x_store = app.L_s_x.Value;
                    L_s_y_store = app.L_s_y.Value;
                    
        
                    % Load
                    LL = app.LL_in.Value;
                    SDL = app.SDL_in.Value;
                    
                    % Strand
                    One_stran_area = app.Strand_area_in.Value;
                    
                    Total_p_x = app.n_p_x_in.Value;
                    Num_p_x_col = app.n_p_x_col_in.Value;
                    Num_p_x_drop = app.n_p_x_drop_in.Value;
                    
                    Total_p_y = app.n_p_y_in.Value;
                    Num_p_y_col = app.n_p_y_col_in.Value;
                    Num_p_y_drop = app.n_p_y_drop_in.Value;
        
        
                    Drape_x = app.Drape_x_in.Value;
                    Drape_y = app.Drape_y_in.Value;
                    
                    P_x_col = f_pe*Num_p_x_col*One_stran_area*fpu;
                    P_x_drop = f_pe*Num_p_x_drop*One_stran_area*fpu;
                    P_x_all = f_pe*Total_p_x*One_stran_area*fpu;
        
                    if Col_position == 1
                        s_cp_x = P_x_all/(Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness);
                    elseif Col_position == 2
                        s_cp_x = P_x_all/(Drop_y*Drop_thickness + (Span_y-Drop_y)*S_thickness);
                    elseif Col_position == 3
                        s_cp_x = P_x_all/(Drop_y_original*Drop_thickness + (Span_y/2-Drop_y_original)*S_thickness);
                    end
                    
        
                    if Col_position == 1
                        s_cp_x_slab = P_x_all/(Span_y*S_thickness);
                    elseif Col_position == 2
                        s_cp_x_slab = P_x_all/(Span_y*S_thickness);
                    elseif Col_position == 3
                        s_cp_x_slab = P_x_all/(Span_y*S_thickness/2);
                    end


                    P_y_col = f_pe*Num_p_y_col*One_stran_area*fpu;
                    P_y_drop = f_pe*Num_p_y_drop*One_stran_area*fpu;
                    P_y_all = f_pe*Total_p_y*One_stran_area*fpu;
                    
                    if Col_position == 1
                        s_cp_y = P_y_all/(Drop_x*Drop_thickness + (Span_x-Drop_x)*S_thickness);
                    elseif Col_position == 2
                        s_cp_y = P_y_all/(Drop_x_original*Drop_thickness + (Span_x/2-Drop_x_original)*S_thickness);
                    elseif Col_position == 3
                        s_cp_y = P_y_all/(Drop_x_original*Drop_thickness + (Span_x/2-Drop_x_original)*S_thickness);
                    end
        
        
                    if Col_position == 1
                        s_cp_y_slab = P_y_all/(Span_x*S_thickness);
                    elseif Col_position == 2
                        s_cp_y_slab = P_y_all/(Span_x*S_thickness/2);
                    elseif Col_position == 3
                        s_cp_y_slab = P_y_all/(Span_x*S_thickness/2);
                    end
        
        
                    s_cp = (s_cp_x + s_cp_y)/2;
                    s_cp_slab = (s_cp_x_slab + s_cp_y_slab)/2;
                    
                    % Load
                    Rz = [app.R1.Value, app.Mx1.Value, app.My1.Value;...
                          app.R2.Value, app.Mx2.Value, app.My2.Value;...
                          app.R3.Value, app.Mx3.Value, app.My3.Value;...
                          app.R4.Value, app.Mx4.Value, app.My4.Value;...
                          app.R5.Value, app.Mx5.Value, app.My5.Value];
                    
                    factor_SDL_LL = [app.F_SDL_1.Value, app.F_LL_1.Value; ...
                                     app.F_SDL_2.Value, app.F_LL_2.Value; ...
                                     app.F_SDL_3.Value, app.F_LL_3.Value; ...
                                     app.F_SDL_4.Value, app.F_LL_4.Value; ...
                                     app.F_SDL_5.Value, app.F_LL_5.Value];
        
                    ex = 1000*Rz(:,2)./Rz(:,1);
                    ey = 1000*Rz(:,3)./Rz(:,1);
        
        
                    D_slab = app.D_in.Value;
                    D_drop = app.Dp_in.Value;
        
        
                    Design_column_or_wall = app.Switch.Value;
                    Completed_input.Design_column_or_wall = Design_column_or_wall;
                    Completed_input.Rebar_area = Rebar_area;
                    Completed_input.fck = fck;
                    Completed_input.gamma_c = gamma_c;
                    Completed_input.fyk = fyk;
                    Completed_input.fpk = fpk;
                    Completed_input.fpu = fpu;
                    Completed_input.gamma_s = gamma_s;
                    Completed_input.f_pe = f_pe;
                    Completed_input.Dia_link = Dia_link;
                    Completed_input.Span_x = Span_x;
                    Completed_input.Span_y = Span_y;
                    Completed_input.hcx = hcx;
                    Completed_input.hcy = hcy;
                    Completed_input.Check_radius = Check_radius;
                    Completed_input.hole_table = hole_table;
                    Completed_input.no_hole = no_hole;
                    Completed_input.number_of_hole_input = number_of_hole_input;
                    Completed_input.S_thickness = S_thickness;
                    Completed_input.Drop_thickness = Drop_thickness;
                    Completed_input.Drop_x = Drop_x;
                    Completed_input.Drop_y = Drop_y;
                    Completed_input.Cx = Cx;
                    Completed_input.Cy = Cy;
                    Completed_input.Cpx = Cpx;
                    Completed_input.Cpy = Cpy;
                    Completed_input.Dia_x = Dia_x;
                    Completed_input.Dia_y = Dia_y;
                    Completed_input.Dia_x_slab = Dia_x_slab;
                    Completed_input.Dia_y_slab = Dia_y_slab;
                    Completed_input.Spacing_slab_x = Spacing_slab_x;
                    Completed_input.Spacing_slab_y = Spacing_slab_y;
                    Completed_input.Area_x_1 = Area_x_1;
                    Completed_input.Area_y_1 = Area_y_1;
                    Completed_input.Area_x_1_slab = Area_x_1_slab;
                    Completed_input.Area_y_1_slab = Area_y_1_slab;
                    Completed_input.Area_link = Area_link;
                    Completed_input.num_x_s = num_x_s;
                    Completed_input.num_y_s = num_y_s;
                    Completed_input.spacing_x_drop = spacing_x_drop;
                    Completed_input.spacing_y_drop = spacing_y_drop;
                    Completed_input.L_s_x_store = L_s_x_store;
                    Completed_input.L_s_y_store = L_s_y_store;
                    Completed_input.d_eff_drop = d_eff_drop;
                    Completed_input.d_eff_slab = d_eff_slab;
                    Completed_input.LL = LL;
                    Completed_input.SDL = SDL;
                    Completed_input.One_stran_area = One_stran_area;
                    Completed_input.Total_p_x = Total_p_x;
                    Completed_input.Num_p_x_col = Num_p_x_col;
                    Completed_input.Num_p_x_drop = Num_p_x_drop;
                    Completed_input.Total_p_y = Total_p_y;
                    Completed_input.Num_p_y_col = Num_p_y_col;
                    Completed_input.Num_p_y_drop = Num_p_y_drop;
                    Completed_input.Drape_x = Drape_x;
                    Completed_input.Drape_y = Drape_y;
                    Completed_input.P_x_col = P_x_col;
                    Completed_input.P_x_drop = P_x_drop;
                    Completed_input.P_x_all = P_x_all;
                    Completed_input.s_cp_x = s_cp_x;
                    Completed_input.P_y_col = P_y_col;
                    Completed_input.P_y_drop = P_y_drop;
                    Completed_input.P_y_all = P_y_all;
                    Completed_input.s_cp_y = s_cp_y;
                    Completed_input.s_cp = s_cp;
                    Completed_input.s_cp_slab = s_cp_slab;
                    Completed_input.Rz = Rz;
                    Completed_input.ex = ex;
                    Completed_input.ey = ey;
                    Completed_input.x_hole_input = x_hole_input;
                    Completed_input.y_hole_input = y_hole_input;
                    Completed_input.Dis_length_Re_X = Dis_length_Re_X;
                    Completed_input.Dis_length_Re_Y = Dis_length_Re_Y;
                    Completed_input.factor_SDL_LL = factor_SDL_LL;
                    Completed_input.Col_position = Col_position;
                    Completed_input.D_slab = D_slab;
                    Completed_input.D_drop = D_drop;
                    Completed_input.Drop_x_original = Drop_x_original;
                    Completed_input.Drop_y_original = Drop_y_original;
                    Completed_input.Topslabrebar = app.TopslabrebarCheckBox.Value;
                    Data_name = app.NameforsavingfileEditField.Value;
        
                    Completed_output.Data_name = Data_name;
                    
                    assignin("base","Completed_output",Completed_output);
        
                    name_4_save = char(Data_name);
        
                    name_4_save_in = char(append(name_4_save,"_Input_file"));
        
                    f_name = fullfile(path,name_4_save_in);


                    save(f_name,'Completed_input');


                    txt='Done'; 
                    im=imread('OK.jpg');
                    h = msgbox(txt,'Completed','custom',im); 
                    waitfor(h);
        
                    app.Image.ImageSource = "Finish.PNG";


                end
            end
        end

        % Value changed function: cr_in
        function cr_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;


            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
            
        end

        % Value changed function: cp_in
        function cp_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;


            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
            
        end

        % Value changed function: n_p_x_drop_in
        function n_p_x_drop_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;


            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
            
        end

        % Value changed function: n_p_y_drop_in
        function n_p_y_drop_inValueChanged(app, event)
            %% Update effective depth
            Rebar_area = ...
                [10 79; ...
                 12 113; ...
                 14 154; ...
                 16 201; ...
                 18 254; ...
                 20 314; ...
                 22 380; ...
                 25 491; ...
                 28 616; ...
                 32 804];

            S_thickness = app.D_in.Value;
            Drop_thickness = app.Dp_in.Value;
            Drop_x = app.Bx_in.Value;
            Drop_y = app.By_in.Value;

            % Span
            Span_x = app.Lx_in.Value*1000;
            Span_y = app.Ly_in.Value*1000;

            % Diameter of rebars
            Dia_x = str2double(app.d_s_x_in.Value);
            Dia_y = str2double(app.d_s_y_in.Value);
            Dia_x_slab = str2double(app.D_s_slab_x_in.Value);
            Dia_y_slab = str2double(app.D_s_slab_y_in.Value);
            Spacing_slab_x = app.Spacing_s_slab_x_in.Value;
            Spacing_slab_y = app.Spacing_s_slab_y_in.Value;

            Area_x_1 = Rebar_area(Rebar_area(:,1) == Dia_x,2);
            Area_y_1 = Rebar_area(Rebar_area(:,1) == Dia_y,2);
            Area_x_1_slab = Rebar_area(Rebar_area(:,1) == Dia_x_slab,2);
            Area_y_1_slab = Rebar_area(Rebar_area(:,1) == Dia_y_slab,2);
            
            % Rebar arrange
            num_x_s = app.n_s_x_in.Value;
            num_y_s = app.n_s_y_in.Value;

            % Effective depth for drop
            A_x_s_drop = Area_x_1*num_x_s;
            A_y_s_drop = Area_y_1*num_y_s;

            Cx = app.cr_in.Value;            
            Cpx = app.cp_in.Value;
           
            Num_p_x_drop = app.n_p_x_drop_in.Value;
            
            Num_p_y_drop = app.n_p_y_drop_in.Value;

            One_stran_area = app.Strand_area_in.Value;


            A_x_p_drop = Num_p_x_drop*One_stran_area;
            A_y_p_drop = Num_p_y_drop*One_stran_area;


            c_drop = ((A_x_s_drop + A_y_s_drop)*Cx + ...
                    (A_x_p_drop + A_y_p_drop)*Cpx) /...
                    (A_x_s_drop + A_y_s_drop + ...
                    A_x_p_drop + A_y_p_drop);

            d_eff_drop = Drop_thickness - c_drop;
            app.d_eff_drop_in.Value = d_eff_drop;

            % Effective depth for slab

            if app.TopslabrebarCheckBox.Value == 1
                A_x_s_slab = Area_x_1*num_x_s + floor((Span_y-Drop_y)/Spacing_slab_x)*Area_x_1_slab;
                A_y_s_slab = Area_y_1*num_y_s + floor((Span_x-Drop_x)/Spacing_slab_y)*Area_y_1_slab;
            else
                A_x_s_slab = Area_x_1*num_x_s;
                A_y_s_slab = Area_y_1*num_y_s;
            end
            A_x_p_slab = Num_p_x_drop*One_stran_area;
            A_y_p_slab = Num_p_y_drop*One_stran_area;

            c_slab = ((A_x_s_slab + A_y_s_slab)*Cx + ...
                    (A_x_p_slab + A_y_p_slab)*Cpx) /...
                    (A_x_s_slab + A_y_s_slab + ...
                    A_x_p_slab + A_y_p_slab);

            d_eff_slab = S_thickness - c_slab;
            app.d_eff_slab_in.Value = d_eff_slab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.UIAxes = uiaxes(app.InputTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Step1MaterialpropertiesPanel = uipanel(app.InputTab);
            app.Step1MaterialpropertiesPanel.Title = 'Step 1: Material properties';
            app.NumberofservicecomboLabel = uilabel(app.Step1MaterialpropertiesPanel);
            app.NumberofservicecomboLabel.Text = 'fck';
            app.Input_fck = uieditfield(app.Step1MaterialpropertiesPanel, 'numeric');
            app.Input_fck.Tooltip = {'Characteristic strength of concrete'};
            app.Input_fck.Value = 28;
            app.MPaLabel = uilabel(app.Step1MaterialpropertiesPanel);
            app.MPaLabel.Text = 'MPa';
            app.NumberofservicecomboLabel_2 = uilabel(app.Step1MaterialpropertiesPanel);
            app.NumberofservicecomboLabel_2.Text = 'Reinf. yield stress';
            app.Input_fsy = uieditfield(app.Step1MaterialpropertiesPanel, 'numeric');
            app.Input_fsy.Tooltip = {'Characteristic strength of rebar'};
            app.Input_fsy.Value = 500;
            app.MPaLabel_2 = uilabel(app.Step1MaterialpropertiesPanel);
            app.MPaLabel_2.Text = 'MPa';
            app.NumberofservicecomboLabel_3 = uilabel(app.Step1MaterialpropertiesPanel);
            app.NumberofservicecomboLabel_3.Text = 'Tendon yield stress';
            app.Input_fpy = uieditfield(app.Step1MaterialpropertiesPanel, 'numeric');
            app.Input_fpy.Tooltip = {'Yield stress of tendon'};
            app.Input_fpy.Value = 1640;
            app.MPaLabel_3 = uilabel(app.Step1MaterialpropertiesPanel);
            app.MPaLabel_3.Text = 'MPa';
            app.NumberofservicecomboLabel_4 = uilabel(app.Step1MaterialpropertiesPanel);
            app.NumberofservicecomboLabel_4.Text = 'Tendon Br. stress';
            app.Input_fpu = uieditfield(app.Step1MaterialpropertiesPanel, 'numeric');
            app.Input_fpu.Tooltip = {'Fracture stress of tendon'};
            app.Input_fpu.Value = 1862;
            app.MPaLabel_4 = uilabel(app.Step1MaterialpropertiesPanel);
            app.MPaLabel_4.Text = 'MPa';
            app.NumberofservicecomboLabel_5 = uilabel(app.Step1MaterialpropertiesPanel);
            app.NumberofservicecomboLabel_5.Text = 'Effective prestress';
            app.Input_speff = uieditfield(app.Step1MaterialpropertiesPanel, 'numeric');
            app.Input_speff.Tooltip = {'Effective stress in tendon as a fracture of facture stress'};
            app.Input_speff.Value = 0.65;
            app.fpuLabel = uilabel(app.Step1MaterialpropertiesPanel);
            app.fpuLabel.Text = 'fpu';
            app.NumberofservicecomboLabel_6 = uilabel(app.Step1MaterialpropertiesPanel);
            app.NumberofservicecomboLabel_6.Text = 'Gamma_c';
            app.gamma_c_in = uieditfield(app.Step1MaterialpropertiesPanel, 'numeric');
            app.gamma_c_in.Tooltip = {'Partial factor of concrete'};
            app.gamma_c_in.Value = 1.5;
            app.NumberofservicecomboLabel_7 = uilabel(app.Step1MaterialpropertiesPanel);
            app.NumberofservicecomboLabel_7.Text = 'Gamma_s';
            app.gamma_s_in = uieditfield(app.Step1MaterialpropertiesPanel, 'numeric');
            app.gamma_s_in.Tooltip = {'Partial factor of rebar and tendon'};
            app.gamma_s_in.Value = 1.15;
            app.Link_dia_in = uidropdown(app.Step1MaterialpropertiesPanel);
            app.Link_dia_in.Items = {'10', '12', '14', '16', '18', '20', '22', '25', '28', '32'};
            app.Link_dia_in.Tooltip = {'Diameter of link'};
            app.Link_dia_in.Value = '10';
            app.mmLabel = uilabel(app.Step1MaterialpropertiesPanel);
            app.mmLabel.Text = 'mm';
            app.NumberofservicecomboLabel_9 = uilabel(app.Step1MaterialpropertiesPanel);
            app.NumberofservicecomboLabel_9.Text = 'Link diameter';
            app.NumberofservicecomboLabel_60 = uilabel(app.Step1MaterialpropertiesPanel);
            app.NumberofservicecomboLabel_60.Text = 'Strand area';
            app.Strand_area_in = uieditfield(app.Step1MaterialpropertiesPanel, 'numeric');
            app.Strand_area_in.Tooltip = {'Strand area'};
            app.Strand_area_in.Value = 140;
            app.mm2Label = uilabel(app.Step1MaterialpropertiesPanel);
            app.mm2Label.Text = 'mm2';
            app.Step2GeometricalinputPanel = uipanel(app.InputTab);
            app.Step2GeometricalinputPanel.Title = 'Step 2: Geometrical input';
            app.NumberofservicecomboLabel_10 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_10.Text = 'Span';
            app.Lx_in = uieditfield(app.Step2GeometricalinputPanel, 'numeric');
            app.Lx_in.ValueChangedFcn = createCallbackFcn(app, @Lx_inValueChanged, true);
            app.Lx_in.Tooltip = {'Span length in the X direction'};
            app.Lx_in.Value = 12;
            app.mLabel = uilabel(app.Step2GeometricalinputPanel);
            app.mLabel.Text = 'm';
            app.NumberofservicecomboLabel_11 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_11.Text = 'Lx';
            app.Ly_in = uieditfield(app.Step2GeometricalinputPanel, 'numeric');
            app.Ly_in.ValueChangedFcn = createCallbackFcn(app, @Ly_inValueChanged, true);
            app.Ly_in.Tooltip = {'Span length in the Y direction'};
            app.Ly_in.Value = 12;
            app.mLabel_2 = uilabel(app.Step2GeometricalinputPanel);
            app.mLabel_2.Text = 'm';
            app.NumberofservicecomboLabel_12 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_12.Text = 'Ly:';
            app.NumberofservicecomboLabel_13 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_13.Text = 'Column/ Wall';
            app.Cx_in = uieditfield(app.Step2GeometricalinputPanel, 'numeric');
            app.Cx_in.Value = 600;
            app.mmLabel_2 = uilabel(app.Step2GeometricalinputPanel);
            app.mmLabel_2.Text = 'mm';
            app.NumberofservicecomboLabel_14 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_14.Text = 'Cx';
            app.Cy_in = uieditfield(app.Step2GeometricalinputPanel, 'numeric');
            app.Cy_in.Value = 600;
            app.mmLabel_3 = uilabel(app.Step2GeometricalinputPanel);
            app.mmLabel_3.Text = 'mm';
            app.NumberofservicecomboLabel_15 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_15.Text = 'Cy:';
            app.NumberofservicecomboLabel_16 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_16.Text = 'Slab';
            app.D_in = uieditfield(app.Step2GeometricalinputPanel, 'numeric');
            app.D_in.ValueChangedFcn = createCallbackFcn(app, @D_inValueChanged, true);
            app.D_in.Tooltip = {'Slab thickness'};
            app.D_in.Value = 200;
            app.mmLabel_4 = uilabel(app.Step2GeometricalinputPanel);
            app.mmLabel_4.Tooltip = {'Slab thickness'};
            app.mmLabel_4.Text = 'mm';
            app.NumberofservicecomboLabel_17 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_17.Tooltip = {'Slab thickness'};
            app.NumberofservicecomboLabel_17.Text = 'D';
            app.NumberofservicecomboLabel_19 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_19.Text = 'Drop';
            app.Dp_in = uieditfield(app.Step2GeometricalinputPanel, 'numeric');
            app.Dp_in.ValueChangedFcn = createCallbackFcn(app, @Dp_inValueChanged, true);
            app.Dp_in.Tooltip = {'Drop thickness'};
            app.Dp_in.Value = 500;
            app.mmLabel_6 = uilabel(app.Step2GeometricalinputPanel);
            app.mmLabel_6.Text = 'mm';
            app.NumberofservicecomboLabel_20 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_20.Text = 'Dp';
            app.Bx_in = uieditfield(app.Step2GeometricalinputPanel, 'numeric');
            app.Bx_in.Tooltip = {'Drop size along the X direction'};
            app.Bx_in.Value = 4000;
            app.mmLabel_8 = uilabel(app.Step2GeometricalinputPanel);
            app.mmLabel_8.Text = 'mm';
            app.NumberofservicecomboLabel_22 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_22.Text = 'Bx';
            app.By_in = uieditfield(app.Step2GeometricalinputPanel, 'numeric');
            app.By_in.Tooltip = {'Drop size along the Y direction'};
            app.By_in.Value = 4000;
            app.mmLabel_9 = uilabel(app.Step2GeometricalinputPanel);
            app.mmLabel_9.Text = 'mm';
            app.NumberofservicecomboLabel_23 = uilabel(app.Step2GeometricalinputPanel);
            app.NumberofservicecomboLabel_23.Text = 'By';
            app.Switch = uiswitch(app.Step2GeometricalinputPanel, 'slider');
            app.Switch.Items = {'Column', 'Wall'};
            app.Switch.ItemsData = {'1', '0'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Check punching for column or wall.'; ''; 'Wall is assumed to be located along the X direction. Users must rotate the directions and force accordingly,'};
            app.Switch.Value = '1';
            app.ColumnpositionDropDownLabel = uilabel(app.Step2GeometricalinputPanel);
            app.ColumnpositionDropDownLabel.Text = 'Column position:';
            app.ColumnpositionDropDown = uidropdown(app.Step2GeometricalinputPanel);
            app.ColumnpositionDropDown.Items = {'Middle', 'Edge', 'Corner'};
            app.ColumnpositionDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnpositionDropDownValueChanged, true);
            app.ColumnpositionDropDown.Tooltip = {'Column position.'; 'Edge column is assumed to be on the left edge of the slab.'; ''; 'Corner column is assumed on the bottom left corner of the slab.'; ''; 'Users must adjust deimensions and forces accordingly.'};
            app.ColumnpositionDropDown.Value = 'Middle';
            app.Step4RebarsTendonPanel = uipanel(app.InputTab);
            app.Step4RebarsTendonPanel.Title = 'Step 4: Rebars & Tendon';
            app.mmLabel_10 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_10.Text = 'mm';
            app.NumberofservicecomboLabel_24 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_24.Text = 'To bars';
            app.NumberofservicecomboLabel_25 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_25.Text = 'Top cover (to center)';
            app.mmLabel_11 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_11.Text = 'mm';
            app.NumberofservicecomboLabel_26 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_26.Text = 'To strands';
            app.cr_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.cr_in.ValueChangedFcn = createCallbackFcn(app, @cr_inValueChanged, true);
            app.cr_in.Tooltip = {'Average cover to center of rebar'};
            app.cr_in.Value = 25;
            app.cp_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.cp_in.ValueChangedFcn = createCallbackFcn(app, @cp_inValueChanged, true);
            app.cp_in.Tooltip = {'Average cover to center of tendon'};
            app.cp_in.Value = 35;
            app.n_p_x_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.n_p_x_in.ValueChangedFcn = createCallbackFcn(app, @n_p_x_inValueChanged, true);
            app.n_p_x_in.Tooltip = {'Total strand within a span for middle column and half span for edge and corner column'};
            app.n_p_y_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.n_p_y_in.Tooltip = {'Total strand within a span for middle column and half span for edge and corner column'};
            app.NumberofservicecomboLabel_27 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_27.Text = 'X-Dir.';
            app.NumberofservicecomboLabel_28 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_28.Text = 'No. of PT strands';
            app.NumberofservicecomboLabel_29 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_29.Text = 'Y-Dir.';
            app.n_p_x_col_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.n_p_x_col_in.Tooltip = {'Strand within 3Dp from the column face.'; ''; 'This number is used for calculating punching shear strength and tendon vertical forces.'};
            app.n_p_y_col_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.n_p_y_col_in.Tooltip = {'Strand within 3Dp from the column face.'; ''; 'This number is used for calculating punching shear strength and tendon vertical forces.'};
            app.NumberofservicecomboLabel_30 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_30.Tooltip = {'Strand within 3Dp from the column face.'; ''; 'This number is used for calculating punching shear strength and tendon vertical forces.'};
            app.NumberofservicecomboLabel_30.Text = '3dp from column';
            app.n_p_x_drop_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.n_p_x_drop_in.ValueChangedFcn = createCallbackFcn(app, @n_p_x_drop_inValueChanged, true);
            app.n_p_x_drop_in.Tooltip = {'Strand within 3D from the drop face.'};
            app.n_p_y_drop_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.n_p_y_drop_in.ValueChangedFcn = createCallbackFcn(app, @n_p_y_drop_inValueChanged, true);
            app.n_p_y_drop_in.Tooltip = {'Strand within 3D from the drop face.'};
            app.NumberofservicecomboLabel_31 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_31.Tooltip = {'Strand within 3D from the drop face.'};
            app.NumberofservicecomboLabel_31.Text = '3d from drop';
            app.Drape_x_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.Drape_x_in.Tooltip = {'Tendon drapes for calculating vertical tendon forces. Inputting 0.01mm when ignoring vertical tendon forces.'};
            app.Drape_x_in.Value = 50;
            app.Drape_y_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.Drape_y_in.Tooltip = {'Tendon drapes for calculating vertical tendon forces. Inputting 0.01mm when ignoring vertical tendon forces.'};
            app.Drape_y_in.Value = 75;
            app.NumberofservicecomboLabel_32 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_32.Tooltip = {'Tendon drapes for calculating vertical tendon forces. Inputting 0.01mm when ignoring vertical tendon forces.'};
            app.NumberofservicecomboLabel_32.Text = 'Drape';
            app.NumberofservicecomboLabel_33 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_33.Tooltip = {'Total strand within a span for middle column and half span for edge and corner column'};
            app.NumberofservicecomboLabel_33.Text = 'Total';
            app.mmLabel_12 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_12.Tooltip = {'Tendon drapes for calculating vertical tendon forces. Inputting 0.01mm when ignoring vertical tendon forces.'};
            app.mmLabel_12.Text = 'mm';
            app.mmLabel_13 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_13.Tooltip = {'Tendon drapes for calculating vertical tendon forces. Inputting 0.01mm when ignoring vertical tendon forces.'};
            app.mmLabel_13.Text = 'mm';
            app.NumberofservicecomboLabel_34 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_34.Text = 'Top rebars on drop';
            app.NumberofservicecomboLabel_35 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_35.Text = 'X-Dir.';
            app.NumberofservicecomboLabel_36 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_36.Text = 'Y-Dir.';
            app.NumberofservicecomboLabel_37 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_37.Text = 'Spacing';
            app.Spacing_x_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.Spacing_x_in.Editable = 'off';
            app.Spacing_x_in.Value = 200;
            app.Spacing_y_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.Spacing_y_in.Editable = 'off';
            app.Spacing_y_in.Value = 200;
            app.NumberofservicecomboLabel_38 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_38.Tooltip = {'The rebar length is calculated, ensuring  the rebars are anchored at the drop face.'};
            app.NumberofservicecomboLabel_38.Text = 'Length';
            app.L_s_x = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.L_s_x.Editable = 'off';
            app.L_s_x.Tooltip = {'The rebar length is calculated, ensuring  the rebars are anchored at the drop face.'};
            app.L_s_x.Value = 1000;
            app.L_s_y = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.L_s_y.Editable = 'off';
            app.L_s_y.Tooltip = {'The rebar length is calculated, ensuring  the rebars are anchored at the drop face.'};
            app.L_s_y.Value = 1000;
            app.NumberofservicecomboLabel_39 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_39.Text = 'Number';
            app.n_s_x_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.n_s_x_in.ValueChangedFcn = createCallbackFcn(app, @n_s_x_inValueChanged, true);
            app.n_s_x_in.Value = 10;
            app.n_s_y_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.n_s_y_in.ValueChangedFcn = createCallbackFcn(app, @n_s_y_inValueChanged, true);
            app.n_s_y_in.Value = 10;
            app.d_s_y_in = uidropdown(app.Step4RebarsTendonPanel);
            app.d_s_y_in.Items = {'10', '12', '14', '16', '18', '20', '22', '25', '28', '32'};
            app.d_s_y_in.ValueChangedFcn = createCallbackFcn(app, @d_s_y_inValueChanged, true);
            app.d_s_y_in.Value = '10';
            app.mmLabel_14 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_14.Text = 'mm';
            app.d_s_x_in = uidropdown(app.Step4RebarsTendonPanel);
            app.d_s_x_in.Items = {'10', '12', '14', '16', '18', '20', '22', '25', '28', '32'};
            app.d_s_x_in.ValueChangedFcn = createCallbackFcn(app, @d_s_x_inValueChanged, true);
            app.d_s_x_in.Value = '10';
            app.mmLabel_15 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_15.Text = 'mm';
            app.NumberofservicecomboLabel_40 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_40.Text = 'Diameter';
            app.NumberofservicecomboLabel_41 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_41.Text = 'Top rebars on slab';
            app.NumberofservicecomboLabel_42 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_42.Text = 'X-Dir.';
            app.NumberofservicecomboLabel_43 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_43.Text = 'Y-Dir.';
            app.NumberofservicecomboLabel_44 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_44.Text = 'Spacing';
            app.Spacing_s_slab_x_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.Spacing_s_slab_x_in.ValueChangedFcn = createCallbackFcn(app, @Spacing_s_slab_x_inValueChanged, true);
            app.Spacing_s_slab_x_in.Value = 300;
            app.Spacing_s_slab_y_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.Spacing_s_slab_y_in.ValueChangedFcn = createCallbackFcn(app, @Spacing_s_slab_y_inValueChanged, true);
            app.Spacing_s_slab_y_in.Value = 300;
            app.D_s_slab_y_in = uidropdown(app.Step4RebarsTendonPanel);
            app.D_s_slab_y_in.Items = {'10', '12', '14', '16', '18', '20', '22', '25', '28', '32'};
            app.D_s_slab_y_in.ValueChangedFcn = createCallbackFcn(app, @D_s_slab_y_inValueChanged, true);
            app.D_s_slab_y_in.Value = '10';
            app.mmLabel_16 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_16.Text = 'mm';
            app.D_s_slab_x_in = uidropdown(app.Step4RebarsTendonPanel);
            app.D_s_slab_x_in.Items = {'10', '12', '14', '16', '18', '20', '22', '25', '28', '32'};
            app.D_s_slab_x_in.ValueChangedFcn = createCallbackFcn(app, @D_s_slab_x_inValueChanged, true);
            app.D_s_slab_x_in.Value = '10';
            app.mmLabel_17 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_17.Text = 'mm';
            app.NumberofservicecomboLabel_45 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_45.Text = 'Diameter';
            app.MinrebarButton = uibutton(app.Step4RebarsTendonPanel, 'push');
            app.MinrebarButton.ButtonPushedFcn = createCallbackFcn(app, @MinrebarButtonPushed, true);
            app.MinrebarButton.Text = 'Min. rebar';
            app.d_eff_slab_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.d_eff_slab_in.Editable = 'off';
            app.d_eff_slab_in.Tooltip = {'Effective depth of the slab.'};
            app.d_eff_slab_in.Value = 300;
            app.mmLabel_18 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_18.Text = 'mm';
            app.NumberofservicecomboLabel_54 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_54.Text = 'Slab';
            app.d_eff_drop_in = uieditfield(app.Step4RebarsTendonPanel, 'numeric');
            app.d_eff_drop_in.Editable = 'off';
            app.d_eff_drop_in.Tooltip = {'Effective depth of the drop.'};
            app.d_eff_drop_in.Value = 300;
            app.mmLabel_19 = uilabel(app.Step4RebarsTendonPanel);
            app.mmLabel_19.Text = 'mm';
            app.NumberofservicecomboLabel_55 = uilabel(app.Step4RebarsTendonPanel);
            app.NumberofservicecomboLabel_55.Text = 'Drop';
            app.XrebarsSwitchLabel = uilabel(app.Step4RebarsTendonPanel);
            app.XrebarsSwitchLabel.Text = 'X rebars';
            app.XrebarsSwitch = uiswitch(app.Step4RebarsTendonPanel, 'slider');
            app.XrebarsSwitch.Items = {'T1', 'T2'};
            app.XrebarsSwitch.ItemsData = {'0', '1'};
            app.XrebarsSwitch.Tooltip = {'T1 is the pritiorized rebar layer.'};
            app.XrebarsSwitch.Value = '0';
            app.TopslabrebarCheckBox = uicheckbox(app.Step4RebarsTendonPanel);
            app.TopslabrebarCheckBox.Tooltip = {'There is uniformly distributed rebars on the top of the slab.'};
            app.TopslabrebarCheckBox.Text = 'Top slab rebar';
            app.Step8DesignButton = uibutton(app.InputTab, 'push');
            app.Step8DesignButton.ButtonPushedFcn = createCallbackFcn(app, @Step7, true);
            app.Step8DesignButton.Text = 'Step 8: Design';
            app.Step3LoadingPanel = uipanel(app.InputTab);
            app.Step3LoadingPanel.Title = 'Step 3: Loading';
            app.NumberofservicecomboLabel_47 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_47.Text = 'Mx (kNm)';
            app.NumberofservicecomboLabel_48 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_48.Text = 'My (kNm)';
            app.R2 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.R2.Tooltip = {'Reactions forces coresponding to the combination of maximum Mx.'};
            app.R2.Value = 1;
            app.Mx2 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.Mx2.Tooltip = {'Reactions forces coresponding to the combination of maximum Mx.'};
            app.Mx2.Value = 1;
            app.NumberofservicecomboLabel_49 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_49.Tooltip = {'Reactions forces coresponding to the combination of maximum Mx.'};
            app.NumberofservicecomboLabel_49.Text = 'Mx max';
            app.My2 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.My2.Tooltip = {'Reactions forces coresponding to the combination of maximum Mx.'};
            app.My2.Value = 1;
            app.R3 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.R3.Tooltip = {'Reactions forces coresponding to the combination of minimum Mx.'};
            app.R3.Value = 1;
            app.Mx3 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.Mx3.Tooltip = {'Reactions forces coresponding to the combination of minimum Mx.'};
            app.Mx3.Value = 1;
            app.NumberofservicecomboLabel_50 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_50.Tooltip = {'Reactions forces coresponding to the combination of minimum Mx.'};
            app.NumberofservicecomboLabel_50.Text = 'Mx min';
            app.My3 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.My3.Tooltip = {'Reactions forces coresponding to the combination of minimum Mx.'};
            app.My3.Value = 1;
            app.R4 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.R4.Tooltip = {'Reactions forces coresponding to the combination of maximum My.'};
            app.R4.Value = 1;
            app.Mx4 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.Mx4.Tooltip = {'Reactions forces coresponding to the combination of maximum My.'};
            app.Mx4.Value = 1;
            app.NumberofservicecomboLabel_51 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_51.Tooltip = {'Reactions forces coresponding to the combination of maximum My.'};
            app.NumberofservicecomboLabel_51.Text = 'My max';
            app.My4 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.My4.Tooltip = {'Reactions forces coresponding to the combination of maximum My.'};
            app.My4.Value = 1;
            app.R5 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.R5.Tooltip = {'Reactions forces coresponding to the combination of minimum My.'};
            app.R5.Value = 1;
            app.Mx5 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.Mx5.Tooltip = {'Reactions forces coresponding to the combination of minimum My.'};
            app.Mx5.Value = 1;
            app.NumberofservicecomboLabel_52 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_52.Tooltip = {'Reactions forces coresponding to the combination of minimum My.'};
            app.NumberofservicecomboLabel_52.Text = 'My min';
            app.My5 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.My5.Tooltip = {'Reactions forces coresponding to the combination of minimum My.'};
            app.My5.Value = 1;
            app.NumberofservicecomboLabel_53 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_53.Text = 'Rz (kN)';
            app.R1 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.R1.Tooltip = {'Reactions forces coresponding to the combination of maximum column axial force.'};
            app.R1.Value = 1;
            app.Mx1 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.Mx1.Tooltip = {'Reactions forces coresponding to the combination of maximum column axial force.'};
            app.Mx1.Value = 1;
            app.NumberofservicecomboLabel_46 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_46.Tooltip = {'Reactions forces coresponding to the combination of maximum column axial force.'};
            app.NumberofservicecomboLabel_46.Text = 'Axial max';
            app.My1 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.My1.Tooltip = {'Reactions forces coresponding to the combination of maximum column axial force.'};
            app.My1.Value = 1;
            app.NumberofservicecomboLabel_56 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_56.Text = 'SDL';
            app.NumberofservicecomboLabel_57 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_57.Text = 'kPa';
            app.SDL_in = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.SDL_in.Tooltip = {'Super imposed dead load'};
            app.SDL_in.Value = 2;
            app.NumberofservicecomboLabel_58 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_58.Text = 'LL';
            app.NumberofservicecomboLabel_59 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_59.Text = 'kPa';
            app.LL_in = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.LL_in.Tooltip = {'Live load'};
            app.LL_in.Value = 5;
            app.NumberofservicecomboLabel_64 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_64.Text = 'SDL Factor';
            app.F_SDL_2 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_SDL_2.Tooltip = {'SDL factor coresponding to the combination of maximum Mx'};
            app.F_SDL_2.Value = 1.35;
            app.F_SDL_3 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_SDL_3.Tooltip = {'SDL factor coresponding to the combination of minimum Mx'};
            app.F_SDL_3.Value = 1.35;
            app.F_SDL_4 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_SDL_4.Tooltip = {'SDL factor coresponding to the combination of maximum My'};
            app.F_SDL_4.Value = 1.35;
            app.F_SDL_5 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_SDL_5.Tooltip = {'SDL factor coresponding to the combination of minimum My'};
            app.F_SDL_5.Value = 1.35;
            app.F_SDL_1 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_SDL_1.Tooltip = {'SDL factor coresponding to the combination of maximum column axial force.'};
            app.F_SDL_1.Value = 1.35;
            app.NumberofservicecomboLabel_65 = uilabel(app.Step3LoadingPanel);
            app.NumberofservicecomboLabel_65.Text = 'LL Factor';
            app.F_LL_2 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_LL_2.Tooltip = {'LL factor coresponding to the combination of maximum Mx'};
            app.F_LL_2.Value = 1.5;
            app.F_LL_3 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_LL_3.Tooltip = {'LL factor coresponding to the combination of minimum Mx'};
            app.F_LL_3.Value = 1.5;
            app.F_LL_4 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_LL_4.Tooltip = {'LL factor coresponding to the combination of maximumMy'};
            app.F_LL_4.Value = 1.5;
            app.F_LL_5 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_LL_5.Tooltip = {'LL factor coresponding to the combination of minimum My'};
            app.F_LL_5.Value = 1.5;
            app.F_LL_1 = uieditfield(app.Step3LoadingPanel, 'numeric');
            app.F_LL_1.Tooltip = {'LL factor coresponding to the combination of maximum column axial force.'};
            app.F_LL_1.Value = 1.5;
            app.ForedgeandcornercolumnsLabel = uilabel(app.Step3LoadingPanel);
            app.ForedgeandcornercolumnsLabel.Enable = 'off';
            app.ForedgeandcornercolumnsLabel.Visible = 'off';
            app.ForedgeandcornercolumnsLabel.Text = 'For edge and corner columns, the punching force Rz is considered to be uniformly distributed along the control perimeter ui* according to Section 6.4.3';
            app.Step7PlottingslabopeningButton = uibutton(app.InputTab, 'push');
            app.Step7PlottingslabopeningButton.ButtonPushedFcn = createCallbackFcn(app, @Step7PlottingslabopeningButtonPushed, true);
            app.Step7PlottingslabopeningButton.Tooltip = {'Plot the slab opening'};
            app.Step7PlottingslabopeningButton.Text = 'Step 7: Plotting slab opening';
            app.Step5OpeninginputPanel = uipanel(app.InputTab);
            app.Step5OpeninginputPanel.Title = 'Step 5: Opening input';
            app.UITable_hole = uitable(app.Step5OpeninginputPanel);
            app.UITable_hole.ColumnName = {'No.'; 'X1 (mm)'; 'Y1 (mm)'; 'X2 (mm)'; 'Y2 (mm)'};
            app.UITable_hole.RowName = {};
            app.UITable_hole.ColumnEditable = [false true true true true];
            app.UITable_hole.Tooltip = {'Inputting polar points of slab opening. The openings should not overlapping each other. If overlapping, merge them before inputting.'};
            app.NumberofservicecomboLabel_76 = uilabel(app.Step5OpeninginputPanel);
            app.NumberofservicecomboLabel_76.Text = 'Note: Openings should not overlapping each other';
            app.ProjectnameEditFieldLabel = uilabel(app.InputTab);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.NameforsavingfileEditField = uieditfield(app.InputTab, 'text');
            app.NameforsavingfileEditField.Tooltip = {'Name for saving file'};
            app.Steps16ReloadoldmodelButton = uibutton(app.InputTab, 'push');
            app.Steps16ReloadoldmodelButton.ButtonPushedFcn = createCallbackFcn(app, @Steps16ReloadoldmodelButtonPushed, true);
            app.Steps16ReloadoldmodelButton.Text = 'Steps 1~6: Reload old model';
            app.Image = uiimage(app.InputTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Pending.png');
            app.Step6SavinginputButton = uibutton(app.InputTab, 'push');
            app.Step6SavinginputButton.ButtonPushedFcn = createCallbackFcn(app, @Step6SavinginputButtonPushed, true);
            app.Step6SavinginputButton.Tooltip = {'Save file'};
            app.Step6SavinginputButton.Text = 'Step 6: Saving input';
            app.DropresultsTab = uitab(app.TabGroup);
            app.DropresultsTab.Title = 'Drop results';
            app.Plotting_result = uiaxes(app.DropresultsTab);
            title(app.Plotting_result, 'Title')
            xlabel(app.Plotting_result, 'X')
            ylabel(app.Plotting_result, 'Y')
            zlabel(app.Plotting_result, 'Z')
            app.Result_table_drop = uitable(app.DropresultsTab);
            app.Result_table_drop.ColumnName = {'No.'; 'L_Crit. (inner)'; 'L_Crit. (outter)'; 'Rein_perimeter'; 'Shear bar'; 'Perimeter (mm)'; 'Shear area (mm2)'; 'Force (kN)'; 'Stress (MPa)'; 'Req. area (mm2)'};
            app.Result_table_drop.RowName = {};
            app.Result_table_drop.Tooltip = {'Summary table of control perimeters.'};
            app.OpeningCheckBox = uicheckbox(app.DropresultsTab);
            app.OpeningCheckBox.Text = 'Opening';
            app.LongitudinalreinforcementsCheckBox = uicheckbox(app.DropresultsTab);
            app.LongitudinalreinforcementsCheckBox.Text = 'Longitudinal reinforcements';
            app.LongitudinalreinforcementsCheckBox.Value = true;
            app.ControlperimetersCheckBox = uicheckbox(app.DropresultsTab);
            app.ControlperimetersCheckBox.Text = 'Control perimeters';
            app.ShearreinforcementsCheckBox = uicheckbox(app.DropresultsTab);
            app.ShearreinforcementsCheckBox.Text = 'Shear reinforcements';
            app.ShearreinforcementsCheckBox.Value = true;
            app.PlotButton = uibutton(app.DropresultsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Design_notice = uitextarea(app.DropresultsTab);
            app.ControlperimetersLabel = uilabel(app.DropresultsTab);
            app.ControlperimetersLabel.Text = 'Control perimeters';
            app.ReinforcementperimeterLabel = uilabel(app.DropresultsTab);
            app.ReinforcementperimeterLabel.Text = 'Reinforcement perimeter';
            app.Result_table_rein = uitable(app.DropresultsTab);
            app.Result_table_rein.ColumnName = {'No.'; 'L_Crit. (mm)'; 'Spacing X (mm)'; 'Spacing Y (mm)'; 'Max. spacing (mm)'; 'Num. Bar. X'; 'Num. Bar. Y'; 'T. Bar'; 'Bar. area (mm2)'};
            app.Result_table_rein.RowName = {};
            app.Result_table_rein.Tooltip = {'Summary table of shear link perimeters.'};
            app.U_out = uieditfield(app.DropresultsTab, 'numeric');
            app.U_out.Tooltip = {'Distance from the column face to the ottermost control perimeter. However, this result is only valid for middle column without slab openings.'};
            app.U_out.Value = 50;
            app.NumberofservicecomboLabel_61 = uilabel(app.DropresultsTab);
            app.NumberofservicecomboLabel_61.Text = 'U_out';
            app.NumberofservicecomboLabel_62 = uilabel(app.DropresultsTab);
            app.NumberofservicecomboLabel_62.Text = 'mm';
            app.NumberofservicecomboLabel_63 = uilabel(app.DropresultsTab);
            app.NumberofservicecomboLabel_63.Text = 'Note: The green line (U_out) is only applicable in the case of middle column without slab openings';
            app.Shear_on_drop = uieditfield(app.DropresultsTab, 'numeric');
            app.Shear_on_drop.Tooltip = {'Quantities of shear link on drop. Assuming the link consist of a 5d hook on one end and a 10d hook on the other end.'};
            app.NumberofservicecomboLabel_66 = uilabel(app.DropresultsTab);
            app.NumberofservicecomboLabel_66.Text = 'Shear on drop';
            app.NumberofservicecomboLabel_67 = uilabel(app.DropresultsTab);
            app.NumberofservicecomboLabel_67.Text = 'kg';
            app.Vrdc_drop = uieditfield(app.DropresultsTab, 'numeric');
            app.Vrdc_drop.Tooltip = {'Punching resistance of drop without shear reinforcements.'};
            app.NumberofservicecomboLabel_72 = uilabel(app.DropresultsTab);
            app.NumberofservicecomboLabel_72.Text = 'v_rdc';
            app.NumberofservicecomboLabel_73 = uilabel(app.DropresultsTab);
            app.NumberofservicecomboLabel_73.Text = 'MPa';
            app.SlabresultsTab = uitab(app.TabGroup);
            app.SlabresultsTab.Title = 'Slab results';
            app.Plotting_result_2 = uiaxes(app.SlabresultsTab);
            title(app.Plotting_result_2, 'Title')
            xlabel(app.Plotting_result_2, 'X')
            ylabel(app.Plotting_result_2, 'Y')
            zlabel(app.Plotting_result_2, 'Z')
            app.OpeningCheckBox_2 = uicheckbox(app.SlabresultsTab);
            app.OpeningCheckBox_2.Text = 'Opening';
            app.LongitudinalreinforcementsCheckBox_2 = uicheckbox(app.SlabresultsTab);
            app.LongitudinalreinforcementsCheckBox_2.Text = 'Longitudinal reinforcements';
            app.LongitudinalreinforcementsCheckBox_2.Value = true;
            app.ControlperimetersCheckBox_2 = uicheckbox(app.SlabresultsTab);
            app.ControlperimetersCheckBox_2.Text = 'Control perimeters';
            app.ShearreinforcementsCheckBox_2 = uicheckbox(app.SlabresultsTab);
            app.ShearreinforcementsCheckBox_2.Text = 'Shear reinforcements';
            app.ShearreinforcementsCheckBox_2.Value = true;
            app.PlotButton_2 = uibutton(app.SlabresultsTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.Design_notice_2 = uitextarea(app.SlabresultsTab);
            app.Result_table_slab = uitable(app.SlabresultsTab);
            app.Result_table_slab.ColumnName = {'No.'; 'L_Crit. (inner)'; 'L_Crit. (outter)'; 'Rein_perimeter'; 'Shear bar'; 'Perimeter (mm)'; 'Area (mm2)'; 'Force (kN)'; 'Stress (MPa)'; 'Req. area (mm2)'};
            app.Result_table_slab.RowName = {};
            app.Result_table_slab.Tooltip = {'Summary table of control perimeters.'};
            app.SlabreinforcementsLabel_2 = uilabel(app.SlabresultsTab);
            app.SlabreinforcementsLabel_2.Text = 'Slab reinforcements';
            app.ReinforcementperimeterLabel_2 = uilabel(app.SlabresultsTab);
            app.ReinforcementperimeterLabel_2.Text = 'Reinforcement perimeter';
            app.Result_table_rein_2 = uitable(app.SlabresultsTab);
            app.Result_table_rein_2.ColumnName = {'No.'; 'L_Crit. (mm)'; 'Spacing X (mm)'; 'Spacing Y (mm)'; 'Max. spacing (mm)'; 'Num. Bar. X'; 'Num. Bar. Y'; 'T. Bart'; 'Bar. area (mm2)'};
            app.Result_table_rein_2.RowName = {};
            app.Result_table_rein_2.Tooltip = {'Summary table of shear link perimeters.'};
            app.Shear_on_slab = uieditfield(app.SlabresultsTab, 'numeric');
            app.Shear_on_slab.Tooltip = {'Quantities of shear link on slab. Assuming the link consist of a 5d hook on one end and a 10d hook on the other end.'};
            app.NumberofservicecomboLabel_68 = uilabel(app.SlabresultsTab);
            app.NumberofservicecomboLabel_68.Text = 'Shear on slab';
            app.NumberofservicecomboLabel_69 = uilabel(app.SlabresultsTab);
            app.NumberofservicecomboLabel_69.Text = 'kg';
            app.Vrdc_slab = uieditfield(app.SlabresultsTab, 'numeric');
            app.Vrdc_slab.Tooltip = {'Punching resistance of slab without shear reinforcements.'};
            app.NumberofservicecomboLabel_74 = uilabel(app.SlabresultsTab);
            app.NumberofservicecomboLabel_74.Text = 'v_rdc';
            app.NumberofservicecomboLabel_75 = uilabel(app.SlabresultsTab);
            app.NumberofservicecomboLabel_75.Text = 'MPa';
            app.CombinedTab = uitab(app.TabGroup);
            app.CombinedTab.Title = 'Combined';
            app.Plotting_result_3 = uiaxes(app.CombinedTab);
            title(app.Plotting_result_3, 'Title')
            xlabel(app.Plotting_result_3, 'X')
            ylabel(app.Plotting_result_3, 'Y')
            zlabel(app.Plotting_result_3, 'Z')
            app.HoleCheckBox_3 = uicheckbox(app.CombinedTab);
            app.HoleCheckBox_3.Text = 'Hole';
            app.LongitudinalreinforcementsCheckBox_3 = uicheckbox(app.CombinedTab);
            app.LongitudinalreinforcementsCheckBox_3.Text = 'Longitudinal reinforcements';
            app.LongitudinalreinforcementsCheckBox_3.Value = true;
            app.ControlperimetersCheckBox_3 = uicheckbox(app.CombinedTab);
            app.ControlperimetersCheckBox_3.Text = 'Control perimeters';
            app.ShearreinforcementsCheckBox_3 = uicheckbox(app.CombinedTab);
            app.ShearreinforcementsCheckBox_3.Text = 'Shear reinforcements';
            app.ShearreinforcementsCheckBox_3.Value = true;
            app.PlotButton_3 = uibutton(app.CombinedTab, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.DropreinforcementperimeterLabel = uilabel(app.CombinedTab);
            app.DropreinforcementperimeterLabel.Text = 'Drop reinforcement perimeter';
            app.Result_table_rein_3 = uitable(app.CombinedTab);
            app.Result_table_rein_3.ColumnName = {'No.'; 'L_Crit. (mm)'; 'Spacing X (mm)'; 'Spacing Y (mm)'; 'Max. spacing (mm)'; 'Num. Bar. X'; 'Num. Bar. Y'; 'T. Bart'; 'Bar. area (mm2)'};
            app.Result_table_rein_3.RowName = {};
            app.Result_table_rein_3.Tooltip = {'Summary table of control perimeters.'};
            app.Result_table_rein_4 = uitable(app.CombinedTab);
            app.Result_table_rein_4.ColumnName = {'No.'; 'L_Crit. (mm)'; 'Spacing X (mm)'; 'Spacing Y (mm)'; 'Max. spacing (mm)'; 'Num. Bar. X'; 'Num. Bar. Y'; 'T. Bart'; 'Bar. area (mm2)'};
            app.Result_table_rein_4.RowName = {};
            app.Result_table_rein_4.Tooltip = {'Summary table of shear link perimeters.'};
            app.SlabreinforcementperimeterLabel = uilabel(app.CombinedTab);
            app.SlabreinforcementperimeterLabel.Text = 'Slab reinforcement perimeter';
            app.Total_reinf_drop_slab = uieditfield(app.CombinedTab, 'numeric');
            app.NumberofservicecomboLabel_70 = uilabel(app.CombinedTab);
            app.NumberofservicecomboLabel_70.Text = 'Total shear Rein.';
            app.NumberofservicecomboLabel_71 = uilabel(app.CombinedTab);
            app.NumberofservicecomboLabel_71.Text = 'kg';
            app.ExportcalculationreportButton = uibutton(app.CombinedTab, 'push');
            app.ExportcalculationreportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcalculationreportButtonPushed, true);
            app.ExportcalculationreportButton.Text = 'Export calculation report';
            app.Image_2 = uiimage(app.CombinedTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Pending.png');
            app.PunchingShearLabel = uilabel(app.UIFigure);
            app.PunchingShearLabel.Text = 'Punching Shear';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Step8DesignButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gen_app_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end